classdef OMG_MSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OMGMSIModeSelectionPanel        matlab.ui.container.Panel
        DataProcessingButton_2          matlab.ui.control.Button
        DataAcquisitionButton_2         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        ModeTab                         matlab.ui.container.Tab
        ButtonGroupMappingSpeed         matlab.ui.container.ButtonGroup
        FastmappingButton               matlab.ui.control.RadioButton
        WithrepositioningButton         matlab.ui.control.RadioButton
        MappingChoiceButtonGroup        matlab.ui.container.ButtonGroup
        LampWithSpectro_2D              matlab.ui.control.Lamp
        DMSIButton_2                    matlab.ui.control.RadioButton
        LampWithSpectro                 matlab.ui.control.Lamp
        LampOnlyTopo                    matlab.ui.control.Lamp
        DMSIButton                      matlab.ui.control.RadioButton
        TopographyOnlyButton            matlab.ui.control.RadioButton
        PropertiesTab                   matlab.ui.container.Tab
        DimensionsLabel                 matlab.ui.control.Label
        FirstmappingpointYpositionmmEditField  matlab.ui.control.NumericEditField
        FirstmappingpointYpositionmmEditFieldLabel  matlab.ui.control.Label
        FirstmappingpointXpositionmmEditField  matlab.ui.control.NumericEditField
        FirstmappingpointXpositionmmEditFieldLabel  matlab.ui.control.Label
        MaximumheightmmofthesampleEditField  matlab.ui.control.NumericEditField
        MaximumheightmmofthesampleLabel  matlab.ui.control.Label
        EstimationofmappingtimeLabel    matlab.ui.control.Label
        YmmEditField                    matlab.ui.control.NumericEditField
        YmmEditFieldLabel               matlab.ui.control.Label
        XmmEditField                    matlab.ui.control.NumericEditField
        XmmLabel                        matlab.ui.control.Label
        SurfaceoffestmmEditField        matlab.ui.control.NumericEditField
        SurfaceoffestmmEditFieldLabel   matlab.ui.control.Label
        ResolutionmmEditField           matlab.ui.control.NumericEditField
        ResolutionmmEditFieldLabel      matlab.ui.control.Label
        mapLabel                        matlab.ui.control.Label
        GeneratedmapfileEditField       matlab.ui.control.EditField
        GeneratedmapfileEditFieldLabel  matlab.ui.control.Label
        RobotTab                        matlab.ui.container.Tab
        DisconnectRobotButton           matlab.ui.control.Button
        StateLampRobot                  matlab.ui.control.Lamp
        RobotConnexionB                 matlab.ui.control.Button
        RobotDropDown                   matlab.ui.control.DropDown
        RobotDropDownLabel              matlab.ui.control.Label
        SensorTab                       matlab.ui.container.Tab
        SensorstateLamp                 matlab.ui.control.Lamp
        SensorstateLampLabel            matlab.ui.control.Label
        ConnectSensorButton             matlab.ui.control.Button
        SensorCalibrationstateLamp      matlab.ui.control.Lamp
        CalibrationstateLabel           matlab.ui.control.Label
        LaunchSensorCalibrationButton   matlab.ui.control.Button
        SensorDropDown                  matlab.ui.control.DropDown
        SensorDropDownLabel             matlab.ui.control.Label
        SourceTab                       matlab.ui.container.Tab
        NumberoflasershotsburstmodeEditField  matlab.ui.control.NumericEditField
        NumberoflasershotsburstmodeEditFieldLabel  matlab.ui.control.Label
        TimebetweenlaserburstssecondEditField  matlab.ui.control.NumericEditField
        TimebetweenlaserburstssecondEditFieldLabel  matlab.ui.control.Label
        LampVoltageSet                  matlab.ui.control.Lamp
        EnergylevelVSpinner             matlab.ui.control.Spinner
        EnergylevelVSpinnerLabel        matlab.ui.control.Label
        ContinuousAcquisitionCheckBox   matlab.ui.control.CheckBox
        FocaldistancemmEditField        matlab.ui.control.NumericEditField
        FocaldistancemmEditFieldLabel   matlab.ui.control.Label
        AffichageState                  matlab.ui.control.Label
        GetStateButton                  matlab.ui.control.Button
        TurnLampOffButton               matlab.ui.control.Button
        Lamp_LampONorOFF                matlab.ui.control.Lamp
        TurnLampOnButton                matlab.ui.control.Button
        AffichageTemperature            matlab.ui.control.Label
        GetTempButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        StateLampOpotek                 matlab.ui.control.Lamp
        ConnectSourceButton             matlab.ui.control.Button
        SourceDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        MassSpectrometerTab             matlab.ui.container.Tab
        MassSpectrometerTrigerringDropDown  matlab.ui.control.DropDown
        MassSpectrometerTrigerringDropDownLabel  matlab.ui.control.Label
        StartTab                        matlab.ui.container.Tab
        FocaldistancemmEditField_2      matlab.ui.control.NumericEditField
        FocaldistancemmEditField_2Label  matlab.ui.control.Label
        EMERGENCYSTOPACQUISITIONButton  matlab.ui.control.StateButton
        LaserBurstButton                matlab.ui.control.Button
        GOTOButton                      matlab.ui.control.Button
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        EstimatedDurationLabel          matlab.ui.control.Label
        SensorStateOffLabel             matlab.ui.control.Label
        Resolutionm500Label             matlab.ui.control.Label
        Y16Label                        matlab.ui.control.Label
        X16Label                        matlab.ui.control.Label
        MapDimensionsmmLabel            matlab.ui.control.Label
        MappingModeTopographyOnlyLabel  matlab.ui.control.Label
        SourceStateOffLabel             matlab.ui.control.Label
        RobotStateDisconnectedLabel     matlab.ui.control.Label
        QuittoMenuButton_2              matlab.ui.control.Button
        LaunchMappingButton             matlab.ui.control.Button
        StatusWaitingLabel              matlab.ui.control.Label
        ClearMapButton                  matlab.ui.control.Button
        GotoMappingPositionButton       matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        PrincipalComponentAnalysisLabel  matlab.ui.control.Label
        LaunchPCAButton                 matlab.ui.control.StateButton
        MaximumEditField_B              matlab.ui.control.NumericEditField
        MaximumEditField_4Label         matlab.ui.control.Label
        MinimumEditField_B              matlab.ui.control.NumericEditField
        MinimumEditField_3Label         matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        MaximumEditField_G              matlab.ui.control.NumericEditField
        MaximumEditField_3Label         matlab.ui.control.Label
        MinimumEditField_G              matlab.ui.control.NumericEditField
        MinimumEditField_2Label         matlab.ui.control.Label
        GreenLabel                      matlab.ui.control.Label
        RedLabel                        matlab.ui.control.Label
        RGBDisplayLabel                 matlab.ui.control.Label
        MaximumEditField_R              matlab.ui.control.NumericEditField
        MaximumEditField_2Label         matlab.ui.control.Label
        MinimumEditField_R              matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        LaunchReconstructionButton_RGB  matlab.ui.control.Button
        DisplaySpectraSumButton         matlab.ui.control.Button
        LouddataonthemapSwitch          matlab.ui.control.Switch
        LouddataonthemapSwitchLabel     matlab.ui.control.Label
        AdditionnalDataExtractionFeaturesLabel  matlab.ui.control.Label
        ReconstructionLabel             matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MapFromSpectraButton            matlab.ui.control.Button
        DisplaySpectraZoneButton        matlab.ui.control.Button
        DisplayOneSpectrumButton        matlab.ui.control.Button
        LaunchReconstructionButton      matlab.ui.control.Button
        MaximumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MasslimitsMinimumEditField      matlab.ui.control.NumericEditField
        MasslimitsMinimumEditFieldLabel  matlab.ui.control.Label
        PostProcessingTab               matlab.ui.container.Tab
        TopographicInterpolationLabel   matlab.ui.control.Label
        TopographicCorrectionLabel      matlab.ui.control.Label
        PointMultiplierEditField        matlab.ui.control.NumericEditField
        PointMultiplierEditFieldLabel   matlab.ui.control.Label
        NameoftheinterpolatedmapEditField  matlab.ui.control.EditField
        NameoftheinterpolatedmapEditFieldLabel  matlab.ui.control.Label
        mapLabel_7                      matlab.ui.control.Label
        LaunchInterpolationButton       matlab.ui.control.Button
        MaximumthresholdmmEditField     matlab.ui.control.NumericEditField
        MaximumthresholdmmEditFieldLabel  matlab.ui.control.Label
        MinimumthresholdmmEditField     matlab.ui.control.NumericEditField
        MinimumthresholdmmEditFieldLabel  matlab.ui.control.Label
        mapLabel_4                      matlab.ui.control.Label
        LaunchRectificationButton       matlab.ui.control.Button
        NameofthecorrectedmapEditField  matlab.ui.control.EditField
        NameofthecorrectedmapEditFieldLabel  matlab.ui.control.Label
        DisplayfromexternaldataTab      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ColorDropDown_6                 matlab.ui.control.DropDown
        ColorDropDown_6Label            matlab.ui.control.Label
        ColorDropDown_5                 matlab.ui.control.DropDown
        ColorDropDown_5Label            matlab.ui.control.Label
        Class06EditField                matlab.ui.control.EditField
        Class06EditFieldLabel           matlab.ui.control.Label
        Class05EditField                matlab.ui.control.EditField
        Class05EditFieldLabel           matlab.ui.control.Label
        Class04EditField                matlab.ui.control.EditField
        Class04EditFieldLabel           matlab.ui.control.Label
        ColorDropDown_4                 matlab.ui.control.DropDown
        ColorDropDown_4Label            matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        ColorDropDown_3Label            matlab.ui.control.Label
        ColorDropDown_2                 matlab.ui.control.DropDown
        ColorDropDown_2Label            matlab.ui.control.Label
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        LaunchLDAReconstructionButton   matlab.ui.control.Button
        Class03EditField                matlab.ui.control.EditField
        Class03EditFieldLabel           matlab.ui.control.Label
        Class02EditField                matlab.ui.control.EditField
        Class02EditFieldLabel           matlab.ui.control.Label
        Class01EditField                matlab.ui.control.EditField
        Class01EditFieldLabel           matlab.ui.control.Label
        NameofthecsvfileEditField       matlab.ui.control.EditField
        NameofthecsvfileEditFieldLabel  matlab.ui.control.Label
        csvLabel                        matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        Scils_Extraction                matlab.ui.container.Tab
        InputWatersRAWfileEditField     matlab.ui.control.EditField
        InputWatersRAWfileEditFieldLabel  matlab.ui.control.Label
        RAWLabel                        matlab.ui.control.Label
        ExportSpectraZonetoCSVButton    matlab.ui.control.Button
        ExportSpectraSumtoCSVButton     matlab.ui.control.Button
        ExportOneSpectrumtoCSVButton    matlab.ui.control.Button
        NormaliseCSVRMSButton           matlab.ui.control.Button
        NormaliseCSVBPButton            matlab.ui.control.Button
        ExportandcoregisterfromCSVButton  matlab.ui.control.Button
        ExportfromCSVButton             matlab.ui.control.Button
        NormaliseCSVTICButton           matlab.ui.control.Button
        ExporttoCSVButton               matlab.ui.control.Button
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        MaxMzEditField                  matlab.ui.control.NumericEditField
        MaxMzEditFieldLabel             matlab.ui.control.Label
        MinMzEditField                  matlab.ui.control.NumericEditField
        MinMzEditFieldLabel             matlab.ui.control.Label
        ExporttoCSVfileLabel            matlab.ui.control.Label
        Exportto3DfileplyLabel          matlab.ui.control.Label
        ExporttoSCILSImzmlLabel         matlab.ui.control.Label
        LaunchExtractionButton          matlab.ui.control.Button
        txtLabel                        matlab.ui.control.Label
        OutputtextfileEditField         matlab.ui.control.EditField
        OutputtextfileEditFieldLabel    matlab.ui.control.Label
        LogTab_2                        matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        TextArea2Label                  matlab.ui.control.Label
        OMGMSINewmatfilePanel           matlab.ui.container.Panel
        AspirationShiftsEditField       matlab.ui.control.NumericEditField
        AspirationShiftsEditFieldLabel  matlab.ui.control.Label
        NeighbourNumberEditField        matlab.ui.control.NumericEditField
        NeighbourNumberEditFieldLabel   matlab.ui.control.Label
        LoudThresholdEditField          matlab.ui.control.NumericEditField
        LoudThresholdEditFieldLabel     matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        TimebetweentwolaserburstssEditField  matlab.ui.control.NumericEditField
        TimebetweentwolaserburstssEditFieldLabel  matlab.ui.control.Label
        BeginThresholdEditField         matlab.ui.control.NumericEditField
        BeginThresholdEditFieldLabel    matlab.ui.control.Label
        PlotChromatogramButton          matlab.ui.control.Button
        LoadmzXMLButton                 matlab.ui.control.Button
        TimeValue                       matlab.ui.control.Label
        LaunchTimeEstimationButton      matlab.ui.control.Button
        ScanSelectionMethodDropDown     matlab.ui.control.DropDown
        ScanSelectionMethodDropDownLabel  matlab.ui.control.Label
        mapLabel_5                      matlab.ui.control.Label
        MapfilenameEditField_2          matlab.ui.control.EditField
        MapfilenameEditField_2Label     matlab.ui.control.Label
        AspirationTimesEditField        matlab.ui.control.NumericEditField
        AspirationTimesEditFieldLabel   matlab.ui.control.Label
        FusionpercentSlider             matlab.ui.control.Slider
        FusionpercentSliderLabel        matlab.ui.control.Label
        PreprocessingLabel              matlab.ui.control.Label
        InternalTriggeringSwitch        matlab.ui.control.Switch
        InternalTriggeringSwitchLabel   matlab.ui.control.Label
        ScanSelectionButton             matlab.ui.control.Button
        matLabel                        matlab.ui.control.Label
        GeneratedmatfileEditField       matlab.ui.control.EditField
        GeneratedmatfileEditFieldLabel  matlab.ui.control.Label
        BiningwindowsizeEditField       matlab.ui.control.NumericEditField
        BiningwindowsizeEditFieldLabel  matlab.ui.control.Label
        GeneratematfileButton           matlab.ui.control.Button
        ScanSelectionLabel              matlab.ui.control.Label
        mzXMLLoadingLabel               matlab.ui.control.Label
        mzXMLLabel                      matlab.ui.control.Label
        mzXMLfilenameEditField          matlab.ui.control.EditField
        mzXMLfilenameEditFieldLabel     matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        OMGMSIHopefully10SoonDataProcessingPanel  matlab.ui.container.Panel
        PleaseinputavalidmatandmapfilenametostartprocessingLabel  matlab.ui.control.Label
        QuittoMenuButton                matlab.ui.control.Button
        NewMatButton                    matlab.ui.control.Button
        MapFileEditField                matlab.ui.control.EditField
        MapFileEditFieldLabel           matlab.ui.control.Label
        MatFileEditField                matlab.ui.control.EditField
        MatFileEditFieldLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        method;  % What is this for?

        % The following properties are all set in the interface
        mapping_mode
        map_x % Pretty sure some of these properties exist elsewhere, but 
        % there is value in having a unified system
        map_y 
        map_res 
        % The following state flags are used to check on several
        % components of the interface and modify callback's behaviours
        % accordingly
        robot_state = 0 % 0:Disconnected 1:Connected 2:Error 
        sensor_state = 0 % 0:Disconnected 1:Connected 2:Calibrated
        source_state = 0 % 0:Disconnected 1:Connected 2:Ready (Lamp On)
        gui_acquisition_state = 0 %0:Not ready for imaging 1:Ready for topography 2:Ready for 2D MSI 3:Ready for 3D MSI

    end
    
    properties (Access = public) % May replace global variables
        workdir = pwd; % Working directory of the app/exe file. Could be used for I/O operations
    end
    
    methods (Access = private)    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center') % Centers the interface on the screen. Causes some jittering when switching windows.

            global info_log;
            info_log = "";
            
            if not(isdeployed)
                disp('The application is not deployed as an executable. Adding necessary folders to PATH. Please review L281 of the app.')
                addpath(genpath("code/"))
                addpath(genpath("files/")) % I feel like this approach, 
                % while less elegant, is much more robust as it does not require 
                % adding .m to the workspace in the first place

%                 % Add folders to the workspace
%                 list_folders_to_import = [find_folders_and_subfolders("code");find_folders_and_subfolders("files")];
%                 si=size(list_folders_to_import);
%                 for i = 1 : si(1)
%                     path(path,list_folders_to_import(i));
%                 end
            else
                disp('The application is deployed as an executable.')
            end            
            
            % Initialise acquisition properties
            app.mapping_mode = "Topography Only";
            app.map_x = 16; 
            app.map_y = 16;
            app.map_res = 0.5;
            
            % Initialise hardware state properties (NOT USED YET)
            app.robot_state = 0;
            app.sensor_state = 0;
            app.source_state = 0;

            global state; % Global variable that contains all the state of the system
            state.stop_flag = 0; % security flag => the system will stop itself if state.stop_flag == 1 % formerly state.arret #TODO = verifier le fonctionnement
            state.source_connected = 0; % source trigeering connexion => if connected state.source_connected == 1 % formerly state.opo
            state.calibrated = 0; % calibration state => if the calibration is done state.calibration == 1 % formerly state.etal
            state.robot_connected = 0;  % robot connexion => if connected state.robot == 1 % formerly state.robot
            state.sensor_connected = 0;  % robot connexion => if connected state.robot == 1 % formerly state.robot

            global old_name; % Useful for map_from_spectra => Avoids loading the same data twice
            old_name = "";

            global mzXML; % Contains all data related to mass spectra
            mzXML.is_loaded = 0; % flag to know if there is any mass spectra data loaded

            % Folder_path = './code/hardware/robot/*.m'; Deprecated thanks
            % to the update_hardware function
            % if not(isdeployed)
            %     OptionNameList = folder_scan_without_extension(Folder_path, app);
            % else
            %     OptionNameList = readlines("/code/hardware/robot_types.txt");
            % end

            % % Remove the templates from dropdown menus
            % OptionName=[];
            % for i = 1:length(OptionNameList)
            %     if strcmp(OptionNameList(i), "_template_(name_MUST_be_changed)") == 0 && strcmp(i, "") == 0
            %         OptionName=[OptionName;OptionNameList(i)];
            %     end
            % end

            OptionName = readlines("/code/hardware/robot_types.txt");
            app.RobotDropDown.Items = OptionName;

            % Folder_path = './code/hardware/source/*.m';
            % if not(isdeployed)
            %     OptionNameList = folder_scan_without_extension(Folder_path, app);
            % else 
            %     OptionNameList = readlines("/code/hardware/source_types.txt");
            % end
            % Remove the templates from dropdown menus
            % OptionName=[];
            % for i = 1:length(OptionNameList)
            %     if strcmp(OptionNameList(i), "_template_(name_MUST_be_changed)") == 0 && strcmp(i, "") == 0
            %         OptionName=[OptionName;OptionNameList(i)];
            %     end
            % end

            OptionName = readlines("/code/hardware/source_types.txt");
            app.SourceDropDown.Items = OptionName;

            % Folder_path = './code/hardware/sensor/*.m';
            % if not(isdeployed)
            %     OptionNameList = folder_scan_without_extension(Folder_path, app);
            % else
            %     OptionNameList = readlines("/code/hardware/sensor_types.txt");
            % end
            % % Remove the templates from dropdown menus
            % OptionName=[];
            % for i = 1:length(OptionNameList)
            %     if strcmp(OptionNameList(i), "_template_(name_MUST_be_changed)") == 0 && strcmp(i, "") == 0
            %         OptionName=[OptionName;OptionNameList(i)];
            %     end
            % end

            OptionName = readlines("/code/hardware/sensor_types.txt");
            app.SensorDropDown.Items = OptionName;

            % Folder_path = './code/hardware/mass_spectrometer/*.m';
            % if not(isdeployed)
            %     OptionNameList = folder_scan_without_extension(Folder_path, app);
            % else
            %     OptionNameList = readlines("/code/hardware/mass_spectrometer_types.txt");
            % end
            % % Remove the templates from dropdown menus
            % OptionName=[];
            % for i = 1:length(OptionNameList)
            %     if strcmp(OptionNameList(i), "_template_(name_MUST_be_changed)") == 0 && strcmp(i, "") == 0
            %         OptionName=[OptionName;OptionNameList(i)];
            %     end
            % end

            OptionName = readlines("/code/hardware/mass_spectrometer_types.txt");
            app.MassSpectrometerTrigerringDropDown.Items = OptionName;

            % Folder_path = './code/method/scan_selection/*.m';
            % if not(isdeployed)
            %     OptionNameList = folder_scan_without_extension(Folder_path, app);
            % else
            %     OptionNameList = readlines("/code/method/scan_methods.txt");
            % end
            % % Remove the templates from dropdown menus
            % OptionName=[];
            % for i = 1:length(OptionNameList)
            %     if strcmp(OptionNameList(i), "_template_(name_MUST_be_changed)") == 0  && strcmp(i, "") == 0
            %         OptionName=[OptionName;OptionNameList(i)];
            %     end
            % end

            OptionName = readlines("/code/method/scan_methods.txt");
            app.ScanSelectionMethodDropDown.Items = OptionName;
             
            global arduino_object % For Prism Laboratory Setup Only
            if not(isempty(arduino_object))
               clear(arduino_object);
            end
            arduino_object = arduino(); % DEVNOTE: Arduinos should be more modular in their implementation
            disp(arduino_object)


            % This code snippet automatically corrects the workspace. Not
            % needed for the compiled version, but can probably help in the
            % MatLab environment.
%             dest = '/home/pchaillo/Documents/prism'; % chemin en dur, à bien choisir avant de décommenter cette partie
%             cd(dest);

        end

        % Button pushed function: LaunchMappingButton
        function LaunchMappingButtonPushed(app, event)
%           While I would rather set conditions for the button to only be
%           enabled once every part of the chosen workflow is properly set,
%           this would cause a massive overhead with MatLab as it currently
%           is. Catching misconfiguration before it causes a mapping error
%           is therefore the next best thing. 

%           Global conditions for imaging
            if isempty(app.GeneratedmapfileEditField) == 1 
                    msg = "Please input a map name in the acquisition properties tab and try again";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];   
                    return
            elseif app.RobotStateDisconnectedLabel.Text == "Robot State: Disconnected" 
                    msg = "Please turn the robot on and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
            end

%           Local conditions for imaging
            selectedButton = app.MappingChoiceButtonGroup.SelectedObject;
            if selectedButton == app.TopographyOnlyButton
                if app.SensorStateOffLabel.Text == "Sensor State: Off"
                    msg = "Please turn the sensor on, calibrate it if needed, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18]; 
                    return
                end
            elseif selectedButton == app.DMSIButton  % 3D MSI
                if app.SensorStateOffLabel.Text == "Sensor State: Off"
                    msg = "Please turn the sensor on, calibrate it if needed, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18]; 
                    return
                elseif app.SourceStateOffLabel.Text ~= "Source State: Lamp Ready"
                    msg = "Please turn the laser and the lamp on in the laser tab, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
                end
            elseif selectedButton == app.DMSIButton_2  % 2D MSI
                if app.SourceStateOffLabel.Text ~= "Source State: Lamp Ready"
                    msg = "Please turn the laser and the lamp on in the laser tab, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
                end
            end
            
            app.LaunchMappingButton.FontColor = [0.2 0.8 0.2];
            app.StatusWaitingLabel.Text = 'Mapping in progress...';

             % Variables initialisation
            global parameters
            global state;
            
            check_state(state, app)
            state.arret = 0; % permet arret securité total en cas de soucis

            global map; % utile ? #TODO
            global source;
            global robot;
            global sensor;

            triggering.type = app.MassSpectrometerTrigerringDropDown.Value;
            triggering.class = eval(triggering.type);

            %% Parameters => Import user parameters from the GUI
            map_name_raw = app.GeneratedmapfileEditField.Value;
            check_isempty(app, map_name_raw,'.map');
            map_name = strcat(map_name_raw,'.map');
            parameters.mapping_step = app.ResolutionmmEditField.Value;         % mapping step in mm
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.y_offset = app.FirstmappingpointYpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.dim_x = app.XmmEditField.Value;        % dimension of the mapping zone in the x axis, should be an even value % Even value only ???? #TODO
            parameters.dim_y = app.YmmEditField.Value;        % dimension of the mapping zone in the y axis, should be an even value
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm
            parameters.initial_height = app.FocaldistancemmEditField.Value + parameters.surface_offset ;          % z offset between the robot arm and the sample to be analysed

            source.voltage_value = app.EnergylevelVSpinner.Value; % With some laser, you may set the voltage you want in order to tune the intensity of the laser 
            source.time_step = app.TimebetweenlaserburstssecondEditField.Value; % pause time between two laser shot, in second
            source.nb_shot = app.NumberoflasershotsburstmodeEditField.Value; % number of shot for the burst mode
            source.continuous_flag = app.ContinuousAcquisitionCheckBox.Value; % 0 => no continuous / 1 => continuous

            parameters.fast_flag = app.FastmappingButton.Value; % I think it's a variable to not do repositionning if it's difficult to get height data from depth sensor

            pos_i= [parameters.x_offset   2  parameters.initial_height 180 0 180]; % initial position
            robot.class.set_position(pos_i);  % send the mapping initial position of the robot

            %% To select and launch the mapping code
            if app.DMSIButton.Value %% For 3D imaging with Mass Spectrometry
                time_ref = triggering.class.trigger();
                map = biologic_3D_acquisition(app, robot, sensor, source, parameters, time_ref); % do the mapping step
            elseif app.TopographyOnlyButton.Value %% For 3D acquisition only (without mass spectrometry)
                time_ref = triggering.class.trigger();
                map = topographic_acquisition(app, robot, sensor, parameters,time_ref); 
            elseif app.DMSIButton_2.Value  %% For 2D imaging with Mass Spectrometry
                time_ref = triggering.class.trigger();
                map = biologic_2D_acquisition(app, robot, source, parameters, time_ref);
            else
                update_log(app, 'ERROR - Mapping choice problem');
                app.StatusWaitingLabel.Text = "Error - Mapping choice problem";
                app.StatusWaitingLabel.FontColor = [0.64 0.08 0.18];
            end

            %[ map.r, nb_err ] = map_rectification(map.i); % correction of
            % the map %%( delete impossible values ) #TOCHOOSE
            map.z = map.i; % two time the same ! #TODO
            figure()
            subplot(2,4,[1,2,5,6]), mesh(map.x,map.y,map.i); % initial map
            axis equal
            subplot(2,4,[3,4,7,8]), mesh(map.x,map.y,map.z); % rectified map
            axis equal

            time_rec = record_map(map,map_name);

            time_text = strcat('Map finished. Recommended reconstruction time : ', time_rec);
            app.StatusWaitingLabel.Text = time_text;
            app.StatusWaitingLabel.FontColor = [0 0 0];
            app.LaunchMappingButton.FontColor = [0.00 0.00 0.00];
            
            % Once the map is done, go back to the 1st point position
            initial_position= [parameters.x_offset  parameters.y_offset  parameters.initial_height 180 0 180];
            robot.class.set_position(initial_position);  % send the mapping initial position of the robot
        end

        % Button pushed function: RobotConnexionB
        function RobotConnexionBButtonPushed(app, event)
            global state;
            check_state(state, app);

            global robot; % ou fermeture de la connexion
            
            robot.type = app.RobotDropDown.Value;
            robot.class = eval(robot.type);
            robot.class.init(app);

            app.StateLampRobot.Color = [0 1 0];
            app.LampOnlyTopo.Color = [0 1 0];

            state.robot_connected = 1 ;
            
            app.DisconnectButton.Enable = "on";
            app.XPositionEditField.Enable = "on";
            app.YPositionEditField.Enable = "on";
            app.ZPositionEditField.Enable = "on";
            app.GOTOButton.Enable = "on";

            app.RobotConnexionB.Enable = "off";
            app.DisconnectRobotButton.Enable = "on";
            app.RobotStateDisconnectedLabel.Text = "Robot State: Connected";
        end

        % Button pushed function: ConnectSourceButton
        function ConnectSourceButtonPushed(app, event)
            global state;
            check_state(state, app)

            app.StateLampOpotek.Color = [0 0 1];

            global source

            source.voltage_value = app.EnergylevelVSpinner.Value; % Put on source variable instead ?  
            source.type = app.SourceDropDown.Value;

            app.StateLampOpotek.Color = [0 0 1];

            update_log(app, "Laser Energy Level : " + source.voltage_value)
            update_log(app, "Laser Type : " + source.type)

            source.class = eval(source.type);

            source.class.init(app);

            [state_string, state_double] = source.class.get_state(app);

            % State double : describe the state of the laser => 
            % 0 - Not connected
            % 1 - Connected - Ready to turn the lamp on
            % 2 - Lamp on - Ready for shooting
            % -1 - Emergency - Problem during communication
    
            if state_double == 1 || state_double == 2
                app.StateLampOpotek.Color = [0 1 0];
            end

            app.AffichageState.Text = state_string;

            if source.type == "Opotek IR Radiant"
                wdog_loop = 1; % There is a watchdog on the Opotek laser (also on Opolette ?) that puts the laser in security mode if no message is received within four seconds. In order to avoid this, as message is sent every two seconds. If the laser is in security mode, please restart it in order to be able to command it again.
                while wdog_loop == 1
                    [state_string, state_double]= source.class.get_state(app);
                    if state_string == -1
                        wdog_loop = 0;
                        update_log(app, 'Loose connexion : no more Opotek feedback')
                    end
                    app.AffichageState.Text = state_string;
                    pause(2);
                end
            end
            
            app.DisconnectButton.Enable = "on";
            app.GetTempButton.Enable = "on";
            app.TurnLampOnButton.Enable = "on";
            app.ConnectSourceButton.Enable = "off";
            app.SourceStateOffLabel.Text = "Source State: On";
            app.SourceStateOffLabel.FontColor = [0.2 0.8 0.2];

            state.source_connected = 1;
        end

        % Button pushed function: LaunchSensorCalibrationButton
        function LaunchSensorCalibrationButtonPushed(app, event)
            app.SensorCalibrationstateLamp.Color = [0 0 1];

            global robot;
            global parameters
            global state;
            global sensor;

            check_state(state, app);

%             scan.etal = 0.25; % pas d'étallonage => 0.25 mm recommandé / step for the camera calibration % variable dans l'interface % #TODO
%             sensor.class.calibration_step % faire remonter dans
%             l'interface ou en dur dans le code objet des capteurs ? #TODO
%             #TOCHOOSE
        
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % X offset (mm) between the robot base and the robot head
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % Surface offset in mm
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % Maximum height (mm) of the sample you want to map, add a little bit more than real height

            % ard = arduino(); % connect the arduino (sensor acquisition and spectro triggering)

            if sensor.class.need_calibration == 1
                calibration_array = sensor.class.calibration(robot,parameters, app); % calibration of the sensor (ILD1320-25)
                sensor.calibration_array = fix_calibration_array(app, calibration_array); % correction of the calibration ( delete false values )
            
                % Show the calibration graph
                figure()
                hold on
                for i = 1 : length(sensor.calibration_array)
                    scatter(sensor.calibration_array(1,i),sensor.calibration_array(2,i))
                end
                xlabel('Hauteur de mesure, en mm')
                ylabel('Tension mesuree')
                hold off
                app.SensorCalibrationstateLamp.Color = [0 1 0];
                app.LampOnlyTopo.Color = [0 1 0];
                state.calibrated = 1;
                if state.source_connected == 1
                    app.LampWithSpectro.Color = [0 1 0];
                end
            else 
                update_log(app, "No calibration needed for this sensor")
            app.SensorStateOffLabel.Text = "Sensor State: Calibrated";
            app.SensorStateOffLabel.FontColor = [0.2 0.8 0.2];
            end
        end

        % Button pushed function: LaserBurstButton
        function LaserBurstButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source;

            source.nb_shot = app.NumberoflasershotsburstmodeEditField.Value; % number of shot for the burst mode

            source.class.trigger(source.nb_shot, app)

        end

        % Button pushed function: GotoMappingPositionButton
        function GotoMappingPositionButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global parameters;
            global robot;

            %for security check
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.initial_height = app.FocaldistancemmEditField.Value + parameters.surface_offset;          % z offset between the robot arm and the sample to be analysed

            initial_position = [parameters.x_offset  2  parameters.initial_height 180 0 180]; % Initial position % format dependant du robot #TODO
            robot.class.set_position(initial_position);  % send the initial position to the robot

        end

        % Button pushed function: TurnLampOnButton
        function TurnLampOnButtonPushed(app, event)
            app.Lamp_LampONorOFF.Color = [0 0 1];
            global source;

            temp = source.class.get_temp(app);
            text_temp = strcat('Temperature : ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;

            state_string = source.class.lamp_on(app);
            [state_string, state_double] = source.class.get_state(app);
            app.AffichageState.Text = state_string;

            if state_double == 2
                app.Lamp_LampONorOFF.Color = [0 1 0];
            else
                app.Lamp_LampONorOFF.Color = [ 1 0 0];
            end

            global state;
            check_state(state, app);
            if state.calibrated == 1
                app.LampWithSpectro.Color = [0 1 0];
            end
            if state.robot_connected == 1
                app.LampWithSpectro_2D.Color = [0 1 0];
            end
            state.source_connected = 1;
            
            app.TurnLampOffButton.Enable = "on";
            app.GetStateButton.Enable = "on";
            app.FocaldistancemmEditField.Enable = "on";
            app.EnergylevelVSpinner.Enable = "on";
            app.SourceStateOffLabel.Text = "Source State: Lamp Ready";
            app.SourceStateOffLabel.FontColor = [0.2 0.8 0.2];
            app.LaserBurstButton.Enable = "on";
            app.ContinuousAcquisitionCheckBox.Enable = "on";
            app.TimebetweenlaserburstssecondEditField.Enable = "on";
            app.NumberoflasershotsburstmodeEditField.Enable = "on";

        end

        % Button pushed function: TurnLampOffButton
        function TurnLampOffButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source;

            state_string = source.class.lamp_off(app);
            [state_text, state_double] = source.class.get_state(app);
            if state_double == 1
                app.Lamp_LampONorOFF.Color = [1 0 0];
            else
                app.Lamp_LampONorOFF.Color = [0 1 1];
            end
            app.AffichageState.Text = state_text;

            %app.Lamp_LampONorOFF.Color = [0 1 1];
            app.SourceStateOffLabel.Text = "Source State: On";
            app.SourceStateOffLabel.FontColor = [0.8 0.3 0];
        end

        % Button pushed function: DisconnectRobotButton
        function DisconnectRobotButtonPushed(app, event)
            app.RobotConnexionB.Enable = "on";
            app.XPositionEditField.Enable = "off";
            app.YPositionEditField.Enable = "off";
            app.ZPositionEditField.Enable = "off";
            app.GOTOButton.Enable = "off";
            
            global robot;
            global parameters;
            global state;
            
            check_state(state, app);
            
            %for security check
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % Surface offset in mm

            robot.class.go_to_rest_position(app); % Too many input argument ? #TODO

            app.StateLampRobot.Color = [1 0 0];

            
            state.robot_connected = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];
            
            app.RobotConnexionB.Enable = "on";
            app.DisconnectRobotButton.Enable = "off";
            app.RobotStateDisconnectedLabel.Text = "Robot State: Disconnected";
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source; 
            source.class.disconnect(app);
            app.StateLampOpotek.Color = [1 0 0];
            app.SourceStateOffLabel.Text = "Source State: Off";
            app.SourceStateOffLabel.FontColor = [1 0 0];
            app.TurnLampOffButton.Enable = "off";
            app.TurnLampOnButton.Enable = "off";
            app.ConnectSourceButton.Enable = "on";
            app.DisconnectButton
        end

        % Button pushed function: GetTempButton
        function GetTempButtonPushed(app, event)
            global state;
            check_state(state, app)

            global source 
            temp = source.class.get_temp(app);
            text_temp = strcat('Temperature :  ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;
        end

        % Button pushed function: GOTOButton
        function GOTOButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global robot;
            global parameters;

            %for security check
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            x = app.XPositionEditField.Value;
            y = app.YPositionEditField.Value;
            z = app.ZPositionEditField.Value;
            position = [x  y  z 180 0 180];
            robot.class.set_position(position);  % send the mapping initial position of the robot

        end

        % Button pushed function: GetStateButton
        function GetStateButtonPushed(app, event)
            global source;
            global state;
            check_state(state, app)
            [state_text, state_double] = source.class.get_state(app);
            % state_text = choose_state_text(state_double);
            if state_double == -1
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.LampWithSpectro.Color = [1 0 0];
                app.StateLampOpotek.Color = [1 0 0];
                state.source_connected = 0;
            elseif state_double == 0
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.LampWithSpectro.Color = [1 0 0];
                app.StateLampOpotek.Color = [1 0 0];
                state.source_connected = 0;
            elseif state_double == 1
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.StateLampOpotek.Color = [0 1 0];
                state.source_connected = 1;
            end

            app.AffichageState.Text = state_text;

        end

        % Value changed function: EnergylevelVSpinner
        function EnergylevelVSpinnerValueChanged(app, event)
            global source;

            app.LampVoltageSet.Color = [0 0 1];

            source.class.voltage_value = app.EnergylevelVSpinner.Value;
            real_voltage = source.class.set_voltage(source.class.voltage_value, app);
            if real_voltage ~= source.voltage_value
                app.LampVoltageSet.Color = [1 0 0];
            else
                app.LampVoltageSet.Color = [0 1 0];
            end

        end

        % Button pushed function: ClearMapButton
        function ClearMapButtonPushed(app, event)
            global map;

            map.x = 0;
            map.y = 0;
            map.i = 0;
            map.r = 0;

        end

        % Selection changed function: ButtonGroupMappingSpeed
        function ButtonGroupMappingSpeedSelectionChanged(app, event)
            global parameters;
            parameters.fast_flag = app.FastmappingButton.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Disconnects the robot
            global robot;
            global parameters
            %for security check
            parameters.maximal_height = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            global state;
            if isfield(state,'robot')
                if state.robot_connected == 1
                    robot.class.disconnect(app);
                end
            end
            app.StateLampRobot.Color = [1 0 0];

            global state;
            state.robot_connected = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];

            global arduino_object
            global sensor
            clear("arduino_object")
            clear("sensor")
%             clear all % clear app so you may not close it afterward ?

            %% Close the app
            delete(app)

        end

        % Button pushed function: GeneratematfileButton
        function GeneratematfileButtonPushed(app, event)
            global mzXML;
            pixels_scans = mzXML.pixels_scans; % Please change this to pixel_scans

            win = app.BiningwindowsizeEditField.Value;

            mat_r = app.GeneratedmatfileEditField.Value;
            check_isempty(app, mat_r,'.mat')
            nom = strcat(mat_r,'.mat');

            l = length(pixels_scans);

            pixels_scans_processed = preprocess_all_image(app, pixels_scans, win);
            
%             fprintf('(%s) - Preprocessing data \n', datestr(now,'HH:MM:SS'));
%             for i = 1 : l
%                 pixels_scans_processed(i) = preprocess(app, pixels_scans(i),win); % pixels_scans_processed
%                 X = sprintf('Preprocessing : %d / %d',i,l);
%                 update_log(app, X)
%             end

            threshold_begin = app.BeginThresholdEditField.Value;
            time_step = app.TimebetweentwolaserburstssEditField.Value;
            update_log(app, app.LoudThresholdEditField.Value)
            noise_threshold = app.LoudThresholdEditField.Value;

            tolerance = app.FusionpercentSlider.Value;

            intern_trig = app.InternalTriggeringSwitch.Value;
            if intern_trig(1:2) == 'On'
                intern_flag = 1;
            else
                intern_flag = 0;
            end

            pixels_scans = pixels_scans_processed; 
            pixels_scans(1).comment = [threshold_begin, noise_threshold, time_step,tolerance,intern_flag ]; % to stock the parameters somewhere with the data

            save('pixels_scans.mat', 'pixels_scans', '-v7.3')
            folder_name = '/files/mat files';
            chemin  = path_editor(folder_name,nom);
            movefile('pixels_scans.mat',chemin);
            app.MatFileEditField.Value = mat_r;

            update_log(app, "Mat file recorded in /files/mat files/")
            MatFileEditFieldValueChanged(app, event)
            ExitButtonPushed(app, event) 
        end

        % Value changed function: MasslimitsMinimumEditField
        function MasslimitsMinimumEditFieldValueChanged(app, event)
            limits = app.MasslimitsMinimumEditField.Value;
            nom_map = app.MapFileEditField.Value;

            limi = round(limits(1));
            mass_str = num2str(limi);
            nom = strcat( nom_map,'_',mass_str);%,'.biomap');

            mz_shift = 0.25; % ussual difference between the mass limits

            app.MaximumEditField.Value = limits + mz_shift;
        end

        % Button pushed function: LaunchReconstructionButton
        function LaunchReconstructionButtonPushed(app, event)

            nom_mat_r = app.MatFileEditField.Value;
            nom_mat = strcat(pwd, '/files/mat files/',  nom_mat_r,'.mat');
            load(nom_mat);

            nom_map_r = app.MapFileEditField.Value;
            nom_map = strcat(pwd, '/files/map files/', nom_map_r,'.map');
            map = load_map_fcn(nom_map);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;
            limits = [l_min l_max];

            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));
            fprintf('(%s) - Starting to build the intensity matrix\n', datestr(now,'HH:MM:SS'));

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            if isfield(map,'time')
                time_flag = 1;
            else
                time_flag = 0;
                map_time = 0;
            end

            [ pixels_ind, scans_ind, pixels_mz, fusion_list] = data_on_map(app, pixels_scans,map,limits,map.time,time_flag,loud_flag);

            [ map.x,map.y,map.z,pixels_mz  ] = fix_border(map.x,map.y,map.z,pixels_mz,pixels_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
            title_str = strcat( "biometric map, mass limits : ", num2str(limits(1)) ,'  -  ',num2str(limits(2)) );
            display_mz_map(map,pixels_mz,title_str)

        end

        % Button pushed function: LaunchTimeEstimationButton
        function LaunchTimeEstimationButtonPushed(app, event)

            global mzXML;

            threshold_begin = app.BeginThresholdEditField.Value;
            noise_threshold = app.LoudThresholdEditField.Value; 

            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));
            %             [bio_dat ,time,g] = load_mzXML_p4(mzXMLStruct,threshold_begin,t_b); % take only the usefull informations
            time = time_from_peaks(app, mzXML.contents, threshold_begin, noise_threshold); % take only the usefull informations

            time_str = num2str(time);
            time_str2 = strcat(time_str, ' seconds');
            app.TimeValue.Text = time_str2;

        end

        % Button pushed function: DisplayOneSpectrumButton
        function DisplayOneSpectrumButtonPushed(app, event)
            mat_file_r = app.MatFileEditField.Value;
            mat_name = strcat(mat_file_r,'.mat');
            load(mat_name)

            map_name_r = app.MapFileEditField.Value;
            map_name = strcat(map_name_r,'.map');
            map = load_map_fcn(map_name);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_one_spectra(app, pixels_scans, map, mat_name, limits,loud_data)
        end

        % Button pushed function: DisplaySpectraZoneButton
        function DisplaySpectraZoneButtonPushed(app, event)


            nom_mat_r = app.MatFileEditField.Value; % creer une routine de chargement des donnees ? => factoriser aussi pour n'avoir que 3 lignes de chargement ici ? fonction load and load ma
            check_isempty(app, nom_mat_r,'.mat')
            nom_mat = strcat(pwd, '/files/mat files/', nom_mat_r,'.mat');
            load(nom_mat)

            nom_map_r = app.MapFileEditField.Value;
            check_isempty(app, nom_map_r,'.map')
            nom_map = strcat(nom_map_r,'.map');
            map = load_map_fcn(nom_map);
            
            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_spectra_zone(app, pixels_scans, map, limits,loud_data,nom_mat_r)

        end

        % Button pushed function: PlotChromatogramButton
        function PlotChromatogramButtonPushed(app, event)

            global mzXML;

            if mzXML.is_loaded == 1
                figure()
                plot_chromatogram(mzXML.contents)
            else
                update_log(app, "No Mass Spectra file loaded : Impossible to plot chromatogram") % ERROR_MESSAGE
            end
        end

        % Value changed function: ResolutionmmEditField
        function ResolutionmmEditFieldValueChanged(app, event)

            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.NumberoflasershotsburstmodeEditField.Value;
            time_step = app.TimebetweenlaserburstssecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
            app.Resolutionm500Label.Text = strcat('Resolution(µm): ', num2str(step_size*1000));
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.NumberoflasershotsburstmodeEditField.Value;
            time_step = app.TimebetweenlaserburstssecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.X16Label.Text = strcat("X: ", num2str(size_x));
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
        end

        % Value changed function: YmmEditField
        function YmmEditFieldValueChanged(app, event)

            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.NumberoflasershotsburstmodeEditField.Value;
            time_step = app.TimebetweenlaserburstssecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.Y16Label.Text = strcat("Y: ", num2str(size_y));
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
        end

        % Value changed function: TimebetweenlaserburstssecondEditField
        function TimebetweenlaserburstssecondEditFieldValueChanged(app, event)

            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.NumberoflasershotsburstmodeEditField.Value;
            time_step = app.TimebetweenlaserburstssecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: NumberoflasershotsburstmodeEditField
        function NumberoflasershotsburstmodeEditFieldValueChanged(app, event)

            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.NumberoflasershotsburstmodeEditField.Value;
            time_step = app.TimebetweenlaserburstssecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Button pushed function: MapFromSpectraButton
        function MapFromSpectraButtonPushed(app, event)
            valmin = app.MinEditField.Value;
            valmax = app.MaxEditField.Value;

            update_log(app, string([valmin,valmax]))

            global all_peaks; % Variable to not reload data each time it's diplayed (not reload if compute_flag == 1)
            global old_name;

            nom_mat_r = app.MatFileEditField.Value;
            nom_mat = strcat(pwd, '/files/mat files/', nom_mat_r,'.mat');
            load(nom_mat) % loas pixels_scans variable % génère la variable pixels_scans

            nom_map_r = app.MapFileEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            map = load_map_fcn(nom_map);

            if length(nom_mat_r) == length(old_name)
                if nom_mat_r == old_name
                    compute_flag = 1;
                else
                    compute_flag = 0;
                end
            else
                compute_flag = 0;
            end

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            all_peaks = plot_all_spectra(app, pixels_scans, compute_flag, all_peaks, valmin, valmax);
            map_from_spectra(app, pixels_scans, map,loud_flag);

            old_name = nom_mat_r;
        end

        % Button pushed function: LaunchRectificationButton
        function LaunchRectificationButtonPushed(app, event)

            nom_map_r = app.MapFileEditField.Value;
            check_isempty(app, nom_map_r,'.map')
            % fullfile % #TODO
            nom_map = strcat(pwd, '/files/map files/', nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            map = load_map_fcn(nom_map);

            new_map_name_raw = app.NameofthecorrectedmapEditField.Value;
            new_map_name = strcat(new_map_name_raw,'.map');

            min_height_threshold = app.MinimumthresholdmmEditField.Value;
            max_height_threshold = app.MaximumthresholdmmEditField.Value;

            map_height = map.z;

            [corrected_map_height, nb_err]= map_rectification(map_height, min_height_threshold, max_height_threshold);

            map.z = reccursive_topographic_correction(app, map, corrected_map_height, min_height_threshold, max_height_threshold);

            time_gap_median = record_map(map,new_map_name);

%             if isfield(map,'time')
%                 time_diff_med = record_map(map.x,map.y,map_out,nom_map_new,map.time);
%             else
%                 record_map_without_time(map.x,map.y,map_out,nom_map_new,threshold);
%             end

        end

        % Button pushed function: LaunchInterpolationButton
        function LaunchInterpolationButtonPushed(app, event)
            nom_map_r = app.MapFileEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            map = load_map_fcn(nom_map);

            nom_new_map_r = app.NameoftheinterpolatedmapEditField.Value;
            nom_map_new = strcat(nom_new_map_r,'.map');

            multiplier = app.PointMultiplierEditField.Value ;

            interpolated_map = polynomial_interpolation(map,multiplier);

            record_map(interpolated_map,nom_map_new);

        end

        % Callback function
        function LaunchBiomapInterpolationButtonPushed(app, event)

            nom_map_r = app.NameofthebiomapEditField.Value;
            nom_map = strcat(nom_map_r,'.biomap');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name

            [map,bio_map,limits] = load_biomap_fcn(nom_map);

            nom_new_map_r = app.NameoftheinterpolatedbiomapEditField.Value;
            nom_map_new = strcat(nom_new_map_r,'.biomap');

            scan_pre = app.PointMultiplierEditField_2.Value ;

            si = size(map.x)-1;

            map2.x = map.x(1:si(1),1:si(2));
            map2.y = map.y(1:si(1),1:si(2));
            map2.z = map.z(1:si(1),1:si(2));
            bio_map2 = bio_map(1:si(1),1:si(2));


            [interpolated_map, interpolated_map_x, interpolated_map_y, interpolated_map_time, interpolated_bio_data] = polynomial_interpolation_bio3(map2,scan_pre,bio_map2);

            %           [ interpolated_map_x,interpolated_map_y,interpolated_map,interpolated_bio_data  ] = fix_border_2(interpolated_map_x,interpolated_map_y,interpolated_map,interpolated_bio_data,bio_ind);

            seuil = 0;

            if isfield(map,'time')
                record_BIOmap_time(interpolated_map_x,interpolated_map_y,interpolated_map,interpolated_bio_data,nom_map_new,limits,interpolated_map_time,time_diff_med)
            else
                record_BIOmap2(interpolated_map_x,interpolated_map_y,interpolated_map,interpolated_bio_data,nom_map_new,limits)
            end

            figure()
            s = surf(interpolated_map_x,interpolated_map_y,interpolated_map,interpolated_bio_data);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['biometric map, mass limits : ', num2str(limits(1)),'  ',num2str(limits(2))]);

        end

        % Button pushed function: LaunchLDAReconstructionButton
        function LaunchLDAReconstructionButtonPushed(app, event)

            nom_map_r = app.NameofthemapfileEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            map = load_map4_fcn(nom_map);

            nom_mat_r = app.NameofthematfileEditField.Value;
            nom_mat = strcat(pwd, '/files/mat files/', nom_mat_r,'.mat');
            load(nom_mat);

            nom_csv_r = app.NameofthecsvfileEditField.Value;
            nom_csv = strcat(nom_csv_r,'.csv');
            T = readtable(nom_csv,'NumHeaderLines',5);  % skips the first three rows of data

            scan.num = T.StartScan;
            scan.num_end = T.EndScan;
            scan.class = T.ClassName;
            scan.prob = T.Probability;

            class(1) = string(app.Class01EditField.Value);
            class(2) = app.Class02EditField.Value;
            class(3) = app.Class03EditField.Value;
            class(4) = app.Class04EditField.Value;
            class(5) = app.Class05EditField.Value;
            class(6) = app.Class06EditField.Value;

            if isempty(class) == 0

                couleur_n(1) = string(app.ColorDropDown.Value);
                couleur_n(2) = app.ColorDropDown_2.Value;
                couleur_n(3) = app.ColorDropDown_3.Value;
                couleur_n(4) = app.ColorDropDown_4.Value;
                couleur_n(5) = app.ColorDropDown_5.Value;
                couleur_n(6) = app.ColorDropDown_6.Value;

                for i = 1 : length(couleur_n)
                    if couleur_n(i) == "Red"
                        color(i,:) = [0.9 0 0];
                    elseif couleur_n(i) == "Green"
                        color(i,:) = [0 0.8 0];
                    elseif couleur_n(i) == "Blue"
                        color(i,:) = [0 0 1];
                    elseif couleur_n(i) == "Orange"
                        color(i,:) = [1 0.5 0];
                    elseif couleur_n(i) == "Yellow"
                        color(i,:) = [0.9 0.9 0];
                    elseif couleur_n(i) == "Violet"
                        color(i,:) = [0.9 0 0.9];
                    elseif couleur_n(i) == "None"
                        color(i,:) = [0 0 0];
                    end
                 end

                if isfield(map,'time')
                    time_flag = 1;
                    map_time = map.time;
                else
                    time_flag = 0;
                    map_time = 0;
                end

                limits = [885 886]; % totalement arbitraire, ces valeurs ne sont pas utiles pour la suite

                [bio_ind, bio_num, ~, fusion_list] = data_on_map(bio_dat,map.z,limits,map_time,time_flag);
           
                si = size(map.z);
                bio_map_class = zeros(si(1),si(2),3);

                back_color = app.BackgroundColorDropDown.Value;
                if back_color == "White"
                    bio_map_class(:,:,:) = 1;
                elseif back_color == "Grey"
                    bio_map_class(:,:,:) = 0.5;
                end

                bio_map_class = bio_class_builder3(scan,bio_num,class,color,fusion_list,bio_ind,bio_map_class); % #TODO bio_class_builder ?

                [ map.x,map.y,map.z,bio_map_class  ] = fix_border(map.x,map.y,map.z,bio_map_class,bio_ind);

                fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
                figure()
                s = surf(map.x,map.y,map.z,bio_map_class);
                s.FaceAlpha = 0.9; % niveau de transparence
                s.FaceColor = 'flat'; % set color interpolation or not
                s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
                axis equal
                title('Biometric Class Map');
                legend(class);
            else
                msgbox("Please name at least one class to perform LDA.")
            end
        end

        % Button pushed function: LoadmzXMLButton
        function LoadmzXMLButtonPushed(app, event)

            update_log(app, "Loading of mzXML file in progress...")

            global mzXML

            mzxml_r = app.mzXMLfilenameEditField.Value;
            mzxml_name = strcat(mzxml_r,'.mzXML');
            mzXML.contents = mzxmlread_2019(mzxml_name);

            mzXML.is_loaded = 1;

            update_log(app, "Loading of mzXML file finished !")
            
            app.LaunchTimeEstimationButton.Enable = "on";
            app.PlotChromatogramButton.Enable = "on";
            app.MapfilenameEditField_2.Enable = "on";

        end

        % Button pushed function: LaunchExtractionButton
        function LaunchExtractionButtonPushed(app, event)
            mat_name_r = app.MatFileEditField.Value;
            mat_name = strcat(pwd, '/files/mat files/', mat_name_r,'.mat');
            load(mat_name);

            txt_name_r = app.OutputtextfileEditField.Value;
            txt_name = strcat(txt_name_r,'.txt');

            folder_txt = 'files\txt files';
            txt  = path_editor(folder_txt, txt_name);

            disp(txt)

            % Writing the txt file
            fid = fopen(txt,'wt'); % Those variables need better naming conventions
            fprintf(fid, 'mzML=true\n');
            fprintf(fid, 'zlib=false\n');
            fprintf(fid, 'filter="scanNumber');

            raw_name_r = app.InputWatersRAWfileEditField.Value;
            raw_name = strcat(raw_name_r,'.raw');           
            folder_raw = 'files\raw files';
            raw_folder  = path_editor(folder_raw, raw_name);

            l = length(pixels_scans);

            for i = 1 : l
                num = abs(pixels_scans(i).num);
                fprintf(fid,' [%d,%d]',num,num);
            end
            fprintf(fid,'"');

            fclose(fid);

            % The txt file is written and saved
            
            if isunix  % Light version of path_editor to accomodate the path leading to a folder instead of a file
                output_file_loc = strcat(app.workdir, "/files/conversion/");
            else
                output_file_loc = strcat(app.workdir, "\files\conversion\");
            end

%           Data extraction continues in a Python script 
            % pyrunfile("code/code_python/raw_conversion/raw_conversion.py", txt_file = txt, raw_file=raw_folder, output_loc = output_file_loc)

%           MatLab Replaement of the Python function
            raw_conversion(app, raw_folder, txt, output_file_loc)

        end

        % Callback function
        function EMERGENCYSTOPACQUISITIONButtonPushed(app, event)
            global source;
            global state;
            global robot;
            global sensor;

            global arduino_object; % for Prism setup only

            emergency_stop(source,robot,sensor,state,arduino_object,app)
        end

        % Button pushed function: ScanSelectionButton
        function ScanSelectionButtonPushed(app, event)
            global mzXML;
            global method;
          %  global parameters; $useless ? #TODO
            nom_map_r = app.MapfilenameEditField_2.Value;
            nom_map = strcat(pwd, '/files/map files/', nom_map_r,'.map');
            %out = load(nom_map); % Loads the topographical map. Remove any dot in the file name
            map = load_map_fcn(nom_map);

            if isfield(map,'time')
                map.time = map.time;
            else
                map.time = 0;
            end

            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));

            method.type = app.ScanSelectionMethodDropDown.Value;

            method.class = eval(method.type);
            method.class.init(app) 
            
            % AspirationTimesEditField
            
            if isfield(mzXML,'contents')
                [pixels_scans, estimated_gap_time] =  method.class.selection(mzXML.contents, map.time, app); % take only the useful informations
            else 
                message_error = "Attention: no mzXML file loaded. Cannot proceed with scan selection";
                update_log(app, message_error)
                msgbox(message_error)
            end

            mzXML.pixels_scans = pixels_scans;
            
            app.GeneratedmatfileEditField.Enable = "on";    

            disp("Scan selection OK, you may launch the generation of .mat file")
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            mz_min = app.MinMzEditField.Value;
            mz_max = app.MaxMzEditField.Value;
            if mz_min < mz_max
                band = [mz_min, mz_max];
            else
                band = [mz_max, mz_min];
            end
            win = app.BinningEditField.Value;
            map = strcat(app.MapFileEditField.Value, '.map');

            mat = strcat(app.MatFileEditField.Value, '.mat');
            
            csv_extractor(app, map, mat, band, win)
        end

        % Button pushed function: NormaliseCSVTICButton
        function NormaliseCSVTICButtonPushed(app, event)
            pyrunfile('CSV_TIC_norm.py')
            update_log(app, "Done!")

            % #TODO: Change those three buttons to a dropdown menu with a
            % method
        end

        % Button pushed function: ExportfromCSVButton
        function ExportfromCSVButtonPushed(app, event)
            pyrunfile('CSV_to_PLY.py')
            update_log(app, "Done!")
        end

        % Button pushed function: ExportandcoregisterfromCSVButton
        function ExportandcoregisterfromCSVButtonPushed(app, event)
            pyrunfile('CSV_to_PNG.py')
            update_log(app, "Data converted to PNG. Proceeding with coregistration.")
            pyrunfile('2-Picture_Manual_Registration.py')
            update_log(app, "Coregistration complete. Now generating the final PLY file.")
            pyrunfile('CSV_to_PLY.py')
            update_log(app, "Done!")
        end

        % Button pushed function: ConnectSensorButton
        function ConnectSensorButtonPushed(app, event)
            global state;
            check_state(state, app);

            update_log(app, "Sensor Connexion... ")
            
            global sensor; % Or shut the connexion down
            global arduino_object

            sensor.type = app.SensorDropDown.Value;

            sensor.class = eval(sensor.type);

            sensor.class.init(arduino_object);

            update_log(app, "Sensor Connected ")

            app.LaunchSensorCalibrationButton.Enable = "on";
            app.SensorStateOffLabel.Text = "Sensor State: Connected";
            app.SensorStateOffLabel.FontColor = [1 0 0];
            app.SensorstateLamp.Color = [0 1 0];
            
        end

        % Value changed function: ScanSelectionMethodDropDown
        function ScanSelectionMethodDropDownValueChanged(app, event)
             value = app.ScanSelectionMethodDropDown.Value;
             if strcmp(value, "TimeBasedMethod") == 1 % Direct comparison of strings cannot be done in certain MatLab versions
                 app.AspirationTimesEditField.Enable = "on";
                 app.BeginThresholdEditField.Enable = "off"; 
                 app.FusionpercentSlider.Enable = "off";
                 app.InternalTriggeringSwitch.Enable = "off";
                 app.LoudThresholdEditField.Enable = "off";
                 app.NeighbourNumberEditField.Enable = "on";
                 app.TimebetweentwolaserburstssEditField.Enable = "off";
                 
             elseif strcmp(value, "PeakPickingMethod") == 1
                 app.AspirationTimesEditField.Enable = "off";
                 app.FusionpercentSlider.Enable = "on";
                 app.InternalTriggeringSwitch.Enable = "on";
                 app.LoudThresholdEditField.Enable = "on";
                 app.NeighbourNumberEditField.Enable = "off";
                 app.TimebetweentwolaserburstssEditField.Enable = "on";
                 InternalTriggeringSwitchValueChanged(app, event)
                 
             end
        end

        % Value changed function: MatFileEditField
        function MatFileEditFieldValueChanged(app, event)
%           DEVNOTE: REPLACE WITH FILE SELECTION THROUGH POPUP, SAME GOES
%           FOR MAP FILES
            mat_name = app.MatFileEditField.Value;
            app.MapFileEditField.Value = mat_name; % Note: This means that 
            % the Map field can never be empty if the mat field is not. 

            if isempty(mat_name) == 0 && isempty(app.MapFileEditField.Value) == 0
              app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = 'All set!';
              app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [0.2 0.8 0.2];

%             Enable Processing Tab
              app.DisplayOneSpectrumButton.Enable = "on";
              app.DisplaySpectraSumButton.Enable = "on";
              app.DisplaySpectraZoneButton.Enable = "on";
              app.LaunchPCAButton.Enable = "on";
              app.LaunchReconstructionButton.Enable = "on";
              app.LaunchReconstructionButton_RGB.Enable = "on";
              app.LouddataonthemapSwitch.Enable = "on";
              app.MapFromSpectraButton.Enable = "on";
              app.MasslimitsMinimumEditField.Enable = 'on';
              app.MaximumEditField.Enable = "on";                       
              app.MaxEditField.Enable = "on";
              app.MinEditField.Enable = "on";             

              app.MinimumEditField_R.Enable = "on";
              app.MaximumEditField_R.Enable = "on";
              app.MinimumEditField_G.Enable = "on";
              app.MaximumEditField_G.Enable = "on";
              app.MinimumEditField_B.Enable = "on";
              app.MaximumEditField_B.Enable = "on";

%             Enable Post-Processing Tab 
              app.MaximumthresholdmmEditField.Enable = 'on';
              app.MinimumthresholdmmEditField.Enable = "on";
              app.NameofthecorrectedmapEditField.Enable = "on";
              app.NameoftheinterpolatedmapEditField.Enable = "on";

%             The Display from external data tab is unaffected
%             Enable Scils Extraction (Data Extraction) Tab
              app.BinningEditField.Enable = "on";
              app.ExporttoCSVButton.Enable = "on";              
              app.InputWatersRAWfileEditField.Enable = "on";
              app.OutputtextfileEditField.Enable = "on";

              app.MinMzEditField.Enable = "on";
              app.MaxMzEditField.Enable = "on";

            else
%             Since the Map text box is currently slaved to the Mat text
%             box, there is no need to account for only one of those two
%             being empty in this callback. This may change later on. 
              app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = "Please input a valid mat and map file name to start processing!";
              app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [1 0 0];

%             Disable Post-Processing Tab
              app.DisplaySpectraSumButton.Enable = "off";
              app.LaunchInterpolationButton.Enable = "off";
              app.LaunchRectificationButton.Enable = "off";
              app.MaximumthresholdmmEditField.Enable = "off";
              app.MinimumthresholdmmEditField.Enable = "off";
              app.NameofthecorrectedmapEditField.Enable = "off";
              app.NameoftheinterpolatedmapEditField.Enable = "off";
              app.PointMultiplierEditField.Enable = "off";             

%             Disable Processing Tab
              app.DisplayOneSpectrumButton.Enable = "off";
              app.DisplaySpectraZoneButton.Enable = "off";
              app.LaunchPCAButton.Enable = "off";
              app.LaunchReconstructionButton.Enable = "off";
              app.LaunchReconstructionButton_RGB.Enable = "off"; 
              app.LouddataonthemapSwitch.Enable = "off";
              app.MapFromSpectraButton.Enable = "off";
              app.MasslimitsMinimumEditField.Enable = 'off';
              app.MaximumEditField.Enable = "off";

              app.MaxEditField.Enable = "off";
              app.MinEditField.Enable = "off"; 
        
              app.MinimumEditField_R.Enable = "off";
              app.MaximumEditField_R.Enable = "off";
              app.MinimumEditField_G.Enable = "off";
              app.MaximumEditField_G.Enable = "off";
              app.MinimumEditField_B.Enable = "off";
              app.MaximumEditField_B.Enable = "off";

%             Disable Scils Extraction (Data Extraction) Tab
              app.BinningEditField.Enable = "off";
              app.ExporttoCSVButton.Enable = "off";
              app.InputWatersRAWfileEditField.Enable = "off";
              app.LaunchExtractionButton.Enable = "off";
              app.MaxMzEditField.Enable = "off";
              app.MinMzEditField.Enable = "off";
              app.OutputtextfileEditField.Enable = "off";
         
            end
        end

        % Value changed function: MapFileEditField
        function MapFileEditFieldValueChanged(app, event)
            map_name = app.MapFileEditField.Value;
            if isempty(map_name) == 0
                if isempty(app.MatFileEditField.Value) == 1
                    app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = 'No Mat file selected. Reduced interface functionnality.';
                    app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [1.00 0.41 0.16];
                else
                    app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = 'All set!';
                    app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [0.2 0.8 0.2];
                    MatFileEditFieldValueChanged(app, event)
                end 

%               Activate Post-Processing Tab 
                app.NameofthecorrectedmapEditField.Enable = "on";
                app.NameoftheinterpolatedmapEditField.Enable = "on";
            else
              if isempty(app.MatFileEditField.Value) == 0
                  app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = 'No Map file selected. Reduced interface functionnality.';
                  app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [1.00 0.41 0.16];
              else 
                  app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = "Please input a valid mat and map file name to start processing!";
                  app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.FontColor = [1 0 0];
              end

%             Disable Processing Tab
              app.DisplayOneSpectrumButton.Enable = "off";
              app.DisplaySpectraSumButton.Enable = "off";
              app.DisplaySpectraZoneButton.Enable = "off";
              app.LaunchPCAButton.Enable = "off";
              app.LaunchReconstructionButton.Enable = "off";
              app.LaunchReconstructionButton_RGB.Enable = "off";
              app.LouddataonthemapSwitch.Enable = "off";
              app.MapFromSpectraButton.Enable = "off";

              app.MasslimitsMinimumEditField.Enable = 'off';
              app.MaximumEditField.Enable = "off";
              
              app.MaxEditField.Enable = "off";
              app.MinEditField.Enable = "off";
              
              app.MaximumEditField_R.Enable = "off";
              app.MinimumEditField_R.Enable = "off";
              app.MaximumEditField_G.Enable = "off";
              app.MinimumEditField_G.Enable = "off";
              app.MaximumEditField_B.Enable = "off";
              app.MinimumEditField_B.Enable = "off";

%             Disable Post-Processing Tab
              app.MaximumthresholdmmEditField.Enable = 'off';              
              app.MinimumthresholdmmEditField.Enable = "off";        
              app.NameofthecorrectedmapEditField.Enable = "off";
              app.NameoftheinterpolatedmapEditField.Enable = "off";
              app.LaunchRectificationButton.Enable = "off";
              app.PointMultiplierEditField.Enable = "off";
              
%             Disable Scils Extraction (Data Extraction) Tab
              app.MinMzEditField.Enable = "off";
              app.MaxMzEditField.Enable = "off";
              app.BinningEditField.Enable = "off";
              app.ExporttoCSVButton.Enable = "off";

           end    
            
                                 
%             limits = app.MasslimitsMinimumEditField.Value;  % Looks like
%             derelict code
%             limi = round(limits(1));
%             mass_str = num2str(limi);
%             name = strcat(map_name,'_',mass_str);%,'.biomap');

        end

        % Value changed function: GeneratedmatfileEditField
        function GeneratedmatfileEditFieldValueChanged(app, event)
            value = app.GeneratedmatfileEditField.Value;
            app.MatFileEditField.Value = value;

            if isempty(value) == 0
                app.BiningwindowsizeEditField.Enable = "on";
                app.GeneratematfileButton.Enable = "on";
            else
                app.BiningwindowsizeEditField.Enable = "off";
                app.GeneratematfileButton.Enable = "off";
            end
        end

        % Button pushed function: NewMatButton
        function NewMatButtonPushed(app, event)
% Causes janky window behaviour. This is probably because Matlab had the 
% brilliant idea to define positions from the bottom left corner, which 
% means we have to take the width and height into account to define a 
% position. Since this was overlooked when coding the interface, the 
% canvas seems to play catchup to the windows it is supposed to house. 
% Great design all around...            
            app.TabGroup.Visible = "off";
            app.OMGMSIHopefully10SoonDataProcessingPanel.Visible = "off";
            app.UIFigure.Position = [100,100,603,500];
%             app.OMGMSINewmatfilePanel.Position = [0,0,603,500];
            app.OMGMSINewmatfilePanel.Position = [0,0,603,500];
            movegui(app.UIFigure, 'center')
            app.OMGMSINewmatfilePanel.Visible = "on";
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.OMGMSINewmatfilePanel.Visible = "off";
            app.UIFigure.Position = [100, 100, 608, 692];
            movegui(app.UIFigure, 'center')
            app.OMGMSIHopefully10SoonDataProcessingPanel.Visible = "on";
            app.TabGroup.Visible = "on";
        end

        % Callback function
        function ProceedButtonPushed(app, event)
            if app.DataProcessingButton.Value == 0
                % Morph app gui, enter data processing submenu
                app.OMGMSIModeSelectionPanel.Visible = "off";
                app.UIFigure.Position = [100, 100, 606, 490];
                app.TabGroup2.Position = [0, 0, 606, 490];
                app.TabGroup2.Visible = "on";
            else
                app.OMGMSIModeSelectionPanel.Visible = "off";
                app.UIFigure.Position = [100, 100, 603, 667];
                app.OMGMSIHopefully10SoonDataProcessingPanel.Position = [0, 0, 603, 667];
                app.TabGroup.Position = [0, 0, 603, 594];
                app.OMGMSIHopefully10SoonDataProcessingPanel.Visible = "on";
                app.TabGroup.Visible = "on";
            end

        end

        % Button pushed function: QuittoMenuButton_2
        function QuittoMenuButton_2Pushed(app, event)
            app.TabGroup2.Visible = "off";
            app.UIFigure.Position = [100,100,263,220];
            movegui(app.UIFigure, 'center')
            app.OMGMSIModeSelectionPanel.Visible = "on";
        end

        % Button pushed function: QuittoMenuButton
        function QuittoMenuButtonPushed(app, event)
            app.TabGroup.Visible = "off";
            app.UIFigure.Position = [300,300,263,220];
            movegui(app.UIFigure, 'center')
            app.OMGMSIModeSelectionPanel.Visible = "on";
        end

        % Value changed function: NameofthecorrectedmapEditField
        function NameofthecorrectedmapEditFieldValueChanged(app, event)
            value = app.NameofthecorrectedmapEditField.Value;
            if isempty(value) == 0
                app.MinimumthresholdmmEditField.Enable = "on";
                app.MaximumthresholdmmEditField.Enable = "on";
                app.LaunchRectificationButton.Enable = "on"; 
            else
                app.MinimumthresholdmmEditField.Enable = "off";
                app.MaximumthresholdmmEditField.Enable = "off";
                app.LaunchRectificationButton.Enable = "off";
            end
        end

        % Value changed function: MinimumthresholdmmEditField
        function MinimumthresholdmmEditFieldValueChanged(app, event)
            value = app.MinimumthresholdmmEditField.Value;
            if isempty(value) == 0 && isempty(app.NameofthecorrectedmapEditField.Value) == 0 && isempty(app.MaximumthresholdmmEditField.Value == 0)
                app.LaunchRectificationButton.Enable = "on";
            else
                app.LaunchRectificationButton.Enable = "off";
            end
        end

        % Value changed function: MaximumthresholdmmEditField
        function MaximumthresholdmmEditFieldValueChanged(app, event)
            value = app.MaximumthresholdmmEditField.Value;
            if isempty(value) == 0 && isempty(app.NameofthecorrectedmapEditField.Value) == 0 && isempty(app.MinimumthresholdmmEditField.Value == 0)
                app.LaunchRectificationButton.Enable = "on";
            else
                app.LaunchRectificationButton.Enable = "off";
            end
        end

        % Value changed function: NameoftheinterpolatedmapEditField
        function NameoftheinterpolatedmapEditFieldValueChanged(app, event)
            value = app.NameoftheinterpolatedmapEditField.Value;
            if isempty(value) == 0
                app.PointMultiplierEditField.Enable = "on";
                if app.PointMultiplierEditField.Value <= 0
                    app.LaunchInterpolationButton.Enable = "off";
                else
                    app.LaunchInterpolationButton.Enable = "on";
                end
            else
                app.PointMultiplierEditField.Enable = "off";
                app.LaunchInterpolationButton.Enable = "off";
            end
        end

        % Value changed function: OutputtextfileEditField
        function OutputtextfileEditFieldValueChanged(app, event)
            value = app.OutputtextfileEditField.Value;
            if isempty(value) == 0
                app.LaunchExtractionButton.Enable = "on";
            else
                app.LaunchExtractionButton.Enable = "off";
            end
        end

        % Value changed function: NameofthecsvfileEditField
        function NameofthecsvfileEditFieldValueChanged(app, event)
            value = app.NameofthecsvfileEditField.Value;
            if isempty(value) == 0
                app.Class01EditField.Enable = "on";
                app.Class02EditField.Enable = "on";
                app.Class03EditField.Enable = "on";
                app.Class04EditField.Enable = "on";
                app.Class05EditField.Enable = "on";
                app.Class06EditField.Enable = "on";
                app.ColorDropDown.Enable = "on";
                app.ColorDropDown_2.Enable = "on";
                app.ColorDropDown_3.Enable = "on";
                app.ColorDropDown_4.Enable = "on";
                app.ColorDropDown_5.Enable = "on";
                app.ColorDropDown_6.Enable = "on";
                app.BackgroundColorDropDown.Enable = "on";
                app.LaunchLDAReconstructionButton.Enable = "on";
            else
                app.Class01EditField.Enable = "off";
                app.Class02EditField.Enable = "off";
                app.Class03EditField.Enable = "off";
                app.Class04EditField.Enable = "off";
                app.Class05EditField.Enable = "off";
                app.Class06EditField.Enable = "off";
                app.ColorDropDown.Enable = "off";
                app.ColorDropDown_2.Enable = "off";
                app.ColorDropDown_3.Enable = "off";
                app.ColorDropDown_4.Enable = "off";
                app.ColorDropDown_5.Enable = "off";
                app.ColorDropDown_6.Enable = "off";
                app.BackgroundColorDropDown.Enable = "off";
                app.LaunchLDAReconstructionButton.Enable = "off";
            end
        end

        % Callback function
        function NameoftheinterpolatedbiomapEditFieldValueChanged(app, event)
            value = app.NameoftheinterpolatedbiomapEditField.Value;
            if isempty(value) == 0 && isempty(app.NameofthebiomapEditField.Value) == 0
                app.PointMultiplierEditField_2.Enable = "on";
                if isempty(app.PointMultiplierEditField_2.Value) == 0
                    app.LaunchBiomapInterpolationButton.Enable = "on";
                end
            else
                app.PointMultiplierEditField_2.Enable = "off";
                app.LaunchBiomapInterpolationButton.Enable = "off";
            end 
        end

        % Callback function
        function PointMultiplierEditField_2ValueChanged(app, event)
            value = app.PointMultiplierEditField_2.Value;
            if value <= 0
                app.LaunchBiomapInterpolationButton.Enable = "off";
            else
                app.LaunchBiomapInterpolationButton.Enable = "on";
            end
        end

        % Value changed function: PointMultiplierEditField
        function PointMultiplierEditFieldValueChanged(app, event)
            value = app.PointMultiplierEditField.Value;
            if value <= 0
                app.LaunchInterpolationButton.Enable = "off";
            else
                app.LaunchInterpolationButton.Enable = "on";
            end
        end

        % Value changed function: ContinuousAcquisitionCheckBox
        function ContinuousAcquisitionCheckBoxValueChanged(app, event)
            value = app.ContinuousAcquisitionCheckBox.Value;
            if value == 1
                app.TimebetweenlaserburstssecondEditField.Value = 0;
                app.NumberoflasershotsburstmodeEditField.Enable = "off";
            else
                app.NumberoflasershotsburstmodeEditField.Enable = "on";
            end
        end

        % Value changed function: GeneratedmapfileEditField
        function GeneratedmapfileEditFieldValueChanged(app, event)
%             DEVNOTE: CREATE SAFEGUARD TO AVOID MAP FILE OVERWRITING
            value = app.GeneratedmapfileEditField.Value;
            if isempty(value) == 0
                app.ResolutionmmEditField.Enable = "on";
                app.SurfaceoffestmmEditField.Enable = "on";
                app.XmmEditField.Enable = "on";
                app.YmmEditField.Enable = "on";
                app.MaximumheightmmofthesampleEditField.Enable = "on";
                app.FirstmappingpointXpositionmmEditField.Enable = "on";
                app.FirstmappingpointYpositionmmEditField.Enable = "on";
            else
                app.ResolutionmmEditField.Enable = "off";
                app.SurfaceoffestmmEditField.Enable = "off";
                app.XmmEditField.Enable = "off";
                app.YmmEditField.Enable = "off";
                app.MaximumheightmmofthesampleEditField.Enable = "off";
                app.FirstmappingpointXpositionmmEditField.Enable = "off";
                app.FirstmappingpointYpositionmmEditField.Enable = "off";
            end
        end

        % Value changed function: mzXMLfilenameEditField
        function mzXMLfilenameEditFieldValueChanged(app, event)
            value = app.mzXMLfilenameEditField.Value;
            if isempty(value) == 0
                app.LoadmzXMLButton.Enable = "on";
            else
                app.LoadmzXMLButton.Enable = "off";
            end
        end

        % Value changed function: MapfilenameEditField_2
        function MapfilenameEditField_2ValueChanged(app, event)
            value = app.MapfilenameEditField_2.Value;
            if isempty(value) == 0
                ScanSelectionMethodDropDownValueChanged(app, event)
                app.ScanSelectionButton.Enable = "on";
                app.ScanSelectionMethodDropDown.Enable = "on";     
%                 if app.ScanSelectionMethodDropDown.Value == "PeakPickingMethod"
%                     if app.InternalTriggeringSwitch.Value == "on"
%                         app.BeginThresholdEditField.Enable = "off";
%                     else
%                         app.BeginThresholdEditField.Enable = "on";
%                     end
%                     app.LoudThresholdEditField.Enable = "on";
%                     app.TimebetweentwolaserburstssEditField.Enable = "on"; 
%                 else
%                     app.AspirationTimesEditField.Enable = "on";
%                 end
%                 app.FusionpercentSlider.Enable = "on";
%                 app.InternalTriggeringSwitch.Enable = "on";
%                 app.ScanSelectionButton.Enable = "on";
%                 app.ScanSelectionMethodDropDown.Enable = "on";                                
            else
                app.AspirationShiftsEditField.Enable = "off";
                app.AspirationTimesEditField.Enable = "off";
                app.BeginThresholdEditField.Enable = "off";
                app.FusionpercentSlider.Enable = "off";
                app.InternalTriggeringSwitch.Enable = "off";
                app.LoudThresholdEditField.Enable = "off";
                app.NeighbourNumberEditField.Enable = "off";
                app.ScanSelectionButton.Enable = "off";
                app.ScanSelectionMethodDropDown.Enable = "off";
                app.TimebetweentwolaserburstssEditField.Enable = "off";
            end
        end

        % Selection changed function: MappingChoiceButtonGroup
        function MappingChoiceButtonGroupSelectionChanged(app, event)
            selectedButton = app.MappingChoiceButtonGroup.SelectedObject;
            if selectedButton == app.TopographyOnlyButton
                app.SourceDropDown.Enable = "off";
                app.ConnectSourceButton.Enable = "off";
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: Topography Only";
                app.SensorDropDown.Enable = "on";
                app.ConnectSensorButton.Enable = "on";

            elseif selectedButton == app.DMSIButton
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: 3D Mass Spectrometry";
                app.SensorDropDown.Enable = "on";
                app.ConnectSensorButton.Enable = "on";

            elseif selectedButton == app.DMSIButton_2
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: 2D Mass Spectrometry";
                app.SensorDropDown.Enable = "off";
                app.ConnectSensorButton.Enable = "off";
            end
        end

        % Button pushed function: DataProcessingButton_2
        function DataProcessingButton_2Pushed(app, event)
            app.OMGMSIModeSelectionPanel.Visible = "off";
            app.UIFigure.Position = [100, 100, 608, 692];
%             app.UIFigure.Position = [100, 100, 608, 677];
            app.OMGMSIHopefully10SoonDataProcessingPanel.Position = [0, 15, 608, 677];
            app.TabGroup.Position = [0, 0, 608, 594];
            app.OMGMSIHopefully10SoonDataProcessingPanel.Visible = "on";
            app.TabGroup.Visible = "on";
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: DataAcquisitionButton_2
        function DataAcquisitionButton_2Pushed(app, event)
            app.OMGMSIModeSelectionPanel.Visible = "off";
            app.UIFigure.Position = [100, 100, 606, 490];
            app.TabGroup2.Position = [0, 0, 606, 490];
            movegui(app.UIFigure, 'center')
            app.TabGroup2.Visible = "on";
        end

        % Value changed function: EMERGENCYSTOPACQUISITIONButton
        function EMERGENCYSTOPACQUISITIONButtonValueChanged(app, event)
            global arduino_object;
            global robot;
            global source;
            global sensor;
            global state;         

%             if  app.EMERGENCYSTOPACQUISITIONButton.Value % Why does this condition exist?
%                 emergency_stop(source, robot, sensor, state, arduino_object, app)
%             end

            emergency_stop(source, robot, sensor, state, arduino_object, app)
            check_state(state, app) % Restores proper diode states and 
            % enables buttons that facilitate hardware connexion     
            app.RobotConnexionB.Enable = "on";
            app.DisconnectRobotButton.Enable = "off";
        end

        % Button pushed function: DisplaySpectraSumButton
        function DisplaySpectraSumButtonPushed(app, event)
            mat_file_r = app.MatFileEditField.Value;
            mat_name = strcat(mat_file_r,'.mat');
            load(mat_name)

            map_name_r = app.MapFileEditField.Value;
            map_name = strcat(map_name_r,'.map');
            map = load_map_fcn(map_name);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_spectra_sum(app, pixels_scans) ;
        end

        % Button pushed function: NormaliseCSVBPButton
        function NormaliseCSVBPButtonPushed(app, event)
            pyrunfile('CSV_BasePeak_norm.py')
            update_log(app, "Done!")
        end

        % Button pushed function: NormaliseCSVRMSButton
        function NormaliseCSVRMSButtonPushed(app, event)
            pyrunfile('CSV_RMS_norm.py')
            update_log(app, "Done!")
        end

        % Button pushed function: ExportOneSpectrumtoCSVButton
        function ExportOneSpectrumtoCSVButtonPushed(app, event)
            mat_file_r = app.MatFileEditField.Value;
            mat_name = strcat(mat_file_r,'.mat');
            load(mat_name)

            map_name_r = app.MapFileEditField.Value;
            map_name = strcat(map_name_r,'.map');
            map = load_map_fcn(map_name);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            record_one_spectra(app, pixels_scans, map, mat_name, limits,loud_data)
        end

        % Button pushed function: ExportSpectraSumtoCSVButton
        function ExportSpectraSumtoCSVButtonPushed(app, event)
            mat_file_r = app.MatFileEditField.Value;
            mat_name = strcat(mat_file_r,'.mat');
            load(mat_name)

            map_name_r = app.MapFileEditField.Value;
            map_name = strcat(map_name_r,'.map');
            map = load_map_fcn(map_name);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            disp(mat_name)
            export_spectra_sum_csv(app, pixels_scans,mat_name) ;
        end

        % Button pushed function: ExportSpectraZonetoCSVButton
        function ExportSpectraZonetoCSVButtonPushed(app, event)
            nom_mat_r = app.MatFileEditField.Value; % creer une routine de chargement des donnees ? => factoriser aussi pour n'avoir que 3 lignes de chargement ici ? fonction load and load ma
            check_isempty(app, nom_mat_r,'.mat')
            nom_mat = strcat(pwd, '/files/mat files/', nom_mat_r,'.mat');
            load(nom_mat)

            nom_map_r = app.MapFileEditField.Value;
            check_isempty(app, nom_map_r,'.map')
            nom_map = strcat(nom_map_r,'.map');
            map = load_map_fcn(nom_map);
            
            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            export_spectra_zone_csv(app, pixels_scans, map, limits,loud_data,nom_mat_r)
        end

        % Button pushed function: LaunchReconstructionButton_RGB
        function LaunchReconstructionButton_RGBPushed(app, event)
            nom_mat_r = app.MatFileEditField.Value;
            nom_mat = strcat(pwd, '/files/mat files/',  nom_mat_r,'.mat');
            load(nom_mat);

            nom_map_r = app.MapFileEditField.Value;
            nom_map = strcat(pwd, '/files/map files/', nom_map_r,'.map');
            map = load_map_fcn(nom_map);

            l_min_R = app.MinimumEditField_R.Value;
            l_max_R = app.MaximumEditField_R.Value;
            limits_R = [l_min_R l_max_R];

            l_min_G = app.MinimumEditField_G.Value;
            l_max_G = app.MaximumEditField_G.Value;
            limits_G = [l_min_G l_max_G];

            l_min_B = app.MinimumEditField_B.Value;
            l_max_B = app.MaximumEditField_B.Value;
            limits_B = [l_min_B l_max_B];

            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));
            fprintf('(%s) - Starting to build the intensity matrix\n', datestr(now,'HH:MM:SS'));

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            if isfield(map,'time')
                time_flag = 1;
            else
                time_flag = 0;
                map_time = 0;
            end

            [ pixels_ind, scans_ind, pixels_mz_R, fusion_list] = data_on_map(app, pixels_scans,map,limits_R,map.time,time_flag,loud_flag);
            [ pixels_ind, scans_ind, pixels_mz_G, fusion_list] = data_on_map(app, pixels_scans,map,limits_G,map.time,time_flag,loud_flag);
            [ pixels_ind, scans_ind, pixels_mz_B, fusion_list] = data_on_map(app, pixels_scans,map,limits_B,map.time,time_flag,loud_flag);

            [ map_2.x,map_2.y,map_2.z,pixels_mz_R  ] = fix_border(map.x,map.y,map.z,pixels_mz_R,pixels_ind);
            [ map_2.x,map_2.y,map_2.z,pixels_mz_G  ] = fix_border(map.x,map.y,map.z,pixels_mz_G,pixels_ind);
            [ map.x,map.y,map.z,pixels_mz_B  ] = fix_border(map.x,map.y,map.z,pixels_mz_B,pixels_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
%             title_str = strcat( "biometric map, mass limits : ", num2str(limits(1)) ,'  -  ',num2str(limits(2)) );
            
            display_RGB_mz_map(map,pixels_mz_R,pixels_mz_G,pixels_mz_B,limits_R,limits_G,limits_B," ")

        end

        % Value changed function: LaunchPCAButton
        function LaunchPCAButtonValueChanged(app, event)
%             value = app.LaunchPCAButton.Value;
            
        b1 = app.MasslimitsMinimumEditField.Value;
        b2 = app.MaximumEditField.Value;
        band = [b1 b2];
        nb_point = 10; % v1 fixe, sinon app.NumberofpointonthecorrelationcirclemaximummzEditField.Value;
        win = 0.1; % app.BiningwindowmassEditField.Value;
        nom_mat_r = app.MatFileEditField.Value;
        nom_mat = strcat(pwd, '/files/mat files/',  nom_mat_r,'.mat');
        load(nom_mat);
        tlp = 1 ; %app.ShowalsoallmassesonanothercorrelationcircleCheckBox.Value;
        mat_pca_circle(win,band,nb_point,pixels_scans,tlp)


        end

        % Value changed function: InternalTriggeringSwitch
        function InternalTriggeringSwitchValueChanged(app, event)
            value = app.InternalTriggeringSwitch.Value;
            if value == "On"
                app.BeginThresholdEditField.Enable = "off";
            else
                app.BeginThresholdEditField.Enable = "on";
            end
        end

        % Value changed function: AspirationTimesEditField
        function AspirationTimesEditFieldValueChanged(app, event)
            value = app.AspirationTimesEditField.Value;
            
        end

        % Value changed function: FocaldistancemmEditField_2
        function FocaldistancemmEditField_2ValueChanged(app, event)
            value = app.FocaldistancemmEditField_2.Value;
            app.FocaldistancemmEditField.Value = value;
        end

        % Value changed function: FocaldistancemmEditField
        function FocaldistancemmEditFieldValueChanged(app, event)
            value = app.FocaldistancemmEditField.Value;
            app.FocaldistancemmEditField_2.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OMGMSIHopefully10SoonDataProcessingPanel = uipanel(app.UIFigure);
            app.OMGMSIHopefully10SoonDataProcessingPanel.Title = 'OMG-MSI - Hopefully 1.0 Soon ! - Data Processing';
            app.OMGMSIHopefully10SoonDataProcessingPanel.Visible = 'off';
            app.MatFileEditFieldLabel = uilabel(app.OMGMSIHopefully10SoonDataProcessingPanel);
            app.MatFileEditFieldLabel.Text = 'Mat File:';
            app.MatFileEditField = uieditfield(app.OMGMSIHopefully10SoonDataProcessingPanel, 'text');
            app.MatFileEditField.ValueChangedFcn = createCallbackFcn(app, @MatFileEditFieldValueChanged, true);
            app.MatFileEditField.Tooltip = {'.Mat files store the data extracted from .mzXML files. They are stored in the "mat files" folder of this software. Specifying one is mandatory for most operations past data acquisition.'};
            app.MapFileEditFieldLabel = uilabel(app.OMGMSIHopefully10SoonDataProcessingPanel);
            app.MapFileEditFieldLabel.Text = 'Map File:';
            app.MapFileEditField = uieditfield(app.OMGMSIHopefully10SoonDataProcessingPanel, 'text');
            app.MapFileEditField.ValueChangedFcn = createCallbackFcn(app, @MapFileEditFieldValueChanged, true);
            app.MapFileEditField.Tooltip = {'.Map files store the positions of each pixel acquired in a given MSI experiment. They are stored in the "map files" folder of this software. Specifying the one corresponding to the .mat or .mzXML file of choice is mandatory for most operations past data acquisition.'};
            app.NewMatButton = uibutton(app.OMGMSIHopefully10SoonDataProcessingPanel, 'push');
            app.NewMatButton.ButtonPushedFcn = createCallbackFcn(app, @NewMatButtonPushed, true);
            app.NewMatButton.Text = 'New Mat';
            app.QuittoMenuButton = uibutton(app.OMGMSIHopefully10SoonDataProcessingPanel, 'push');
            app.QuittoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @QuittoMenuButtonPushed, true);
            app.QuittoMenuButton.Text = 'Quit to Menu';
            app.PleaseinputavalidmatandmapfilenametostartprocessingLabel = uilabel(app.OMGMSIHopefully10SoonDataProcessingPanel);
            app.PleaseinputavalidmatandmapfilenametostartprocessingLabel.Text = 'Please input a valid mat and map file name to start processing!';
            app.OMGMSINewmatfilePanel = uipanel(app.UIFigure);
            app.OMGMSINewmatfilePanel.Title = 'OMG-MSI - New .mat file';
            app.OMGMSINewmatfilePanel.Visible = 'off';
            app.ExitButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.mzXMLfilenameEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.mzXMLfilenameEditFieldLabel.Text = 'mzXML file name :';
            app.mzXMLfilenameEditField = uieditfield(app.OMGMSINewmatfilePanel, 'text');
            app.mzXMLfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @mzXMLfilenameEditFieldValueChanged, true);
            app.mzXMLLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.mzXMLLabel.Text = '.mzXML';
            app.mzXMLLoadingLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.mzXMLLoadingLabel.Text = 'mzXML Loading :';
            app.ScanSelectionLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.ScanSelectionLabel.Text = 'Scan Selection :';
            app.GeneratematfileButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.GeneratematfileButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratematfileButtonPushed, true);
            app.GeneratematfileButton.Enable = 'off';
            app.GeneratematfileButton.Text = 'Generate .mat file';
            app.BiningwindowsizeEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.BiningwindowsizeEditFieldLabel.Text = 'Bining window size';
            app.BiningwindowsizeEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.BiningwindowsizeEditField.Enable = 'off';
            app.BiningwindowsizeEditField.Tooltip = {'Bins m/z intensities in the specified range'};
            app.BiningwindowsizeEditField.Value = 0.1;
            app.GeneratedmatfileEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.GeneratedmatfileEditFieldLabel.Text = 'Generated mat file ';
            app.GeneratedmatfileEditField = uieditfield(app.OMGMSINewmatfilePanel, 'text');
            app.GeneratedmatfileEditField.ValueChangedFcn = createCallbackFcn(app, @GeneratedmatfileEditFieldValueChanged, true);
            app.GeneratedmatfileEditField.Enable = 'off';
            app.GeneratedmatfileEditField.Tooltip = {'For time-based scan selection, we advise adding the aspiration time and shift at the end of the file''s name.'};
            app.matLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.matLabel.Text = '.mat';
            app.ScanSelectionButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.ScanSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSelectionButtonPushed, true);
            app.ScanSelectionButton.Enable = 'off';
            app.ScanSelectionButton.Text = 'Scan Selection';
            app.InternalTriggeringSwitchLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.InternalTriggeringSwitchLabel.Text = 'Internal Triggering';
            app.InternalTriggeringSwitch = uiswitch(app.OMGMSINewmatfilePanel, 'slider');
            app.InternalTriggeringSwitch.ValueChangedFcn = createCallbackFcn(app, @InternalTriggeringSwitchValueChanged, true);
            app.InternalTriggeringSwitch.Enable = 'off';
            app.InternalTriggeringSwitch.Tooltip = {'If "No Triggering" was selected during the acquisition, turn this off. If disabled, the starting value for scan selection will be set through the "Begin Threshold" field.'};
            app.InternalTriggeringSwitch.Value = 'On';
            app.PreprocessingLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.PreprocessingLabel.Text = 'Preprocessing :';
            app.FusionpercentSliderLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.FusionpercentSliderLabel.Text = ' Fusion percent';
            app.FusionpercentSlider = uislider(app.OMGMSINewmatfilePanel);
            app.FusionpercentSlider.Enable = 'off';
            app.FusionpercentSlider.Value = 50;
            app.AspirationTimesEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.AspirationTimesEditFieldLabel.Text = 'Aspiration Time (s)';
            app.AspirationTimesEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.AspirationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @AspirationTimesEditFieldValueChanged, true);
            app.AspirationTimesEditField.Enable = 'off';
            app.AspirationTimesEditField.Value = 0.9;
            app.MapfilenameEditField_2Label = uilabel(app.OMGMSINewmatfilePanel);
            app.MapfilenameEditField_2Label.Text = 'Map file name';
            app.MapfilenameEditField_2 = uieditfield(app.OMGMSINewmatfilePanel, 'text');
            app.MapfilenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @MapfilenameEditField_2ValueChanged, true);
            app.MapfilenameEditField_2.Enable = 'off';
            app.mapLabel_5 = uilabel(app.OMGMSINewmatfilePanel);
            app.mapLabel_5.Text = '.map';
            app.ScanSelectionMethodDropDownLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.ScanSelectionMethodDropDownLabel.Text = 'Scan Selection Method';
            app.ScanSelectionMethodDropDown = uidropdown(app.OMGMSINewmatfilePanel);
            app.ScanSelectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanSelectionMethodDropDownValueChanged, true);
            app.ScanSelectionMethodDropDown.Enable = 'off';
            app.ScanSelectionMethodDropDown.Tooltip = {'Methods for assigning scans to points in the map file. There are currently two methods: '; '- Peak Picking: Peak detection that allows for finer scan assignment. Advised for conventional, "discrete" acquisitions, where a pause is taken between individual sampling points, allowing the chromatogram to return to a baseline.'; '- Time-Based: Alternative scan selection method, primarily intended for continuous analyses where peak picking cannot be successfuly performed.'};
            app.LaunchTimeEstimationButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.LaunchTimeEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchTimeEstimationButtonPushed, true);
            app.LaunchTimeEstimationButton.Enable = 'off';
            app.LaunchTimeEstimationButton.Text = 'Launch Time Estimation';
            app.TimeValue = uilabel(app.OMGMSINewmatfilePanel);
            app.TimeValue.Text = 'NO DATA';
            app.LoadmzXMLButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.LoadmzXMLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmzXMLButtonPushed, true);
            app.LoadmzXMLButton.Enable = 'off';
            app.LoadmzXMLButton.Text = 'Load mzXML';
            app.PlotChromatogramButton = uibutton(app.OMGMSINewmatfilePanel, 'push');
            app.PlotChromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChromatogramButtonPushed, true);
            app.PlotChromatogramButton.Enable = 'off';
            app.PlotChromatogramButton.Text = 'Plot Chromatogram';
            app.BeginThresholdEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.BeginThresholdEditFieldLabel.Text = 'Begin Threshold';
            app.BeginThresholdEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.BeginThresholdEditField.Enable = 'off';
            app.BeginThresholdEditField.Value = 10000;
            app.TimebetweentwolaserburstssEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.TimebetweentwolaserburstssEditFieldLabel.Text = 'Time between two laser bursts (s):';
            app.TimebetweentwolaserburstssEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.TimebetweentwolaserburstssEditField.Enable = 'off';
            app.TimebetweentwolaserburstssEditField.Value = 1.5;
            app.LogTextAreaLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.OMGMSINewmatfilePanel);
            app.LogTextArea.Editable = 'off';
            app.LoudThresholdEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.LoudThresholdEditFieldLabel.Text = 'Loud Threshold';
            app.LoudThresholdEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.LoudThresholdEditField.Enable = 'off';
            app.LoudThresholdEditField.Value = 1000;
            app.NeighbourNumberEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.NeighbourNumberEditFieldLabel.Text = 'Neighbour Number';
            app.NeighbourNumberEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.NeighbourNumberEditField.Enable = 'off';
            app.AspirationShiftsEditFieldLabel = uilabel(app.OMGMSINewmatfilePanel);
            app.AspirationShiftsEditFieldLabel.Text = 'Aspiration Shift (s)';
            app.AspirationShiftsEditField = uieditfield(app.OMGMSINewmatfilePanel, 'numeric');
            app.AspirationShiftsEditField.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.MasslimitsMinimumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MasslimitsMinimumEditFieldLabel.Text = 'Mass limits:    Minimum:';
            app.MasslimitsMinimumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MasslimitsMinimumEditField.ValueDisplayFormat = '%.3f';
            app.MasslimitsMinimumEditField.ValueChangedFcn = createCallbackFcn(app, @MasslimitsMinimumEditFieldValueChanged, true);
            app.MasslimitsMinimumEditField.Enable = 'off';
            app.MasslimitsMinimumEditField.Value = 600;
            app.MaximumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField.ValueDisplayFormat = '%.3f';
            app.MaximumEditField.Enable = 'off';
            app.MaximumEditField.Value = 600.25;
            app.LaunchReconstructionButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchReconstructionButtonPushed, true);
            app.LaunchReconstructionButton.Enable = 'off';
            app.LaunchReconstructionButton.Text = 'Launch Reconstruction';
            app.DisplayOneSpectrumButton = uibutton(app.ProcessingTab, 'push');
            app.DisplayOneSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayOneSpectrumButtonPushed, true);
            app.DisplayOneSpectrumButton.Enable = 'off';
            app.DisplayOneSpectrumButton.Text = 'Display One Spectrum';
            app.DisplaySpectraZoneButton = uibutton(app.ProcessingTab, 'push');
            app.DisplaySpectraZoneButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySpectraZoneButtonPushed, true);
            app.DisplaySpectraZoneButton.Enable = 'off';
            app.DisplaySpectraZoneButton.Text = 'Display Spectra Zone';
            app.MapFromSpectraButton = uibutton(app.ProcessingTab, 'push');
            app.MapFromSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @MapFromSpectraButtonPushed, true);
            app.MapFromSpectraButton.Enable = 'off';
            app.MapFromSpectraButton.Tooltip = {'Allows to rebuild images by selecting a mass range from the average spectrum. Note that in order to build a subsequent image, the chromatogram must be closed, and this button musst be pressed again.'};
            app.MapFromSpectraButton.Text = 'Map From Spectra';
            app.MaxEditFieldLabel = uilabel(app.ProcessingTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MaxEditField.Enable = 'off';
            app.MaxEditField.Value = 2000;
            app.MinEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MinEditField.Enable = 'off';
            app.ReconstructionLabel = uilabel(app.ProcessingTab);
            app.ReconstructionLabel.Text = 'Reconstruction :';
            app.AdditionnalDataExtractionFeaturesLabel = uilabel(app.ProcessingTab);
            app.AdditionnalDataExtractionFeaturesLabel.Text = 'Additionnal Data Extraction Features :';
            app.LouddataonthemapSwitchLabel = uilabel(app.ProcessingTab);
            app.LouddataonthemapSwitchLabel.Text = 'Loud data on the map';
            app.LouddataonthemapSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.LouddataonthemapSwitch.Enable = 'off';
            app.DisplaySpectraSumButton = uibutton(app.ProcessingTab, 'push');
            app.DisplaySpectraSumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySpectraSumButtonPushed, true);
            app.DisplaySpectraSumButton.Enable = 'off';
            app.DisplaySpectraSumButton.Text = 'Display Spectra Sum';
            app.LaunchReconstructionButton_RGB = uibutton(app.ProcessingTab, 'push');
            app.LaunchReconstructionButton_RGB.ButtonPushedFcn = createCallbackFcn(app, @LaunchReconstructionButton_RGBPushed, true);
            app.LaunchReconstructionButton_RGB.Enable = 'off';
            app.LaunchReconstructionButton_RGB.Text = 'Launch Reconstruction';
            app.MinimumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinimumEditFieldLabel.Text = '   Minimum:';
            app.MinimumEditField_R = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_R.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_R.Enable = 'off';
            app.MinimumEditField_R.Value = 600;
            app.MaximumEditField_2Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_R = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_R.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_R.Enable = 'off';
            app.MaximumEditField_R.Value = 600.25;
            app.RGBDisplayLabel = uilabel(app.ProcessingTab);
            app.RGBDisplayLabel.Text = 'RGB Display :';
            app.RedLabel = uilabel(app.ProcessingTab);
            app.RedLabel.Text = 'Red :';
            app.GreenLabel = uilabel(app.ProcessingTab);
            app.GreenLabel.Text = 'Green :';
            app.MinimumEditField_2Label = uilabel(app.ProcessingTab);
            app.MinimumEditField_2Label.Text = '   Minimum:';
            app.MinimumEditField_G = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_G.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_G.Enable = 'off';
            app.MinimumEditField_G.Value = 600;
            app.MaximumEditField_3Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_3Label.Text = 'Maximum';
            app.MaximumEditField_G = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_G.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_G.Enable = 'off';
            app.MaximumEditField_G.Value = 600.25;
            app.BlueLabel = uilabel(app.ProcessingTab);
            app.BlueLabel.Text = 'Blue :';
            app.MinimumEditField_3Label = uilabel(app.ProcessingTab);
            app.MinimumEditField_3Label.Text = '   Minimum:';
            app.MinimumEditField_B = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_B.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_B.Enable = 'off';
            app.MinimumEditField_B.Value = 600;
            app.MaximumEditField_4Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_4Label.Text = 'Maximum';
            app.MaximumEditField_B = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_B.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_B.Enable = 'off';
            app.MaximumEditField_B.Value = 600.25;
            app.LaunchPCAButton = uibutton(app.ProcessingTab, 'state');
            app.LaunchPCAButton.ValueChangedFcn = createCallbackFcn(app, @LaunchPCAButtonValueChanged, true);
            app.LaunchPCAButton.Enable = 'off';
            app.LaunchPCAButton.Text = 'Launch PCA';
            app.PrincipalComponentAnalysisLabel = uilabel(app.ProcessingTab);
            app.PrincipalComponentAnalysisLabel.Text = 'Principal Component Analysis : ';
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post Processing';
            app.NameofthecorrectedmapEditFieldLabel = uilabel(app.PostProcessingTab);
            app.NameofthecorrectedmapEditFieldLabel.Text = 'Name of the corrected map ';
            app.NameofthecorrectedmapEditField = uieditfield(app.PostProcessingTab, 'text');
            app.NameofthecorrectedmapEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthecorrectedmapEditFieldValueChanged, true);
            app.NameofthecorrectedmapEditField.Enable = 'off';
            app.LaunchRectificationButton = uibutton(app.PostProcessingTab, 'push');
            app.LaunchRectificationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchRectificationButtonPushed, true);
            app.LaunchRectificationButton.Enable = 'off';
            app.LaunchRectificationButton.Tooltip = {'Automatically recomputes the height of points falling outside of the threshold based on the height of neighbouring points. Manual selection of points to correct is also possible.'};
            app.LaunchRectificationButton.Text = 'Launch Rectification ';
            app.mapLabel_4 = uilabel(app.PostProcessingTab);
            app.mapLabel_4.Text = '.map';
            app.MinimumthresholdmmEditFieldLabel = uilabel(app.PostProcessingTab);
            app.MinimumthresholdmmEditFieldLabel.Text = 'Minimum threshold (mm) ';
            app.MinimumthresholdmmEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.MinimumthresholdmmEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumthresholdmmEditFieldValueChanged, true);
            app.MinimumthresholdmmEditField.Enable = 'off';
            app.MinimumthresholdmmEditField.Value = -1;
            app.MaximumthresholdmmEditFieldLabel = uilabel(app.PostProcessingTab);
            app.MaximumthresholdmmEditFieldLabel.Text = 'Maximum threshold (mm) ';
            app.MaximumthresholdmmEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.MaximumthresholdmmEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumthresholdmmEditFieldValueChanged, true);
            app.MaximumthresholdmmEditField.Enable = 'off';
            app.MaximumthresholdmmEditField.Value = 20;
            app.LaunchInterpolationButton = uibutton(app.PostProcessingTab, 'push');
            app.LaunchInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchInterpolationButtonPushed, true);
            app.LaunchInterpolationButton.Enable = 'off';
            app.LaunchInterpolationButton.Text = 'Launch Interpolation';
            app.mapLabel_7 = uilabel(app.PostProcessingTab);
            app.mapLabel_7.Text = '.map';
            app.NameoftheinterpolatedmapEditFieldLabel = uilabel(app.PostProcessingTab);
            app.NameoftheinterpolatedmapEditFieldLabel.Text = 'Name of the interpolated map ';
            app.NameoftheinterpolatedmapEditField = uieditfield(app.PostProcessingTab, 'text');
            app.NameoftheinterpolatedmapEditField.ValueChangedFcn = createCallbackFcn(app, @NameoftheinterpolatedmapEditFieldValueChanged, true);
            app.NameoftheinterpolatedmapEditField.Enable = 'off';
            app.NameoftheinterpolatedmapEditField.Value = 'Ploynomial interpolation of the map file. Helps smoothing a map''s features.';
            app.PointMultiplierEditFieldLabel = uilabel(app.PostProcessingTab);
            app.PointMultiplierEditFieldLabel.Text = 'Point Multiplier';
            app.PointMultiplierEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.PointMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @PointMultiplierEditFieldValueChanged, true);
            app.PointMultiplierEditField.Enable = 'off';
            app.PointMultiplierEditField.Value = 2;
            app.TopographicCorrectionLabel = uilabel(app.PostProcessingTab);
            app.TopographicCorrectionLabel.Text = 'Topographic Correction';
            app.TopographicInterpolationLabel = uilabel(app.PostProcessingTab);
            app.TopographicInterpolationLabel.Text = 'Topographic Interpolation';
            app.DisplayfromexternaldataTab = uitab(app.TabGroup);
            app.DisplayfromexternaldataTab.Title = 'Display from external data';
            app.ColorDropDownLabel = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown.Enable = 'off';
            app.ColorDropDown.Value = 'Red';
            app.csvLabel = uilabel(app.DisplayfromexternaldataTab);
            app.csvLabel.Text = '.csv';
            app.NameofthecsvfileEditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.NameofthecsvfileEditFieldLabel.Text = 'Name of the csv file';
            app.NameofthecsvfileEditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.NameofthecsvfileEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthecsvfileEditFieldValueChanged, true);
            app.Class01EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class01EditFieldLabel.Text = 'Class 01';
            app.Class01EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class01EditField.Enable = 'off';
            app.Class02EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class02EditFieldLabel.Text = 'Class 02';
            app.Class02EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class02EditField.Enable = 'off';
            app.Class03EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class03EditFieldLabel.Text = 'Class 03';
            app.Class03EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class03EditField.Enable = 'off';
            app.LaunchLDAReconstructionButton = uibutton(app.DisplayfromexternaldataTab, 'push');
            app.LaunchLDAReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchLDAReconstructionButtonPushed, true);
            app.LaunchLDAReconstructionButton.Enable = 'off';
            app.LaunchLDAReconstructionButton.Text = 'Launch LDA Reconstruction';
            app.BackgroundColorDropDownLabel = uilabel(app.DisplayfromexternaldataTab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.DisplayfromexternaldataTab);
            app.BackgroundColorDropDown.Items = {'Black', 'White', 'Grey'};
            app.BackgroundColorDropDown.Enable = 'off';
            app.BackgroundColorDropDown.Value = 'Black';
            app.ColorDropDown_2Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_2.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_2.Enable = 'off';
            app.ColorDropDown_2.Value = 'Green';
            app.ColorDropDown_3Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_3.Enable = 'off';
            app.ColorDropDown_3.Value = 'Orange';
            app.ColorDropDown_4Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_4Label.Text = 'Color';
            app.ColorDropDown_4 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_4.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_4.Enable = 'off';
            app.ColorDropDown_4.Value = 'None';
            app.Class04EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class04EditFieldLabel.Text = 'Class 04';
            app.Class04EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class04EditField.Enable = 'off';
            app.Class05EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class05EditFieldLabel.Text = 'Class 05';
            app.Class05EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class05EditField.Enable = 'off';
            app.Class06EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class06EditFieldLabel.Text = 'Class 06';
            app.Class06EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class06EditField.Enable = 'off';
            app.ColorDropDown_5Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_5Label.Text = 'Color';
            app.ColorDropDown_5 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_5.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_5.Enable = 'off';
            app.ColorDropDown_5.Value = 'None';
            app.ColorDropDown_6Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_6Label.Text = 'Color';
            app.ColorDropDown_6 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_6.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_6.Enable = 'off';
            app.ColorDropDown_6.Value = 'None';
            app.Label = uilabel(app.DisplayfromexternaldataTab);
            app.Label.Text = 'Import Classification from External Software : Display of the classification on the map';
            app.Scils_Extraction = uitab(app.TabGroup);
            app.Scils_Extraction.Title = 'Data Extraction';
            app.OutputtextfileEditFieldLabel = uilabel(app.Scils_Extraction);
            app.OutputtextfileEditFieldLabel.Text = 'Output text file:';
            app.OutputtextfileEditField = uieditfield(app.Scils_Extraction, 'text');
            app.OutputtextfileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputtextfileEditFieldValueChanged, true);
            app.OutputtextfileEditField.Enable = 'off';
            app.txtLabel = uilabel(app.Scils_Extraction);
            app.txtLabel.Text = '.txt';
            app.LaunchExtractionButton = uibutton(app.Scils_Extraction, 'push');
            app.LaunchExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchExtractionButtonPushed, true);
            app.LaunchExtractionButton.Enable = 'off';
            app.LaunchExtractionButton.Text = 'Launch Extraction';
            app.ExporttoSCILSImzmlLabel = uilabel(app.Scils_Extraction);
            app.ExporttoSCILSImzmlLabel.Text = 'Export to SCILS (Imzml)';
            app.Exportto3DfileplyLabel = uilabel(app.Scils_Extraction);
            app.Exportto3DfileplyLabel.Text = 'Export to 3D file (ply)';
            app.ExporttoCSVfileLabel = uilabel(app.Scils_Extraction);
            app.ExporttoCSVfileLabel.Text = 'Export to CSV file:';
            app.MinMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MinMzEditFieldLabel.Text = 'Min M/z';
            app.MinMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MinMzEditField.Enable = 'off';
            app.MaxMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MaxMzEditFieldLabel.Text = 'Max M/z';
            app.MaxMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MaxMzEditField.Enable = 'off';
            app.MaxMzEditField.Value = 2000;
            app.BinningEditFieldLabel = uilabel(app.Scils_Extraction);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.BinningEditField.Enable = 'off';
            app.BinningEditField.Value = 0.1;
            app.ExporttoCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Enable = 'off';
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.NormaliseCSVTICButton = uibutton(app.Scils_Extraction, 'push');
            app.NormaliseCSVTICButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseCSVTICButtonPushed, true);
            app.NormaliseCSVTICButton.Tooltip = {'Takes a .csv file generated with the "Export to CSV" button, and performs Total Ion Count (TIC) normalization on it. Highly recommended for data interpretation.'};
            app.NormaliseCSVTICButton.Text = 'Normalise CSV (TIC)';
            app.ExportfromCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportfromCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfromCSVButtonPushed, true);
            app.ExportfromCSVButton.Text = 'Export from CSV';
            app.ExportandcoregisterfromCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportandcoregisterfromCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportandcoregisterfromCSVButtonPushed, true);
            app.ExportandcoregisterfromCSVButton.Text = 'Export and coregister from CSV';
            app.NormaliseCSVBPButton = uibutton(app.Scils_Extraction, 'push');
            app.NormaliseCSVBPButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseCSVBPButtonPushed, true);
            app.NormaliseCSVBPButton.Tooltip = {'Takes a .csv file generated with the "Export to CSV" button, and performs Total Ion Count (TIC) normalization on it. Highly recommended for data interpretation.'};
            app.NormaliseCSVBPButton.Text = 'Normalise CSV (BP)';
            app.NormaliseCSVRMSButton = uibutton(app.Scils_Extraction, 'push');
            app.NormaliseCSVRMSButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseCSVRMSButtonPushed, true);
            app.NormaliseCSVRMSButton.Tooltip = {'Takes a .csv file generated with the "Export to CSV" button, and performs Total Ion Count (TIC) normalization on it. Highly recommended for data interpretation.'};
            app.NormaliseCSVRMSButton.Text = 'Normalise CSV (RMS)';
            app.ExportOneSpectrumtoCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportOneSpectrumtoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOneSpectrumtoCSVButtonPushed, true);
            app.ExportOneSpectrumtoCSVButton.Text = 'Export One Spectrum to CSV';
            app.ExportSpectraSumtoCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportSpectraSumtoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraSumtoCSVButtonPushed, true);
            app.ExportSpectraSumtoCSVButton.Text = 'Export Spectra Sum to CSV';
            app.ExportSpectraZonetoCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportSpectraZonetoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraZonetoCSVButtonPushed, true);
            app.ExportSpectraZonetoCSVButton.Text = 'Export Spectra Zone to CSV';
            app.RAWLabel = uilabel(app.Scils_Extraction);
            app.RAWLabel.Text = '.RAW';
            app.InputWatersRAWfileEditFieldLabel = uilabel(app.Scils_Extraction);
            app.InputWatersRAWfileEditFieldLabel.Text = 'Input Waters RAW file:';
            app.InputWatersRAWfileEditField = uieditfield(app.Scils_Extraction, 'text');
            app.InputWatersRAWfileEditField.Enable = 'off';
            app.InputWatersRAWfileEditField.Tooltip = {'Note: The .RAW file must be placed in "OMG_MSI/files/raw files"'};
            app.LogTab_2 = uitab(app.TabGroup);
            app.LogTab_2.Title = 'Log';
            app.TextArea2Label = uilabel(app.LogTab_2);
            app.TextArea2Label.Enable = 'off';
            app.TextArea2Label.Visible = 'off';
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.LogTab_2);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Visible = 'off';
            app.ModeTab = uitab(app.TabGroup2);
            app.ModeTab.Title = 'Mode';
            app.MappingChoiceButtonGroup = uibuttongroup(app.ModeTab);
            app.MappingChoiceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MappingChoiceButtonGroupSelectionChanged, true);
            app.MappingChoiceButtonGroup.Title = 'Mapping Choice';
            app.TopographyOnlyButton = uiradiobutton(app.MappingChoiceButtonGroup);
            app.TopographyOnlyButton.Text = 'Topography Only';
            app.TopographyOnlyButton.Value = true;
            app.DMSIButton = uiradiobutton(app.MappingChoiceButtonGroup);
            app.DMSIButton.Tooltip = {'Mass Spectrometry Imaging of a non-flat sample. Requires the use of a topographical sensor.'};
            app.DMSIButton.Text = '3D MSI';
            app.LampOnlyTopo = uilamp(app.MappingChoiceButtonGroup);
            app.LampOnlyTopo.Tooltip = {'Those lamps are most likely getting removed'};
            app.LampWithSpectro = uilamp(app.MappingChoiceButtonGroup);
            app.DMSIButton_2 = uiradiobutton(app.MappingChoiceButtonGroup);
            app.DMSIButton_2.Text = '2D MSI';
            app.LampWithSpectro_2D = uilamp(app.MappingChoiceButtonGroup);
            app.ButtonGroupMappingSpeed = uibuttongroup(app.ModeTab);
            app.ButtonGroupMappingSpeed.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupMappingSpeedSelectionChanged, true);
            app.WithrepositioningButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.WithrepositioningButton.Tooltip = {'Implements an error correction routine in the robot movement, at the cost of a longer experiment. Can be detrimental at high acquisition speeds.'};
            app.WithrepositioningButton.Text = 'With repositioning';
            app.WithrepositioningButton.Value = true;
            app.FastmappingButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.FastmappingButton.Tooltip = {'Shunts the repositioning routine when the arm is misplaced in height. Check with Paul which combinations should be allowed, and which should not.'};
            app.FastmappingButton.Text = 'Fast mapping';
            app.PropertiesTab = uitab(app.TabGroup2);
            app.PropertiesTab.Title = 'Properties';
            app.GeneratedmapfileEditFieldLabel = uilabel(app.PropertiesTab);
            app.GeneratedmapfileEditFieldLabel.Text = 'Generated map file';
            app.GeneratedmapfileEditField = uieditfield(app.PropertiesTab, 'text');
            app.GeneratedmapfileEditField.ValueChangedFcn = createCallbackFcn(app, @GeneratedmapfileEditFieldValueChanged, true);
            app.mapLabel = uilabel(app.PropertiesTab);
            app.mapLabel.Text = '.map';
            app.ResolutionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.ResolutionmmEditFieldLabel.Text = 'Resolution  (mm)';
            app.ResolutionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.ResolutionmmEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionmmEditFieldValueChanged, true);
            app.ResolutionmmEditField.Enable = 'off';
            app.ResolutionmmEditField.Value = 0.5;
            app.SurfaceoffestmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.SurfaceoffestmmEditFieldLabel.Text = 'Surface offest (mm)';
            app.SurfaceoffestmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.SurfaceoffestmmEditField.Enable = 'off';
            app.XmmLabel = uilabel(app.PropertiesTab);
            app.XmmLabel.Text = '- X (mm)';
            app.XmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.XmmEditField.Enable = 'off';
            app.XmmEditField.Value = 16;
            app.YmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.YmmEditFieldLabel.Text = '- Y (mm)';
            app.YmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.YmmEditField.ValueChangedFcn = createCallbackFcn(app, @YmmEditFieldValueChanged, true);
            app.YmmEditField.Enable = 'off';
            app.YmmEditField.Value = 16;
            app.EstimationofmappingtimeLabel = uilabel(app.PropertiesTab);
            app.EstimationofmappingtimeLabel.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Laser tab)'};
            app.EstimationofmappingtimeLabel.Text = 'Estimation of mapping time : ';
            app.MaximumheightmmofthesampleLabel = uilabel(app.PropertiesTab);
            app.MaximumheightmmofthesampleLabel.Text = 'Maximum height (mm) of the sample';
            app.MaximumheightmmofthesampleEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.MaximumheightmmofthesampleEditField.Enable = 'off';
            app.MaximumheightmmofthesampleEditField.Value = 10;
            app.FirstmappingpointXpositionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.FirstmappingpointXpositionmmEditFieldLabel.Text = 'First mapping point X position (mm)';
            app.FirstmappingpointXpositionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.FirstmappingpointXpositionmmEditField.Enable = 'off';
            app.FirstmappingpointXpositionmmEditField.Tooltip = {'The idea is sound, but the implementation could be improved. I suggest replacing this with the manual coordinates we can give to the robot.'};
            app.FirstmappingpointXpositionmmEditField.Value = 150;
            app.FirstmappingpointYpositionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.FirstmappingpointYpositionmmEditFieldLabel.Text = 'First mapping point Y position (mm)';
            app.FirstmappingpointYpositionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.FirstmappingpointYpositionmmEditField.Enable = 'off';
            app.FirstmappingpointYpositionmmEditField.Tooltip = {'The idea is sound, but the implementation could be improved. I suggest replacing this with the manual coordinates we can give to the robot.'};
            app.FirstmappingpointYpositionmmEditField.Value = 2;
            app.DimensionsLabel = uilabel(app.PropertiesTab);
            app.DimensionsLabel.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Laser tab)'};
            app.DimensionsLabel.Text = 'Dimensions:';
            app.RobotTab = uitab(app.TabGroup2);
            app.RobotTab.Title = 'Robot';
            app.RobotDropDownLabel = uilabel(app.RobotTab);
            app.RobotDropDownLabel.Text = 'Robot ';
            app.RobotDropDown = uidropdown(app.RobotTab);
            app.RobotConnexionB = uibutton(app.RobotTab, 'push');
            app.RobotConnexionB.ButtonPushedFcn = createCallbackFcn(app, @RobotConnexionBButtonPushed, true);
            app.RobotConnexionB.Text = 'Connect Robot';
            app.StateLampRobot = uilamp(app.RobotTab);
            app.DisconnectRobotButton = uibutton(app.RobotTab, 'push');
            app.DisconnectRobotButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRobotButtonPushed, true);
            app.DisconnectRobotButton.Enable = 'off';
            app.DisconnectRobotButton.Text = 'Disconnect Robot';
            app.SensorTab = uitab(app.TabGroup2);
            app.SensorTab.Title = 'Sensor';
            app.SensorDropDownLabel = uilabel(app.SensorTab);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.SensorTab);
            app.LaunchSensorCalibrationButton = uibutton(app.SensorTab, 'push');
            app.LaunchSensorCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchSensorCalibrationButtonPushed, true);
            app.LaunchSensorCalibrationButton.Enable = 'off';
            app.LaunchSensorCalibrationButton.Tooltip = {'Sensor calibration is only required for analogic sensors'};
            app.LaunchSensorCalibrationButton.Text = 'Launch Sensor Calibration';
            app.CalibrationstateLabel = uilabel(app.SensorTab);
            app.CalibrationstateLabel.Text = 'Sensor Calibration state :';
            app.SensorCalibrationstateLamp = uilamp(app.SensorTab);
            app.ConnectSensorButton = uibutton(app.SensorTab, 'push');
            app.ConnectSensorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSensorButtonPushed, true);
            app.ConnectSensorButton.Text = 'Connect Sensor';
            app.SensorstateLampLabel = uilabel(app.SensorTab);
            app.SensorstateLampLabel.Text = 'Sensor state :';
            app.SensorstateLamp = uilamp(app.SensorTab);
            app.SourceTab = uitab(app.TabGroup2);
            app.SourceTab.Title = 'Source';
            app.SourceDropDownLabel = uilabel(app.SourceTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.SourceTab);
            app.SourceDropDown.Items = {'Opotek IR Radiant', 'Opolette'};
            app.SourceDropDown.Value = 'Opotek IR Radiant';
            app.ConnectSourceButton = uibutton(app.SourceTab, 'push');
            app.ConnectSourceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSourceButtonPushed, true);
            app.ConnectSourceButton.Text = 'Connect Source';
            app.StateLampOpotek = uilamp(app.SourceTab);
            app.DisconnectButton = uibutton(app.SourceTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect ';
            app.GetTempButton = uibutton(app.SourceTab, 'push');
            app.GetTempButton.ButtonPushedFcn = createCallbackFcn(app, @GetTempButtonPushed, true);
            app.GetTempButton.Enable = 'off';
            app.GetTempButton.Text = 'Get Temp';
            app.AffichageTemperature = uilabel(app.SourceTab);
            app.AffichageTemperature.Text = 'Temperature : NO_DATA';
            app.TurnLampOnButton = uibutton(app.SourceTab, 'push');
            app.TurnLampOnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOnButtonPushed, true);
            app.TurnLampOnButton.Enable = 'off';
            app.TurnLampOnButton.Text = 'Turn Lamp On ';
            app.Lamp_LampONorOFF = uilamp(app.SourceTab);
            app.TurnLampOffButton = uibutton(app.SourceTab, 'push');
            app.TurnLampOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOffButtonPushed, true);
            app.TurnLampOffButton.Enable = 'off';
            app.TurnLampOffButton.Text = 'Turn Lamp Off';
            app.GetStateButton = uibutton(app.SourceTab, 'push');
            app.GetStateButton.ButtonPushedFcn = createCallbackFcn(app, @GetStateButtonPushed, true);
            app.GetStateButton.Enable = 'off';
            app.GetStateButton.Text = 'Get State';
            app.AffichageState = uilabel(app.SourceTab);
            app.AffichageState.Text = 'State : NO_DATA';
            app.FocaldistancemmEditFieldLabel = uilabel(app.SourceTab);
            app.FocaldistancemmEditFieldLabel.Text = 'Focal distance (mm) ';
            app.FocaldistancemmEditField = uieditfield(app.SourceTab, 'numeric');
            app.FocaldistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @FocaldistancemmEditFieldValueChanged, true);
            app.FocaldistancemmEditField.Enable = 'off';
            app.FocaldistancemmEditField.Tooltip = {'Height at which the robot must be positioned in order for the spot to be at focal distance. This setting is relevant to focused lasers only.'};
            app.FocaldistancemmEditField.Value = 80;
            app.ContinuousAcquisitionCheckBox = uicheckbox(app.SourceTab);
            app.ContinuousAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousAcquisitionCheckBoxValueChanged, true);
            app.ContinuousAcquisitionCheckBox.Enable = 'off';
            app.ContinuousAcquisitionCheckBox.Tooltip = {'When turned on, the laser will fire continuously instead of performing individual bursts for each pixel. Note that this does not alter the behaviour of the robot arm.'};
            app.ContinuousAcquisitionCheckBox.Text = 'Continuous Acquisition';
            app.EnergylevelVSpinnerLabel = uilabel(app.SourceTab);
            app.EnergylevelVSpinnerLabel.Text = 'Energy level (V)';
            app.EnergylevelVSpinner = uispinner(app.SourceTab);
            app.EnergylevelVSpinner.ValueChangedFcn = createCallbackFcn(app, @EnergylevelVSpinnerValueChanged, true);
            app.EnergylevelVSpinner.Enable = 'off';
            app.EnergylevelVSpinner.Value = 682;
            app.LampVoltageSet = uilamp(app.SourceTab);
            app.TimebetweenlaserburstssecondEditFieldLabel = uilabel(app.SourceTab);
            app.TimebetweenlaserburstssecondEditFieldLabel.Text = 'Time between laser bursts (second)';
            app.TimebetweenlaserburstssecondEditField = uieditfield(app.SourceTab, 'numeric');
            app.TimebetweenlaserburstssecondEditField.ValueChangedFcn = createCallbackFcn(app, @TimebetweenlaserburstssecondEditFieldValueChanged, true);
            app.TimebetweenlaserburstssecondEditField.Enable = 'off';
            app.TimebetweenlaserburstssecondEditField.Value = 3;
            app.NumberoflasershotsburstmodeEditFieldLabel = uilabel(app.SourceTab);
            app.NumberoflasershotsburstmodeEditFieldLabel.Text = 'Number of laser shots (burst mode)';
            app.NumberoflasershotsburstmodeEditField = uieditfield(app.SourceTab, 'numeric');
            app.NumberoflasershotsburstmodeEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflasershotsburstmodeEditFieldValueChanged, true);
            app.NumberoflasershotsburstmodeEditField.Enable = 'off';
            app.NumberoflasershotsburstmodeEditField.Value = 3;
            app.MassSpectrometerTab = uitab(app.TabGroup2);
            app.MassSpectrometerTab.Title = 'Mass Spectrometer';
            app.MassSpectrometerTrigerringDropDownLabel = uilabel(app.MassSpectrometerTab);
            app.MassSpectrometerTrigerringDropDownLabel.Text = 'Mass Spectrometer Trigerring :';
            app.MassSpectrometerTrigerringDropDown = uidropdown(app.MassSpectrometerTab);
            app.MassSpectrometerTrigerringDropDown.Items = {'Option 1'};
            app.MassSpectrometerTrigerringDropDown.Tooltip = {'Synchronizes the start of an MS acquisition with the robot and the laser. '};
            app.StartTab = uitab(app.TabGroup2);
            app.StartTab.Title = 'Start';
            app.GotoMappingPositionButton = uibutton(app.StartTab, 'push');
            app.GotoMappingPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotoMappingPositionButtonPushed, true);
            app.GotoMappingPositionButton.Tooltip = {'Moves the robot to its starting position for data acquisition.'};
            app.GotoMappingPositionButton.Text = 'Go to Mapping Position';
            app.ClearMapButton = uibutton(app.StartTab, 'push');
            app.ClearMapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMapButtonPushed, true);
            app.ClearMapButton.Text = 'Clear Map';
            app.StatusWaitingLabel = uilabel(app.StartTab);
            app.StatusWaitingLabel.Text = 'Status: Waiting...';
            app.LaunchMappingButton = uibutton(app.StartTab, 'push');
            app.LaunchMappingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMappingButtonPushed, true);
            app.LaunchMappingButton.Text = 'Launch Mapping';
            app.QuittoMenuButton_2 = uibutton(app.StartTab, 'push');
            app.QuittoMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @QuittoMenuButton_2Pushed, true);
            app.QuittoMenuButton_2.Text = 'Quit to Menu';
            app.RobotStateDisconnectedLabel = uilabel(app.StartTab);
            app.RobotStateDisconnectedLabel.Text = 'Robot State: Disconnected';
            app.SourceStateOffLabel = uilabel(app.StartTab);
            app.SourceStateOffLabel.Text = 'Source State: Off';
            app.MappingModeTopographyOnlyLabel = uilabel(app.StartTab);
            app.MappingModeTopographyOnlyLabel.Text = 'Mapping Mode: Topography Only';
            app.MapDimensionsmmLabel = uilabel(app.StartTab);
            app.MapDimensionsmmLabel.Text = 'Map Dimensions (mm)';
            app.X16Label = uilabel(app.StartTab);
            app.X16Label.Text = 'X: 16';
            app.Y16Label = uilabel(app.StartTab);
            app.Y16Label.Text = 'Y: 16';
            app.Resolutionm500Label = uilabel(app.StartTab);
            app.Resolutionm500Label.Text = 'Resolution (µm): 500';
            app.SensorStateOffLabel = uilabel(app.StartTab);
            app.SensorStateOffLabel.Text = 'Sensor State: Off';
            app.EstimatedDurationLabel = uilabel(app.StartTab);
            app.EstimatedDurationLabel.Text = 'Estimated Duration: ';
            app.XPositionEditFieldLabel = uilabel(app.StartTab);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.XPositionEditField.Enable = 'off';
            app.XPositionEditField.Value = 150;
            app.YPositionEditFieldLabel = uilabel(app.StartTab);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.YPositionEditField.Enable = 'off';
            app.YPositionEditField.Value = 2;
            app.ZPositionEditFieldLabel = uilabel(app.StartTab);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.ZPositionEditField.Enable = 'off';
            app.ZPositionEditField.Value = 100;
            app.GOTOButton = uibutton(app.StartTab, 'push');
            app.GOTOButton.ButtonPushedFcn = createCallbackFcn(app, @GOTOButtonPushed, true);
            app.GOTOButton.Enable = 'off';
            app.GOTOButton.Text = 'GO TO';
            app.LaserBurstButton = uibutton(app.StartTab, 'push');
            app.LaserBurstButton.ButtonPushedFcn = createCallbackFcn(app, @LaserBurstButtonPushed, true);
            app.LaserBurstButton.Enable = 'off';
            app.LaserBurstButton.Text = 'Laser Burst ';
            app.EMERGENCYSTOPACQUISITIONButton = uibutton(app.StartTab, 'state');
            app.EMERGENCYSTOPACQUISITIONButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPACQUISITIONButtonValueChanged, true);
            app.EMERGENCYSTOPACQUISITIONButton.Text = {'EMERGENCY STOP '; ' ACQUISITION'};
            app.FocaldistancemmEditField_2Label = uilabel(app.StartTab);
            app.FocaldistancemmEditField_2Label.Text = 'Focal distance (mm) ';
            app.FocaldistancemmEditField_2 = uieditfield(app.StartTab, 'numeric');
            app.FocaldistancemmEditField_2.ValueChangedFcn = createCallbackFcn(app, @FocaldistancemmEditField_2ValueChanged, true);
            app.FocaldistancemmEditField_2.Enable = 'off';
            app.FocaldistancemmEditField_2.Tooltip = {'Height at which the robot must be positioned in order for the spot to be at focal distance. This setting is relevant to focused lasers only.'};
            app.FocaldistancemmEditField_2.Value = 80;
            app.LogTab = uitab(app.TabGroup2);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.TextArea.Editable = 'off';
            app.TextAreaLabel = uilabel(app.LogTab);
            app.TextAreaLabel.Enable = 'off';
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.OMGMSIModeSelectionPanel = uipanel(app.UIFigure);
            app.OMGMSIModeSelectionPanel.Title = 'OMG-MSI - Mode Selection';
            app.DataAcquisitionButton_2 = uibutton(app.OMGMSIModeSelectionPanel, 'push');
            app.DataAcquisitionButton_2.ButtonPushedFcn = createCallbackFcn(app, @DataAcquisitionButton_2Pushed, true);
            app.DataAcquisitionButton_2.Text = 'Data Acquisition';
            app.DataProcessingButton_2 = uibutton(app.OMGMSIModeSelectionPanel, 'push');
            app.DataProcessingButton_2.ButtonPushedFcn = createCallbackFcn(app, @DataProcessingButton_2Pushed, true);
            app.DataProcessingButton_2.Text = 'Data Processing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMG_MSI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end