classdef mapping_APP_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TerminalTextArea                matlab.ui.control.TextArea
        TerminalTextAreaLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MappingTab                      matlab.ui.container.Tab
        LaserDropDown                   matlab.ui.control.DropDown
        LaserDropDownLabel              matlab.ui.control.Label
        EstimationofmappingtimeLabel    matlab.ui.control.Label
        NoMappingLabel                  matlab.ui.control.Label
        mapLabel                        matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        CalibrationstateLamp            matlab.ui.control.Lamp
        CalibrationstateLabel           matlab.ui.control.Label
        FirstmappingpointXpositionmmEditField  matlab.ui.control.NumericEditField
        FirstmappingpointXpositionmmEditFieldLabel  matlab.ui.control.Label
        EnergylevelVSpinner             matlab.ui.control.Spinner
        EnergylevelVSpinnerLabel        matlab.ui.control.Label
        NumberoflasershotburstmodeEditField  matlab.ui.control.NumericEditField
        NumberoflasershotburstmodeEditFieldLabel  matlab.ui.control.Label
        TimebetweentwolasershotsecondEditField  matlab.ui.control.NumericEditField
        TimebetweentwolasershotsecondEditFieldLabel  matlab.ui.control.Label
        MicroprobefocaldistanceinZaxismmEditField  matlab.ui.control.NumericEditField
        MicroprobefocaldistanceinZaxismmEditFieldLabel  matlab.ui.control.Label
        SurfaceoffestmmEditField        matlab.ui.control.NumericEditField
        SurfaceoffestmmEditFieldLabel   matlab.ui.control.Label
        ZonesizeYaxismmEditField        matlab.ui.control.NumericEditField
        ZonesizeYaxismmEditFieldLabel   matlab.ui.control.Label
        ZonesizeXaxismmEditField        matlab.ui.control.NumericEditField
        ZonesizeXaxismmEditFieldLabel   matlab.ui.control.Label
        MaximumheightmmofthesampleEditField  matlab.ui.control.NumericEditField
        MaximumheightmmofthesampleEditFieldLabel  matlab.ui.control.Label
        MappingStepmmEditField          matlab.ui.control.NumericEditField
        MappingStepmmEditFieldLabel     matlab.ui.control.Label
        MapNameEditField                matlab.ui.control.EditField
        MapNameEditFieldLabel           matlab.ui.control.Label
        ButtonGroupMappingSpeed         matlab.ui.container.ButtonGroup
        FastMappingButton               matlab.ui.control.RadioButton
        WithrepositioningButton         matlab.ui.control.RadioButton
        ClearMapButton                  matlab.ui.control.Button
        LampVoltageSet                  matlab.ui.control.Lamp
        GetStateButton                  matlab.ui.control.Button
        GOTOButton                      matlab.ui.control.Button
        GetTempButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        AffichageState                  matlab.ui.control.Label
        DisconnectRobotButton           matlab.ui.control.Button
        AffichageTemperature            matlab.ui.control.Label
        Lamp_LampONorOFF                matlab.ui.control.Lamp
        TurnLampOffButton               matlab.ui.control.Button
        TurnLampOnButton                matlab.ui.control.Button
        GotoPositionButton              matlab.ui.control.Button
        BurstModeButton                 matlab.ui.control.Button
        LaunchSensorCalibrationButton   matlab.ui.control.Button
        StateLampOpotek                 matlab.ui.control.Lamp
        ConnectLaserButton              matlab.ui.control.Button
        StateLampRobot                  matlab.ui.control.Lamp
        RobotConnexionB                 matlab.ui.control.Button
        MappingChoiceButtonGroup        matlab.ui.container.ButtonGroup
        LampWithSpectro_2D              matlab.ui.control.Lamp
        DMassSpectometryButton          matlab.ui.control.RadioButton
        LampWithSpectro                 matlab.ui.control.Lamp
        LampOnlyTopo                    matlab.ui.control.Lamp
        WithMassSpectometryButton       matlab.ui.control.RadioButton
        OnlyTopographicButton           matlab.ui.control.RadioButton
        LaunchMappingButton             matlab.ui.control.Button
        ProcessingTab                   matlab.ui.container.Tab
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        LoadmzXMLButton                 matlab.ui.control.Button
        LouddataonthemapSwitch          matlab.ui.control.Switch
        LouddataonthemapSwitchLabel     matlab.ui.control.Label
        LoadBiomapButton                matlab.ui.control.Button
        biomapLabel_4                   matlab.ui.control.Label
        NamebiomapfileEditField         matlab.ui.control.EditField
        NamebiomapfileEditFieldLabel    matlab.ui.control.Label
        MapfilenameEditField_2          matlab.ui.control.EditField
        MapfilenameEditField_2Label     matlab.ui.control.Label
        mapLabel_5                      matlab.ui.control.Label
        FusionpercentSlider             matlab.ui.control.Slider
        FusionpercentSliderLabel        matlab.ui.control.Label
        MapFromSpectraButton            matlab.ui.control.Button
        InternalTriggeringSwitch        matlab.ui.control.Switch
        InternalTriggeringSwitchLabel   matlab.ui.control.Label
        PlotChromatogramButton          matlab.ui.control.Button
        TimeValue                       matlab.ui.control.Label
        ExtractSpectraZoneButton        matlab.ui.control.Button
        ExtractOneSpectraButton         matlab.ui.control.Button
        LaunchTimeEstimationButton      matlab.ui.control.Button
        LoudthresholdEditField          matlab.ui.control.NumericEditField
        LoudthresholdEditFieldLabel     matlab.ui.control.Label
        maxEditField_2                  matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        MassbandminEditField_2          matlab.ui.control.NumericEditField
        MassbandminEditField_2Label     matlab.ui.control.Label
        BiningwindowEditField           matlab.ui.control.NumericEditField
        BiningwindowEditFieldLabel      matlab.ui.control.Label
        MainmassesonthemapButton        matlab.ui.control.Button
        Timebetween2lasershotsecondEditField  matlab.ui.control.NumericEditField
        Timebetween2lasershotsecondEditFieldLabel  matlab.ui.control.Label
        CreateRGBmapButton              matlab.ui.control.Button
        rgbmapLabel                     matlab.ui.control.Label
        NameofthegeneratedRGBmapEditField  matlab.ui.control.EditField
        NameofthegeneratedRGBmapEditFieldLabel  matlab.ui.control.Label
        Mass03EditField                 matlab.ui.control.NumericEditField
        Mass03EditFieldLabel            matlab.ui.control.Label
        Mass02EditField                 matlab.ui.control.NumericEditField
        Mass02EditFieldLabel            matlab.ui.control.Label
        Mass01EditField                 matlab.ui.control.NumericEditField
        Mass01EditFieldLabel            matlab.ui.control.Label
        MASSbiomapLabel                 matlab.ui.control.Label
        BiomapfilenameEditField         matlab.ui.control.EditField
        BiomapfilenameEditFieldLabel    matlab.ui.control.Label
        LaunchReconstructionButton      matlab.ui.control.Button
        MapfilenameEditField            matlab.ui.control.EditField
        MapfilenameEditFieldLabel       matlab.ui.control.Label
        mapLabel_2                      matlab.ui.control.Label
        biomapLabel                     matlab.ui.control.Label
        NameofthegeneratedbiomapfileEditField  matlab.ui.control.EditField
        NameofthegeneratedbiomapfileEditFieldLabel  matlab.ui.control.Label
        maximumEditField                matlab.ui.control.NumericEditField
        maximumEditFieldLabel           matlab.ui.control.Label
        MasslimitsminimumEditField      matlab.ui.control.NumericEditField
        MasslimitsminimumEditFieldLabel  matlab.ui.control.Label
        matLabel_2                      matlab.ui.control.Label
        MatfilenameEditField            matlab.ui.control.EditField
        MatfilenameEditFieldLabel       matlab.ui.control.Label
        LaunchPreprocessingButton       matlab.ui.control.Button
        BiningwindowsizeEditField       matlab.ui.control.NumericEditField
        BiningwindowsizeEditFieldLabel  matlab.ui.control.Label
        BeginThresholdEditField         matlab.ui.control.NumericEditField
        BeginThresholdEditFieldLabel    matlab.ui.control.Label
        matLabel                        matlab.ui.control.Label
        mzXMLLabel                      matlab.ui.control.Label
        NameofthegeneratedmatfileEditField  matlab.ui.control.EditField
        NameofthegeneratedmatfileEditFieldLabel  matlab.ui.control.Label
        mzXMLfilenameEditField          matlab.ui.control.EditField
        mzXMLfilenameEditFieldLabel     matlab.ui.control.Label
        LaunchPeakDetectionButton       matlab.ui.control.Button
        PCAAnalysisTab                  matlab.ui.container.Tab
        ShowalsoallmassesonanothercorrelationcircleCheckBox  matlab.ui.control.CheckBox
        NumberofpointonthecorrelationcirclemaximummzEditField  matlab.ui.control.NumericEditField
        NumberofpointonthecorrelationcirclemaximummzEditFieldLabel  matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        MassbandminEditField            matlab.ui.control.NumericEditField
        MassbandminEditFieldLabel       matlab.ui.control.Label
        BiningwindowmassEditField       matlab.ui.control.NumericEditField
        BiningwindowmassEditFieldLabel  matlab.ui.control.Label
        matLabel_3                      matlab.ui.control.Label
        MatfilenameEditField_2          matlab.ui.control.EditField
        MatfilenameEditField_2Label     matlab.ui.control.Label
        LaunchmatPCAAnalysisButton      matlab.ui.control.Button
        MassesEditField                 matlab.ui.control.EditField
        MassesEditFieldLabel            matlab.ui.control.Label
        LaunchMapPCAAnalysisButton      matlab.ui.control.Button
        BiomapfilenameEditField_2Label  matlab.ui.control.Label
        BiomapfilenameEditField_2       matlab.ui.control.EditField
        MASSbiomapLabel_2               matlab.ui.control.Label
        MapRectificationTab             matlab.ui.container.Tab
        PointMultiplierEditField_2      matlab.ui.control.NumericEditField
        PointMultiplierEditField_2Label  matlab.ui.control.Label
        NameoftheinterpolatedbiomapEditField  matlab.ui.control.EditField
        NameoftheinterpolatedbiomapEditFieldLabel  matlab.ui.control.Label
        NameofthebiomapEditField        matlab.ui.control.EditField
        NameofthebiomapEditFieldLabel   matlab.ui.control.Label
        biomapLabel_3                   matlab.ui.control.Label
        biomapLabel_2                   matlab.ui.control.Label
        LaunchBiomapInterpolationButton  matlab.ui.control.Button
        PointMultiplierEditField        matlab.ui.control.NumericEditField
        PointMultiplierEditFieldLabel   matlab.ui.control.Label
        NameoftheinterpolatedmapEditField  matlab.ui.control.EditField
        NameoftheinterpolatedmapEditFieldLabel  matlab.ui.control.Label
        NameofthemapEditField_22        matlab.ui.control.EditField
        NameofthemapEditField_2Label    matlab.ui.control.Label
        mapLabel_7                      matlab.ui.control.Label
        mapLabel_6                      matlab.ui.control.Label
        LaunchInterpolationButton       matlab.ui.control.Button
        MaximumthresholdmmEditField     matlab.ui.control.NumericEditField
        MaximumthresholdmmEditFieldLabel  matlab.ui.control.Label
        MinimumthresholdmmEditField     matlab.ui.control.NumericEditField
        MinimumthresholdmmEditFieldLabel  matlab.ui.control.Label
        mapLabel_4                      matlab.ui.control.Label
        mapLabel_3                      matlab.ui.control.Label
        LaunchRectificationButton       matlab.ui.control.Button
        NameofthecorrectedmapEditField  matlab.ui.control.EditField
        NameofthecorrectedmapEditFieldLabel  matlab.ui.control.Label
        NameofthemapEditField           matlab.ui.control.EditField
        NameofthemapEditFieldLabel      matlab.ui.control.Label
        LDATab                          matlab.ui.container.Tab
        ColorDropDown_6                 matlab.ui.control.DropDown
        ColorDropDown_6Label            matlab.ui.control.Label
        ColorDropDown_5                 matlab.ui.control.DropDown
        ColorDropDown_5Label            matlab.ui.control.Label
        Class06EditField                matlab.ui.control.EditField
        Class06EditFieldLabel           matlab.ui.control.Label
        Class05EditField                matlab.ui.control.EditField
        Class05EditFieldLabel           matlab.ui.control.Label
        Class04EditField                matlab.ui.control.EditField
        Class04EditFieldLabel           matlab.ui.control.Label
        ColorDropDown_4                 matlab.ui.control.DropDown
        ColorDropDown_4Label            matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        ColorDropDown_3Label            matlab.ui.control.Label
        ColorDropDown_2                 matlab.ui.control.DropDown
        ColorDropDown_2Label            matlab.ui.control.Label
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        LaunchLDAReconstructionButton   matlab.ui.control.Button
        Class03EditField                matlab.ui.control.EditField
        Class03EditFieldLabel           matlab.ui.control.Label
        Class02EditField                matlab.ui.control.EditField
        Class02EditFieldLabel           matlab.ui.control.Label
        Class01EditField                matlab.ui.control.EditField
        Class01EditFieldLabel           matlab.ui.control.Label
        NameofthecsvfileEditField       matlab.ui.control.EditField
        NameofthecsvfileEditFieldLabel  matlab.ui.control.Label
        csvLabel                        matlab.ui.control.Label
        NameofthemapfileEditField       matlab.ui.control.EditField
        NameofthemapfileEditFieldLabel  matlab.ui.control.Label
        mapLabel_8                      matlab.ui.control.Label
        matLabel_4                      matlab.ui.control.Label
        NameofthematfileEditField       matlab.ui.control.EditField
        NameofthematfileEditFieldLabel  matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        Scils_Extraction                matlab.ui.container.Tab
        CoregistertwoimagesButton       matlab.ui.control.Button
        ExportcsvtopngButton            matlab.ui.control.Button
        NormalizecsvRMSButton           matlab.ui.control.Button
        NormalizecsvTICButton           matlab.ui.control.Button
        ExportcsvtoplyButton            matlab.ui.control.Button
        BinningwindowsizeEditField      matlab.ui.control.NumericEditField
        BinningwindowsizeEditFieldLabel  matlab.ui.control.Label
        MaxMzEditField                  matlab.ui.control.NumericEditField
        MaxMzEditFieldLabel             matlab.ui.control.Label
        MinMzEditField                  matlab.ui.control.NumericEditField
        MinMzEditFieldLabel             matlab.ui.control.Label
        Input2EditField                 matlab.ui.control.EditField
        Input2EditFieldLabel            matlab.ui.control.Label
        Input1EditField                 matlab.ui.control.EditField
        Input1EditFieldLabel            matlab.ui.control.Label
        mapLabel_9                      matlab.ui.control.Label
        matLabel_6                      matlab.ui.control.Label
        ExportwithoutbinningPLACEHOLDERButton  matlab.ui.control.Button
        ExportwithbinningButton         matlab.ui.control.Button
        ExporttocsvLabel                matlab.ui.control.Label
        ExportandcoregisterBiomapButton  matlab.ui.control.Button
        ExportrawBioMapButton           matlab.ui.control.Button
        Exportto3DfileLabel             matlab.ui.control.Label
        ExporttoSCILSLabel              matlab.ui.control.Label
        LaunchExtractionButton          matlab.ui.control.Button
        txtLabel                        matlab.ui.control.Label
        OutputnameofthecreatedtxtfileEditField  matlab.ui.control.EditField
        OutputnameofthecreatedtxtfileEditFieldLabel  matlab.ui.control.Label
        InputMatfilenameEditField_scils  matlab.ui.control.EditField
        InputMatfilenameEditFieldLabel  matlab.ui.control.Label
        matLabel_5                      matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        DisplayclusterButton            matlab.ui.control.Button
        SegmentationButton              matlab.ui.control.Button
        ClusterIndexEditField           matlab.ui.control.NumericEditField
        ClusterIndexEditFieldLabel      matlab.ui.control.Label
        ClusterNumberEditField          matlab.ui.control.NumericEditField
        ClusterNumberEditFieldLabel     matlab.ui.control.Label
        DataReductionButton             matlab.ui.control.Button
        DisplaySumofIntensitiesButton   matlab.ui.control.Button
        DataImportationButton           matlab.ui.control.Button
    end


    properties (Access = private)
        valmin = 0; % Minimum M/z for 'Map from spectra'
        valmax = 2000; % Maximum M/z for 'Map from spectra'
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Variables initialisation
            global robot;
            robot.arret = 0; % permet arret securité en cas de soucis
            global state;
            state.opo = 0;
            state.etal = 0;
            state.robot = 0;
            global old_name;
            old_name = "";
            %disp(pwd); % ne permet pas de récupérer le chemin en dynamique.
            % CODE A DECOMMENTER POUR AJOUTER LA CORRECTION DE WORKSPACE
%             dest = '/home/pchaillo/Documents/prism'; % chemin en dur, à bien choisir avant de décommenter cette partie
%             cd(dest);
        end

        % Button pushed function: LaunchMappingButton
        function LaunchMappingButtonPushed(app, event)

            % main mapping
            path(path,'to add')

            % add folders to workspace
            path(path,'map files')
            %path(path,'code')
            path(path,'code/code_for_mapping')
            path(path,'code/code_for_recording')

            app.NoMappingLabel.Text = 'Mapping in progress...';

            % Variables initialisation
            global scan;
            global zone;
            global robot;
            robot.arret = 0; % permet arret securité total en cas de soucis

            global carte;
            carte.x = 0;
            carte.y = 0;
            carte.i = 0;
            carte.r = 0;
            carte.time = 0;

            global t;
            global laser;
            global etal;

            %% Parameters
            nom_1 = app.MapNameEditField.Value;
            nom = strcat(nom_1,'.map');
            scan.pas = app.MappingStepmmEditField.Value;         % mapping step (mm)
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            zone.dec = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            zone.dim_x = app.ZonesizeXaxismmEditField.Value;        % dimension of the mapping zone in the x axis, should be an even value
            zone.dim_y = app.ZonesizeYaxismmEditField.Value;        % dimension of the mapping zone in the y axis, should be an even value
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm
            scan.dh = app.MicroprobefocaldistanceinZaxismmEditField.Value + scan.s_offset;          % z offset between the robot arm and the sample to be analysed

            scan.voltage_value = app.EnergylevelVSpinner.Value;
            scan.t_b = app.TimebetweentwolasershotsecondEditField.Value; % pause time between two laser shot, in second
            scan.nb_shot = app.NumberoflasershotburstmodeEditField.Value; % number of shot for the burst mode
            %             biometrical_scanning = 1; % 0 only topographical / 1 total scanning

            % Variables Cartographie %
            scan.etal = 0.25; % pas d'étallonage => 0.25 mm recommandé / step of the camera calibration
            scan.pre = 2; % multiplier for the number of point in the map from the scanning : if scanning step=1mm then a factor of 1 will keep this resolution, a factor of two will create a map with point every 0.5mm

            seuil = 0;

            scan.fast = app.FastMappingButton.Value;

            ard = arduino(); % connect the arduino (sensor acquisition and spectro triggering)

            pos_d_i= [zone.dec  2  scan.dh 180 0 180];
            set_pos(pos_d_i,t);  % send the mapping initial position of the robot

            if app.WithMassSpectometryButton.Value
                time_ref = trigger_spectro_time(ard);
                %  pause(2); ??????? % 5 ?
                real_time_mapping_scanning_2(t,ard,etal.tab_etal,laser,scan.t_b,scan.nb_shot,time_ref); % do the mapping step
            elseif app.OnlyTopographicButton.Value
                % mono_carto_topo2(t,ard,etal.tab_etal);
                %                time_ref = trigger_spectro_time(ard); % for tests
                %                pause(5); % 5 ?
                %                topographic_mapping_time_test(t,ard,etal.tab_etal,time_ref);
                topographic_mapping(t,ard,etal.tab_etal);
            elseif app.DMassSpectometryButton.Value
                %carto_top_2d();
                time_ref = trigger_spectro_time(ard);
                scanning_2d_2(t,laser,scan.nb_shot,scan.t_b,time_ref);
            else
                disp('Mapping choice problem');
            end

            %[ carte.r, nb_err ] = recti(carte.i); % correction of the map ( delete impossible values )
            carte.r = carte.i;

            figure(4)
            subplot(2,4,[1,2,5,6]), mesh(carte.x,carte.y,carte.i); % initial map
            axis equal
            subplot(2,4,[3,4,7,8]), mesh(carte.x,carte.y,carte.r); % rectified map
            axis equal

            time_rec = record_map4(carte.x,carte.y,carte.r,nom,seuil,carte.time);

            time_text = strcat('Map finished. Recommended reconstruction time : ',time_rec);
            app.NoMappingLabel.Text = time_text;

            % once the map is done, go back to the 1st point position
            pos_d_i= [zone.dec  2  scan.dh 180 0 180];
            set_pos(pos_d_i,t);  % send the mapping initial position of the robot

            % hugh_to_sleep(t);

        end

        % Button pushed function: RobotConnexionB
        function RobotConnexionBButtonPushed(app, event)

            app.StateLampRobot.Color = [0 0 1];

            path(path,'code/code_for_mapping')
            global t % ou fermeture de la connexion
            t = init_tcp();
            app.StateLampRobot.Color = [0 1 0];

            global state;
            state.robot = 1 ;

        end

        % Button pushed function: ConnectLaserButton
        function ConnectLaserButtonPushed(app, event)
            app.StateLampOpotek.Color = [0 0 1];
            path(path,'code/code_for_mapping')
            path(path,'code/hardware/laser')
            global scan;
            global laser

            scan.voltage_value = app.EnergylevelVSpinner.Value; % put on laser variable instead ?
            laser.type = app.LaserDropDown.Value;

            disp(scan.voltage_value)
            disp(laser.type)

            if laser.type == "Opotek IR Radiant"
                laser.class = LaserOpotek
            elseif laser.type == "Opolette"
                laser.class = LaserOpolette
            end

            laser.connexion = laser.class.init();

            [state_string, state_double] = laser.class.get_state(laser.connexion)
            app.StateLampOpotek.Color = [0 1 0];
            app.AffichageState.Text = state_string;

            if laser.type == "Opotek IR Radiant"
                wdog_loop = 1; % there is a watchdog on Opotek laser (also on Opolette ?) that put the laser in security mode if there is no message for 4 seconds. So I send message each 2 seconds. If the laser is in security mode, you should restart it in order to be able to command it again.
                while wdog_loop == 1
                    [state_string, state_double]= laser.class.get_state(laser.connexion)
                    if state_string == -1
                        wdog_loop = 0;
                        disp('Plus de retour Opotek')
                    end
                    app.AffichageState.Text = state_string;
                    pause(2);
                end
            end

        end

        % Button pushed function: LaunchSensorCalibrationButton
        function LaunchSensorCalibrationButtonPushed(app, event)
            app.CalibrationstateLamp.Color = [0 0 1];

            path(path,'code/code_for_mapping')
            global etal;
            global t;
            global scan;
            global zone;
            global state;
            scan.etal = 0.25; % pas d'étallonage => 0.25 mm recommandé / step of the camera calibration
            zone.dec = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height

            ard = arduino(); % connect the arduino (sensor acquisition and spectro triggering)
            tab_e = etal_vid3(t,ard); % calibration of the sensor (ILD1320-25)
            etal.tab_etal = tab_continu(tab_e); % correction of the calibration ( delete false values )
            % Show the calibration graph
            figure()
            hold on
            for i = 1 : length(etal.tab_etal)
                scatter(etal.tab_etal(1,i),etal.tab_etal(2,i))
            end
            xlabel('Hauteur de mesure, en mm')
            ylabel('Tension mesuree')
            hold off
            app.CalibrationstateLamp.Color = [0 1 0];
            app.LampOnlyTopo.Color = [0 1 0];
            state.etal = 1;
            if state.opo == 1
                app.LampWithSpectro.Color = [0 1 0];
            end
        end

        % Button pushed function: BurstModeButton
        function BurstModeButtonPushed(app, event)
            path(path,'code/code_for_mapping')
            global laser;
            global scan;
            scan.nb_shot = app.NumberoflasershotburstmodeEditField.Value; % number of shot for the burst mode

            laser.class.tir(laser.connexion,scan.nb_shot)

        end

        % Button pushed function: GotoPositionButton
        function GotoPositionButtonPushed(app, event)

            path(path,'code/code_for_mapping')
            global scan;
            global t;
            global zone;

            %for security check
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            zone.dec = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head

            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm
            scan.dh = app.MicroprobefocaldistanceinZaxismmEditField.Value + scan.s_offset;          % z offset between the robot arm and the sample to be analysed

            pos_d_i= [zone.dec  2  scan.dh 180 0 180];
            set_pos(pos_d_i,t);  % send the mapping initial position of the robot

        end

        % Callback function
        function LoadCalibrationFileButtonPushed(app, event)
            global etal;

            nom_1 = app.EtalfilenameEditField.Value;
            nom = strcat(nom_1,'.etal');

            etal.tab_etal = load_etal_fcn(nom);

            app.CalibrationstateLamp.Color = [0 1 0];

        end

        % Callback function
        function LaunchPolynomialInterpolationButtonPushed(app, event)
            global carte;
            global scan;
            scan.pre = 2;

            poly_appro_top()
            figure()
            mesh(carte.x_o,carte.y_o,carte.o);
            %colormap(autumn);
            axis equal
            grid off
            axis off
        end

        % Callback function
        function RecordInterpolatedMapButtonPushed(app, event)
            global carte
            path(path,'map files')
            seuil = 0;
            nom_1 = app.MapNameEditField.Value;
            nom = strcat(nom_1,'_2.map');
            record_map2(carte.x_o,carte.y_o,carte.o,nom,seuil)
        end

        % Button pushed function: TurnLampOnButton
        function TurnLampOnButtonPushed(app, event)
            app.Lamp_LampONorOFF.Color = [0 0 1];
            path(path,'code/code_for_mapping')
            global laser;

            temp = laser.class.get_temp(laser.connexion);
            text_temp = strcat('Temperature : ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;

            state_string = laser.class.lamp_on(laser.connexion)
            [state_string, state_double] = laser.class.get_state(laser.connexion);
            app.AffichageState.Text = state_string;

            if state_double == 2
                app.Lamp_LampONorOFF.Color = [0 1 0];
            else
                app.Lamp_LampONorOFF.Color = [ 1 0 0];
            end

            global state;
            if state.etal == 1
                app.LampWithSpectro.Color = [0 1 0];
            end
            if state.robot == 1
                app.LampWithSpectro_2D.Color = [0 1 0];
            end
            state.opo = 1;
        end

        % Button pushed function: TurnLampOffButton
        function TurnLampOffButtonPushed(app, event)
            path(path,'code/code_for_mapping')
            global laser;
            state_string = laser.class.lamp_off(laser.connexion);
            [state_text, state_double] = laser.class.get_state(laser.connexion);
            if state_double == 1
                app.Lamp_LampONorOFF.Color = [1 0 0];
            else
                app.Lamp_LampONorOFF.Color = [0 1 1];
            end
            app.AffichageState.Text = state_text;

            %app.Lamp_LampONorOFF.Color = [0 1 1];
        end

        % Button pushed function: DisconnectRobotButton
        function DisconnectRobotButtonPushed(app, event)
            path(path,'code/code_for_mapping')
            global t;
            global scan;
            %for security check
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            hugh_to_sleep(t);
            app.StateLampRobot.Color = [1 0 0];

            global state;
            state.robot = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global laser;
            clear laser.connexion;
            clear laser;
        end

        % Button pushed function: GetTempButton
        function GetTempButtonPushed(app, event)
            global laser;
            temp = laser.class.get_temp(laser.connexion);
            text_temp = strcat('Temperature : ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;
        end

        % Button pushed function: GOTOButton
        function GOTOButtonPushed(app, event)
            global t;
            global scan;

            %for security check
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            x = app.XPositionEditField.Value;
            y = app.YPositionEditField.Value;
            z = app.ZPositionEditField.Value;
            pos_d_i= [x  y  z 180 0 180];
            set_pos(pos_d_i,t);  % send the mapping initial position of the robot

        end

        % Button pushed function: GetStateButton
        function GetStateButtonPushed(app, event)
            global laser;
            global state;
            [state_text, state_double] = laser.class.get_state(laser.connexion);
            % state_text = choose_state_text(state_double);
            if state_double ~= 2
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.LampWithSpectro.Color = [1 0 0];
                state.opo = 0;
            end
            app.AffichageState.Text = state_text;

        end

        % Value changed function: EnergylevelVSpinner
        function EnergylevelVSpinnerValueChanged(app, event)
            global scan;
            global laser;

            app.LampVoltageSet.Color = [0 0 1];

            scan.voltage_value = app.EnergylevelVSpinner.Value;
            real_voltage = laser.class.set_voltage(laser.connexion,scan.voltage_value);
            if real_voltage ~= scan.voltage_value
                app.LampVoltageSet.Color = [1 0 0];
            else
                app.LampVoltageSet.Color = [0 1 0];
            end

        end

        % Button pushed function: ClearMapButton
        function ClearMapButtonPushed(app, event)
            global carte;
            %             global zone;
            %             global scan;
            %             scan.pas = app.MappingStepmmEditField.Value;         % mapping step (mm)
            %             zone.dec = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            %             zone.dim_x = app.ZonesizeXaxismmEditField.Value;        % dimension of the mapping zone in the x axis, should be an even value
            %             zone.dim_y = app.ZonesizeYaxismmEditField.Value;        % dimension of the mapping zone in the y axis, should be an even value
            carte.x = 0;
            carte.y = 0;
            carte.i = 0;
            carte.r = 0;


        end

        % Selection changed function: ButtonGroupMappingSpeed
        function ButtonGroupMappingSpeedSelectionChanged(app, event)
            global scan;
            scan.fast = app.FastMappingButton.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% deconnecte le robot
            path(path,'code/code_for_mapping')
            global t;
            global scan;
            %for security check
            scan.hmax = app.MaximumheightmmofthesampleEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            scan.s_offset = app.SurfaceoffestmmEditField.Value;        % surface offset in mm

            global state;
            if isfield(state,'robot')
                if state.robot == 1
                    close_tcp_r(t);
                end
            end
            app.StateLampRobot.Color = [1 0 0];

            global state;
            state.robot = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];

            %% ferme l'application
            delete(app)

        end

        % Button pushed function: LaunchPeakDetectionButton
        function LaunchPeakDetectionButtonPushed(app, event)
            %             clc       % pas s^ur
            %             close all
            %             clear all
            global bio;
            global mzXML;

            path(path,'to add')

            path(path,'mzXML files')
            path(path,'code/code_for_preprocessing')
            path(path,'code/code_for_recording')

            %             fprintf('(%s) - Loading the mzXML file\n', datestr(now,'HH:MM:SS'));
            %             mzxml_r = app.mzXMLfilenameEditField.Value; % dans "Load mzXML"
            %             nom_mzxml = strcat(mzxml_r,'.mzXML');
            %             mzXMLStruct = mzxmlread(nom_mzxml);

            threshold_begin = app.BeginThresholdEditField.Value;
            t_b = app.Timebetween2lasershotsecondEditField.Value;
            min_threshold = app.LoudthresholdEditField.Value;

            tolerance = app.FusionpercentSlider.Value;

            intern_trig = app.InternalTriggeringSwitch.Value;

            nom_map_r = app.MapfilenameEditField_2.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            if isfield(carte,'time')
                carte_time = carte.time;
            else
                carte_time = 0;
            end


            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));

            if intern_trig(1:2) == 'On'
                intern_flag = 1;
                % [bio_dat ,time,g] = peak_detection8_internal(mzXMLStruct,threshold_begin,t_b,min_threshold); % take only the usefull informations
            else
                intern_flag = 0;
                % [bio_dat ,time,g] = peak_detection8(mzXMLStruct,threshold_begin,t_b,min_threshold); % take only the usefull informations
            end

            %[bio_dat ,time,g] = peak_detection11(mzXMLStruct,threshold_begin,t_b,min_threshold,intern_flag,tolerance); % take only the usefull informations
            [bio_dat ,time,g] = peak_detection_pt_par_pt_8(mzXML.Struct,threshold_begin,t_b,min_threshold,intern_flag,tolerance,carte_time); % take only the usefull informations

            bio.bio_dat = bio_dat;
            bio.time = time;
            bio.g = g;

            time_str = num2str(time);
            time_str2 = strcat(time_str, ' seconds');
            app.TimeValue.Text = time_str2;

            disp(length(bio_dat));
        end

        % Button pushed function: LaunchPreprocessingButton
        function LaunchPreprocessingButtonPushed(app, event)
            path(path,'code/code_for_preprocessing')
            path(path,'code/code_for_recording')

            global bio;
            bio_dat = bio.bio_dat;

            win = app.BiningwindowsizeEditField.Value;

            mat_r = app.NameofthegeneratedmatfileEditField.Value;
            nom = strcat(mat_r,'.mat');

            l = length(bio_dat);

            fprintf('(%s) - Preprocessing data \n', datestr(now,'HH:MM:SS'));
            for i = 1 : l
                bio_dat2(i) = preprocess6(bio_dat(i),win);
                X = sprintf('Preprocessing : %d / %d',i,l);
                disp(X)
            end

            threshold_begin = app.BeginThresholdEditField.Value;
            t_b = app.Timebetween2lasershotsecondEditField.Value;
            min_threshold = app.LoudthresholdEditField.Value;

            tolerance = app.FusionpercentSlider.Value;

            intern_trig = app.InternalTriggeringSwitch.Value;
            if intern_trig(1:2) == 'On'
                intern_flag = 1;
            else
                intern_flag = 0;
            end

            bio_dat = bio_dat2;

            bio_dat(1).comment = [threshold_begin, min_threshold, t_b,tolerance,intern_flag ];

            save('bio_dat.mat', 'bio_dat')
            folder_name = 'mat files';
            chemin  = choix_chemin(folder_name,nom);
            movefile('bio_dat.mat',chemin);
        end

        % Value changed function: MapfilenameEditField
        function MapfilenameEditFieldValueChanged(app, event)
            nom_map = app.MapfilenameEditField.Value;
            limits = app.MasslimitsminimumEditField.Value;

            limi = round(limits(1));
            mass_str = num2str(limi);
            nom = strcat( nom_map,'_',mass_str);%,'.biomap');

            app.NameofthegeneratedbiomapfileEditField.Value = nom;
        end

        % Value changed function: MasslimitsminimumEditField
        function MasslimitsminimumEditFieldValueChanged(app, event)
            limits = app.MasslimitsminimumEditField.Value;
            nom_map = app.MapfilenameEditField.Value;

            limi = round(limits(1));
            mass_str = num2str(limi);
            nom = strcat( nom_map,'_',mass_str);%,'.biomap');

            dec = 0.25; % ussual difference between the mass limits

            app.maximumEditField.Value = limits + 0.25;
            app.NameofthegeneratedbiomapfileEditField.Value = nom;
        end

        % Button pushed function: LaunchReconstructionButton
        function LaunchReconstructionButtonPushed(app, event)

            path(path,'to add')
            path(path,'map files')
            path(path,'mat files')
            %path(path,'code')
            path(path,'code/code_for_recording')
            path(path,'code/code_for_reconstruction')

            nom_mat_r = app.MatfilenameEditField.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat);

            nom_map_r = app.MapfilenameEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            nom_r = app.NameofthegeneratedbiomapfileEditField.Value;
            nom = strcat(nom_r,'.biomap');

            % seuil = 6; % if the threshold is recorded in the map
            seuil = -1;

            l_min = app.MasslimitsminimumEditField.Value;
            l_max = app.maximumEditField.Value;


            limits = [l_min l_max];

            clearvars carte_x
            clearvars carte_y
            clearvars carte_z

            carte_x = carte.x;
            carte_y = carte.y;
            carte_z = carte.z;
            if isfield(carte,'time')
                carte_time = carte.time;
            end

            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));
            fprintf('(%s) - Starting to build the intensity matrix\n', datestr(now,'HH:MM:SS'));

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
                % [bio_dat ,time,g] = peak_detection8_internal(mzXMLStruct,threshold_begin,t_b,min_threshold); % take only the usefull informations
            else
                loud_flag = 0;
                % [bio_dat ,time,g] = peak_detection8(mzXMLStruct,threshold_begin,t_b,min_threshold); % take only the usefull informations
            end

            if isfield(carte,'time')
                % [ bio_ind ,bio_map ] = mzXML_on_map_norm12(bio_dat,carte_z,limits,carte_time); % put the information on the map
                time_flag = 1;
            else
                % [ bio_ind ,bio_map ] = mzXML_on_map_norm8_4(bio_dat,carte_z,limits,seuil); % put the information on the map
                time_flag = 0;
                carte_time = 0;
            end

            [ bio_ind, bio_num, bio_map, deiso_tab] = mzXML_on_map_norm17(bio_dat,carte_z,limits,carte_time,time_flag,loud_flag);
            %[ bio_ind ,bio_map ] = mzXML_on_map_norm14(bio_dat,carte_z,limits,carte_time,time_flag); % put the information on the map

            %   bio_ind_debug(bio_ind,bio_map,bio_dat);

            [ carte_x,carte_y,carte_z,bio_map  ] = fix_border_2(carte_x,carte_y,carte_z,bio_map,bio_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
            figure()
            s = surf(carte_x,carte_y,carte_z,bio_map);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['biometric map, mass limits : ', num2str(limits(1)),'  ',num2str(limits(2))]);

            record_BIOmap2(carte_x,carte_y,carte_z,bio_map,nom,limits)
        end

        % Button pushed function: CreateRGBmapButton
        function CreateRGBmapButtonPushed(app, event)
            nom_biomap = app.BiomapfilenameEditField.Value;
            m1 = app.Mass01EditField.Value;
            m2 = app.Mass02EditField.Value;
            m3 = app.Mass03EditField.Value;

            m_1 = num2str(m1);
            m_2 = num2str(m2);
            m_3 = num2str(m3);

            nom_1 = strcat( nom_biomap,'_',m_1,'.biomap');
            nom_2 = strcat( nom_biomap,'_',m_2,'.biomap');
            nom_3 = strcat( nom_biomap,'_',m_3,'.biomap');

            out = load(nom_1);
            out2 = load(nom_2);
            out3 = load(nom_3);

            file_name_r = app.NameofthegeneratedRGBmapEditField.Value;
            file_name = strcat(file_name_r,'.rgbmap');

            %% CODE

            si = size(out);

            %%% Récupération des dimensions %%%
            si_1 = out(1,1);
            si_2 = out(1,2);
            import_limits(1) = out(1,3);
            import_limits(2) = out(1,4);

            i_1 = (import_limits(1)+import_limits(2))/2;

            i_l_2(1) = out2(1,3);
            i_l_2(2) = out2(1,4);

            i_2 = ( i_l_2(1) + i_l_2(2) ) /2;

            i_l_3(1) = out3(1,3);
            i_l_3(2) = out3(1,4);

            i_3 = ( i_l_3(1) + i_l_3(2) ) /2;


            u = 1;

            for i = 1 : si_1
                for j = 1 : si_2
                    u = u + 1;
                    carte_x(i,j) = out(u,1);
                    carte_y(i,j) = out(u,2);
                    carte_z(i,j) = out(u,3);
                    bio_map1(i,j) = out(u,4);
                    bio_map2(i,j) = out2(u,4);
                    bio_map3(i,j) = out3(u,4);
                end
            end

            m1 = max(max(bio_map1));
            m2 = max(max(bio_map2));
            m3 = max(max(bio_map3));

            % mmax = max( max(m1,m2), max(m2,m3) ); %method 1
            %
            % bio_map1_r = bio_map1/mmax;
            % bio_map2_g = bio_map2/mmax;
            % bio_map3_b = bio_map3/mmax; %

            % normalisation des valeurs
            bio_map1_r = bio_map1/m1; %method 2
            bio_map2_g = bio_map2/m2;
            bio_map3_b = bio_map3/m3; %

            bio_map(:,:,1) = bio_map1_r ;
            bio_map(:,:,2) = bio_map2_g ;
            bio_map(:,:,3) = bio_map3_b ;


            figure()
            s = surf(carte_x,carte_y,carte_z,bio_map);
            s.FaceAlpha=0.9; % niveau de tranparence
            s.FaceColor = 'flat'; % set color interpolqtion
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            title(['biometric map, mass limits : ', num2str(i_1),'  ',num2str(i_2),' ',num2str(i_3)]);
            axis equal

            % figure()
            % s = surf(carte_x,carte_y,carte_z,bio_map);
            % s.FaceAlpha=0.9; % niveau de tranparence
            % s.FaceColor = 'interp'; % set color interpolqtion
            % s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            % title(['biometric map, mass limits : ', num2str(i_1),'  ',num2str(i_2),' ',num2str(i_3)]);
            % axis equal

            data.b1 = bio_map(:,:,1);
            data.b2 = bio_map(:,:,2);
            data.b3 = bio_map(:,:,3);
            data.x = carte_x;
            data.y= carte_y;
            data.z = carte_z;
            data.i1 = i_1;
            data.i2 = i_2;
            data.i3 = i_3;


            record_rgb_map(data,file_name);

        end

        % Button pushed function: LaunchMapPCAAnalysisButton
        function LaunchMapPCAAnalysisButtonPushed(app, event)
            path(path,'BIOmap files')
            path(path,'code_for_recording')

            a = app.MassesEditField.Value;
            aConverted=str2double(strsplit(a,','));
            mass = aConverted';

            name_int_r = app.BiomapfilenameEditField_2.Value;
            name_int = strcat(name_int_r,'_');


            l = length(mass);
            mass_char = num2str(mass);
            for i = 1 : l % recuperation des informations

                B = convertCharsToStrings(mass_char(i,:));
                nom = strcat(name_int,B,'.biomap');
                [carte_x,carte_y,carte_z,bio_map] = load_BIOmap_fcn_pca(nom);
                bio_map_tab(:,:,i) = bio_map;

            end

            si = size(carte_x);

            ind = 0;
            for x = 1 : si(1) % mise en format ligne de longueur n*m d'un tableau de dimension n par m
                for y = 1 : si(2)

                    ind = ind + 1;

                    pca_ingredients(ind,:) = [bio_map_tab(x,y,1:l)];

                end
            end

            [vect_propres,scores,val_propres,t2] = pca(pca_ingredients);

            % Cette commande permet de réaliser le cercle des corrélations variables
            % sur les deux axes principaux.

            figure('Name','Cercle des corrélations','NumberTitle','off');
            plot(vect_propres(:,1),vect_propres(:,2),'.');
            text(vect_propres(:,1),vect_propres(:,2),mass_char);
            hold on
            [x,y,z] = cylinder(1,200);
            plot(x(1,:),y(1,:))
            hold on
            line([-1 1],[0 0])
            line([0 0],[-1 1])
            axis equal


            % Cette commande permet de réaliser la carte factorielle des individus sur
            % les deux axes principaux.
            Individus_num = [1 : si(1)*si(2)]';
            Individus = num2str(Individus_num);
            %Individus = Individus';

            bande_point = [ 100: 200]';

            figure('Name','Carte factorielle des différents points','NumberTitle','off');
            plot(scores(bande_point,1),scores(bande_point,2),'r+');
            text(scores(bande_point,1),scores(bande_point,2),Individus(bande_point,:));
            a  = axis;
            xl = a(1);xu = a(2);yl = a(3);yu = a(4);
            xlabel('1ère Composante Principale')
            ylabel('2nde Composante Principale')
            hold on
            line([xl xu],[0 0])
            line([0 0],[yl yu])

            ind = 0;
            for x = 1 : si(1)
                for y = 1 : si(2)

                    ind = ind + 1;

                    %pca_ingredients(ind,:) = [bio_map_tab(x,y,1:l)];
                    bio_map_score(x,y) = scores(ind,1);
                    bio_map_score_g(x,y) = scores(ind,2);
                    bio_map_score_b(x,y) = scores(ind,3);

                end
            end

            %% For displaying
            figure('Name','Principal Component Map')
            s = surf(carte_x,carte_y,carte_z,bio_map_score);
            s.FaceAlpha=0.9; % niveau de tranparence
            s.FaceColor = 'flat'; % set color interpolqtion
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            %title(['biometric map, mass limits : ', num2str(import_limits(1)),'  ',num2str(import_limits(2))]);
            axis equal
            %grid off
            axis off

            %
            % bio_map(:,:,1) = bio_map_score ;
            % bio_map(:,:,2) = bio_map_score_g  ;
            % bio_map(:,:,3) = bio_map_score_b  ;
            %
            % figure('Name','3 Principals Component')
            % s = surf(carte_x,carte_y,carte_z,bio_map);
            % s.FaceAlpha=0.9; % niveau de tranparence
            % s.FaceColor = 'flat'; % set color interpolqtion
            % s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            % %title(['biometric map, mass limits : ', num2str(i_1),'  ',num2str(i_2),' ',num2str(i_3)]);
            % axis equal


            m1 = max(max(bio_map_score));
            m2 = max(max(bio_map_score_g));
            m3 = max(max(bio_map_score_b));
            % normalisation des valeurs
            bio_map1_r = bio_map_score/m1; %method 2
            bio_map2_g = bio_map_score_g/m2;
            bio_map3_b = bio_map_score_b/m3; %

            bio_map(:,:,1) = bio_map1_r ;
            bio_map(:,:,2) = bio_map2_g ;
            bio_map(:,:,3) = bio_map3_b ;

            figure('Name','3 Principals Component RGB map, with normalisation')
            s = surf(carte_x,carte_y,carte_z,bio_map);
            s.FaceAlpha=0.9; % niveau de tranparence
            s.FaceColor = 'flat'; % set color interpolqtion
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            %title(['biometric map, mass limits : ', num2str(i_1),'  ',num2str(i_2),' ',num2str(i_3)]);
            axis equal
        end

        % Value changed function: MatfilenameEditField
        function MatfilenameEditFieldValueChanged(app, event)
            value = app.MatfilenameEditField.Value;
            app.MapfilenameEditField. Value = value;
        end

        % Value changing function: MapfilenameEditField
        function MapfilenameEditFieldValueChanging(app, event)

        end

        % Button pushed function: LaunchmatPCAAnalysisButton
        function LaunchmatPCAAnalysisButtonPushed(app, event)

            path(path,'code/code_for_analysing')
            path(path,'mat files');

            path(path,'to add');


            b1 = app.MassbandminEditField.Value;
            b2 = app.maxEditField.Value;
            band = [b1 b2];
            nb_point = app.NumberofpointonthecorrelationcirclemaximummzEditField.Value;
            win = app.BiningwindowmassEditField.Value;
            nom_r = app.MatfilenameEditField_2.Value;
            nom = strcat(nom_r,'.mat');
            load(nom);
            tlp = app.ShowalsoallmassesonanothercorrelationcircleCheckBox.Value;
            mat_pca_circle2(win,band,nb_point,bio_dat,tlp)


        end

        % Button pushed function: MainmassesonthemapButton
        function MainmassesonthemapButtonPushed(app, event)
            path(path,'code/code_for_analysing')
            path(path,'code/code_for_reconstruction')
            path(path,'map files')
            path(path,'mat files')

            mat_name_r = app.MatfilenameEditField.Value;
            mat_name = strcat(mat_name_r,'.mat');
            load(mat_name);

            map_name_r = app.MapfilenameEditField.Value;
            map_name = strcat(map_name_r,'.map');
            out = load(map_name);

            seuil = -1;
            %seuil = 6; % for tests

            win = app.BiningwindowEditField.Value;
            l1 = app.MassbandminEditField_2.Value;
            l2 = app.maxEditField_2.Value;
            limits= [l1 l2];

            %% CODE
            %%% Récupération des dimensions %%%
            si = size(out);
            si_1 = out(1,1);
            si_2 = out(1,2);

            u = 1;

            clearvars carte_x
            clearvars carte_y
            clearvars carte_z

            for i = 1 : si_1
                for j = 1 : si_2
                    u = u + 1;
                    carte_x(i,j) = out(u,1);
                    carte_y(i,j) = out(u,2);
                    carte_z(i,j) = out(u,3);
                end
            end
            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));


            l = length(bio_dat);
            for i = 1 : l
                peak_tab4 = bining_2(bio_dat(i).peaks.mz,win,limits);
                bio_dat(i).peaks.mz = peak_tab4;
                a = i/l
            end


            fprintf('(%s) - Starting to build the maximum matrix\n', datestr(now,'HH:MM:SS'));
            [ max_ind ,max_map ] = max_on_map(bio_dat,carte_z,seuil,limits); % put the information on the map

            [ carte_x,carte_y,carte_z,max_map  ] = add_border(carte_x,carte_y,carte_z,max_map);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));

            %max_map = max_map - limits(1);

            max_map2 = max_map - limits(1);

            figure()
            %mesh(carte_x,carte_y,carte_z,bio_map);
            s = surf(carte_x,carte_y,carte_z,max_map2);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['maximum map']);

            mass_max_list = unique(max_map);
            for i = 1 : length(mass_max_list)
                ind_v_max = find(max_map == mass_max_list(i));
                tab_nb_max(i) = length(ind_v_max);
            end
            tab_nb_max = tab_nb_max';
            tab_t(:,1) = mass_max_list;
            tab_t(:,2) = tab_nb_max;

            tab_t(1,:) = [];

            tab_t( isnan(tab_t(:,1)) ,:) = []; % supprime les valeurs nulles (NaN)
            max_tri = sort(tab_t(:,2), 'descend');

            nb_point = 3;

            %for i = 1 : nb_point
            mass_max_tab = 0;
            i = 0;
            while length(mass_max_tab) < nb_point % récupère les 1nb_point masses les plus ionisés
                i = i+1
                max_v = max_tri(i);
                loca_ion = find (tab_t(:,2) == max_v);
                if length(loca_ion) ~= 1
                    loca_ion = max(loca_ion);
                end
                mass_max_tab_r(i) = tab_t(loca_ion,1);
                mass_max_tab  = unique(mass_max_tab_r);
            end

            max_map_3 = find_max_zone(max_map,mass_max_tab);

            figure()
            s = surf(carte_x,carte_y,carte_z,max_map_3);
            s.FaceAlpha=0.9; % niveau de tranparence
            s.FaceColor = 'flat'; % set color interpolqtion
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            title(['Mass max zone map, mass (RGB) : R : ', num2str(mass_max_tab(1)),' G : ', num2str(mass_max_tab(2)),' B : ', num2str(mass_max_tab(3))]);
            axis equal
        end

        % Button pushed function: LaunchTimeEstimationButton
        function LaunchTimeEstimationButtonPushed(app, event)
            % global bio;
            global mzXML;

            path(path,'to add')

            path(path,'mzXML files')
            path(path,'code/code_for_preprocessing')
            path(path,'code/code_for_recording')

            %             fprintf('(%s) - Loading the mzXML file\n', datestr(now,'HH:MM:SS'));
            %             mzxml_r = app.mzXMLfilenameEditField.Value;
            %             nom_mzxml = strcat(mzxml_r,'.mzXML');
            %             mzXMLStruct = mzxmlread(nom_mzxml);

            threshold_begin = app.BeginThresholdEditField.Value;
            t_b = app.Timebetween2lasershotsecondEditField.Value;
            min_threshold = app.LoudthresholdEditField.Value;

            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));
            %             [bio_dat ,time,g] = load_mzXML_p4(mzXMLStruct,threshold_begin,t_b); % take only the usefull informations
            time = time_from_peaks3(mzXML.Struct,threshold_begin,min_threshold); % take only the usefull informations

            time_str = num2str(time);
            time_str2 = strcat(time_str, ' seconds');
            app.TimeValue.Text = time_str2;

        end

        % Button pushed function: ExtractOneSpectraButton
        function ExtractOneSpectraButtonPushed(app, event)
            path(path,'map files')
            path(path,'mat files')
            %path(path,'code')
            path(path,'code/code_for_recording')
            path(path,'code/code_for_reconstruction')
            path(path,'to add')

            nom_mat_r = app.MatfilenameEditField.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat)

            nom_map_r = app.MapfilenameEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            nom_r = app.NameofthegeneratedbiomapfileEditField.Value;
            nom = strcat(nom_r,'.biomap');

            % seuil = 6; % if the threshold is recorded in the map
            seuil = -1;

            l_min = app.MasslimitsminimumEditField.Value;
            l_max = app.maximumEditField.Value;

            limits = [l_min l_max];

            %             nom = 'slice_breastcancer.map';
            %             carte = load_map4_fcn(nom);
            %
            %             load('slice_breastcancer02.mat');
            %
            %             limits = [885 886];

            extract_one_spectra2(bio_dat,carte,limits)
        end

        % Button pushed function: ExtractSpectraZoneButton
        function ExtractSpectraZoneButtonPushed(app, event)
            path(path,'map files')
            path(path,'mat files')
            %path(path,'code')
            path(path,'code/code_for_recording')
            path(path,'code/code_for_analysing')
            path(path,'code/code_for_reconstruction')
            path(path,'to add')

            nom_mat_r = app.MatfilenameEditField.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat)

            nom_map_r = app.MapfilenameEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            nom_r = app.NameofthegeneratedbiomapfileEditField.Value;
            nom = strcat(nom_r,'.biomap');

            % seuil = 6; % if the threshold is recorded in the map
            seuil = -1;

            l_min = app.MasslimitsminimumEditField.Value;
            l_max = app.maximumEditField.Value;

            limits = [l_min l_max];

            extract_spectra_zone3(bio_dat,carte,limits)

        end

        % Button pushed function: PlotChromatogramButton
        function PlotChromatogramButtonPushed(app, event)
            path(path,'to add')

            global mzXML;

            path(path,'mzXML files')
            path(path,'code/code_for_preprocessing')
            path(path,'code/code_for_recording')

            %             fprintf('(%s) - Loading the mzXML file\n', datestr(now,'HH:MM:SS'));
            %             mzxml_r = app.mzXMLfilenameEditField.Value;
            %             nom_mzxml = strcat(mzxml_r,'.mzXML');
            %             mzXMLStruct = mzxmlread(nom_mzxml);

            figure()
            plot_chromatogram(mzXML.Struct)
        end

        % Value changed function: MappingStepmmEditField
        function MappingStepmmEditFieldValueChanged(app, event)
            path(path,'code/code_for_mapping')


            size_x = app.ZonesizeXaxismmEditField.Value;
            step_size = app.MappingStepmmEditField.Value;
            size_y = app.ZonesizeYaxismmEditField.Value;
            nb_shot = app.NumberoflasershotburstmodeEditField.Value;
            t_b = app.TimebetweentwolasershotsecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,t_b,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: ZonesizeXaxismmEditField
        function ZonesizeXaxismmEditFieldValueChanged(app, event)
            path(path,'code/code_for_mapping')


            size_x = app.ZonesizeXaxismmEditField.Value;
            step_size = app.MappingStepmmEditField.Value;
            size_y = app.ZonesizeYaxismmEditField.Value;
            nb_shot = app.NumberoflasershotburstmodeEditField.Value;
            t_b = app.TimebetweentwolasershotsecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,t_b,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: ZonesizeYaxismmEditField
        function ZonesizeYaxismmEditFieldValueChanged(app, event)
            path(path,'code/code_for_mapping')


            size_x = app.ZonesizeXaxismmEditField.Value;
            step_size = app.MappingStepmmEditField.Value;
            size_y = app.ZonesizeYaxismmEditField.Value;
            nb_shot = app.NumberoflasershotburstmodeEditField.Value;
            t_b = app.TimebetweentwolasershotsecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,t_b,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: TimebetweentwolasershotsecondEditField
        function TimebetweentwolasershotsecondEditFieldValueChanged(app, event)
            path(path,'code/code_for_mapping')


            size_x = app.ZonesizeXaxismmEditField.Value;
            step_size = app.MappingStepmmEditField.Value;
            size_y = app.ZonesizeYaxismmEditField.Value;
            nb_shot = app.NumberoflasershotburstmodeEditField.Value;
            t_b = app.TimebetweentwolasershotsecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,t_b,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: NumberoflasershotburstmodeEditField
        function NumberoflasershotburstmodeEditFieldValueChanged(app, event)
            path(path,'code/code_for_mapping')


            size_x = app.ZonesizeXaxismmEditField.Value;
            step_size = app.MappingStepmmEditField.Value;
            size_y = app.ZonesizeYaxismmEditField.Value;
            nb_shot = app.NumberoflasershotburstmodeEditField.Value;
            t_b = app.TimebetweentwolasershotsecondEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,t_b,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Button pushed function: MapFromSpectraButton
        function MapFromSpectraButtonPushed(app, event)
            path(path,'code/code_for_preprocessing')
            path(path,'code/code_for_recording')
            path(path,'code/code_for_reconstruction')

            path(path,'mat files')
            path(path,'map files')

            path(path,'to add')

            valmin = app.valmin;
            valmax = app.valmax;

            global all_peaks;
            global old_name;

            nom_mat_r = app.MatfilenameEditField.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat) % génère la variable bio_dat

            nom_map_r = app.MapfilenameEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            % out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            %             disp(nom_mat_r)
            %             disp(old_name)

            if nom_mat_r == old_name
                compute_flag = 1;
            else
                compute_flag = 0;
            end

            all_peaks = plot_all_spectra(bio_dat,compute_flag,all_peaks,valmin,valmax);
            map_from_spectra(bio_dat,carte);

            old_name = nom_mat_r;
        end

        % Button pushed function: LaunchRectificationButton
        function LaunchRectificationButtonPushed(app, event)
            path(path,'map files')
            path(path,'code/code_for_reconstruction')
            path(path,'code/code_for_analysing')
            path(path,'code/code_for_recording')


            nom_map_r = app.NameofthemapEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            nom_new_map_r = app.NameofthecorrectedmapEditField.Value;
            nom_map_new = strcat(nom_new_map_r,'.map');

            seuil_min = app.MinimumthresholdmmEditField.Value;
            seuil_max = app.MaximumthresholdmmEditField.Value;

            carte_z = carte.z;

            [carte_z2, nb_err ]= map_rectification(carte_z,seuil_min,seuil_max);

            carte_f = click_loop_multi(carte,carte_z2,seuil_min,seuil_max);

            seuil = 0;

            if isfield(carte,'time')
                time_diff_med = record_map4(carte.x,carte.y,carte_f,nom_map_new,seuil,carte.time);
            else
                record_map2(carte.x,carte.y,carte_f,nom_map_new,seuil);
            end


            %     close all; % fermer au fur et à mesure plutot


        end

        % Button pushed function: LaunchInterpolationButton
        function LaunchInterpolationButtonPushed(app, event)
            path(path,'map files')
            path(path,'code/code_for_analysing')
            path(path,'code/code_for_recording')

            nom_map_r = app.NameofthemapEditField_22.Value;
            nom_map = strcat(nom_map_r,'.map');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name
            carte = load_map4_fcn(nom_map);

            nom_new_map_r = app.NameoftheinterpolatedmapEditField.Value;
            nom_map_new = strcat(nom_new_map_r,'.map');

            scan_pre = app.PointMultiplierEditField.Value ;

            [carte_o, carte_x_o, carte_y_o, carte_time_o] = polynomial_interpolation_2(carte,scan_pre);

            seuil = 0;

            if isfield(carte,'time')
                time_diff_med = record_map4(carte_x_o,carte_y_o,carte_o,nom_map_new,seuil,carte_time_o);
            else
                record_map2(carte.x,carte.y,carte_f,nom_map_new,seuil);
            end


        end

        % Button pushed function: LaunchBiomapInterpolationButton
        function LaunchBiomapInterpolationButtonPushed(app, event)
            path(path,'map files')
            path(path,'code/code_for_analysing')
            path(path,'code/code_for_recording')
            path(path,'to add')


            nom_map_r = app.NameofthebiomapEditField.Value;
            nom_map = strcat(nom_map_r,'.biomap');
            %out = load(nom_map); % load topographical map. Remove any dot in the file name

            [carte,bio_map,limits] = load_biomap_fcn(nom_map);

            nom_new_map_r = app.NameoftheinterpolatedbiomapEditField.Value;
            nom_map_new = strcat(nom_new_map_r,'.biomap');

            scan_pre = app.PointMultiplierEditField_2.Value ;

            si = size(carte.x)-1;

            carte2.x = carte.x(1:si(1),1:si(2));
            carte2.y = carte.y(1:si(1),1:si(2));
            carte2.z = carte.z(1:si(1),1:si(2));
            bio_map2 = bio_map(1:si(1),1:si(2));


            [carte_o, carte_x_o, carte_y_o, carte_time_o, bio_map_o] = polynomial_interpolation_bio3(carte2,scan_pre,bio_map2);

            %           [ carte_x_o,carte_y_o,carte_o,bio_map_o  ] = fix_border_2(carte_x_o,carte_y_o,carte_o,bio_map_o,bio_ind);

            seuil = 0;

            if isfield(carte,'time')
                record_BIOmap_time(carte_x_o,carte_y_o,carte_o,bio_map_o,nom_map_new,limits,carte_time_o,time_diff_med)
            else
                record_BIOmap2(carte_x_o,carte_y_o,carte_o,bio_map_o,nom_map_new,limits)
            end

            figure()
            s = surf(carte_x_o,carte_y_o,carte_o,bio_map_o);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['biometric map, mass limits : ', num2str(limits(1)),'  ',num2str(limits(2))]);

        end

        % Button pushed function: LoadBiomapButton
        function LoadBiomapButtonPushed(app, event)
            nom_r = app.NamebiomapfileEditField.Value;
            nom = strcat(nom_r,'.biomap');

            [carte,bio_map,import_limits] = load_biomap_fcn(nom);

            figure()
            s = surf(carte.x,carte.y,carte.z,bio_map);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['biometric map, mass limits : ', num2str(limits(1)),'  ',num2str(limits(2))]);
        end

        % Button pushed function: LaunchLDAReconstructionButton
        function LaunchLDAReconstructionButtonPushed(app, event)
            path(path,'code/code_for_reconstruction')
            path(path,'code/code_for_recording')
            path(path,'to add')
            path(path,'map files')
            path(path,'mat files')
            path(path,'csv files')

            nom_map_r = app.NameofthemapfileEditField.Value;
            nom_map = strcat(nom_map_r,'.map');
            carte = load_map4_fcn(nom_map);

            nom_mat_r = app.NameofthematfileEditField.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat);

            nom_csv_r = app.NameofthecsvfileEditField.Value;
            nom_csv = strcat(nom_csv_r,'.csv');
            T = readtable(nom_csv,'NumHeaderLines',5);  % skips the first three rows of data

            scan.num = T.StartScan;
            scan.num_end = T.EndScan;
            scan.class = T.ClassName;
            scan.prob = T.Probability;

            class(1) = string(app.Class01EditField.Value);
            class(2) = app.Class02EditField.Value;
            class(3) = app.Class03EditField.Value;
            class(4) = app.Class04EditField.Value;
            class(5) = app.Class05EditField.Value;
            class(6) = app.Class06EditField.Value;

            couleur_n(1) = string(app.ColorDropDown.Value);
            couleur_n(2) = app.ColorDropDown_2.Value;
            couleur_n(3) = app.ColorDropDown_3.Value;
            couleur_n(4) = app.ColorDropDown_4.Value;
            couleur_n(5) = app.ColorDropDown_5.Value;
            couleur_n(6) = app.ColorDropDown_6.Value;

            for i = 1 : length(couleur_n)
                if couleur_n(i) == "Red"
                    color(i,:) = [0.9 0 0];
                elseif couleur_n(i) == "Green"
                    color(i,:) = [0 0.8 0];
                elseif couleur_n(i) == "Blue"
                    color(i,:) = [0 0 1];
                elseif couleur_n(i) == "Orange"
                    color(i,:) = [1 0.5 0];
                elseif couleur_n(i) == "Yellow"
                    color(i,:) = [0.9 0.9 0];
                elseif couleur_n(i) == "Violet"
                    color(i,:) = [0.9 0 0.9];
                elseif couleur_n(i) == "None"
                    color(i,:) = [0 0 0];
                end
            end

            if isfield(carte,'time')
                time_flag = 1;
                carte_time = carte.time;
            else
                time_flag = 0;
                carte_time = 0;
            end

            limits = [885 886]; % totalement arbitraire, ces valeurs ne sont pas utiles pour la suite

            [ bio_ind, bio_num, bio_map , deiso_tab] = mzXML_on_map_norm16(bio_dat,carte.z,limits,carte_time,time_flag);
            %  [ bio_ind, bio_num, bio_map ] = mzXML_on_map_norm15(bio_dat,carte.z,limits,carte_time,time_flag);

            si = size(carte.z);
            bio_map_class = zeros(si(1),si(2),3);

            back_color = app.BackgroundColorDropDown.Value;
            if back_color == "White"
                bio_map_class(:,:,:) = 1;
            elseif back_color == "Grey"
                bio_map_class(:,:,:) = 0.5;
            end

            bio_map_class = bio_class_builder3(scan,bio_num,class,color,deiso_tab,bio_ind,bio_map_class);

            % bio_map_class = bio_class_builder(scan,bio_num,class,color,deiso_tab,bio_ind);

            %             for i = 1 : length(scan.num)
            %                 [x_i y_i] = find(scan.num(i) == bio_num);
            %                 if ~isempty(x_i)
            %                     c_ind = find(string(scan.class(i)) == class);
            %                     bio_map_class(x_i,y_i,:) = color(c_ind,:);
            %                 end
            %             end

            [ carte.x,carte.y,carte.z,bio_map_class  ] = fix_border_3(carte.x,carte.y,carte.z,bio_map_class,bio_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
            figure()
            s = surf(carte.x,carte.y,carte.z,bio_map_class);
            s.FaceAlpha = 0.9; % niveau de transparence
            s.FaceColor = 'flat'; % set color interpolation or not
            s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
            axis equal
            title(['Biometric Class Map']);
            legend(class);


        end

        % Button pushed function: LoadmzXMLButton
        function LoadmzXMLButtonPushed(app, event)
            path(path,'mzXML files')

            disp("Chargement du fichier mzXML en cours")

            global mzXML

            mzxml_r = app.mzXMLfilenameEditField.Value;
            nom_mzxml = strcat(mzxml_r,'.mzXML');
            mzXML.Struct = mzxmlread(nom_mzxml);

            disp("Chargement du fichier mzXML terminé ")

        end

        % Button pushed function: LaunchExtractionButton
        function LaunchExtractionButtonPushed(app, event)
            path(path,'mat files')
            path(path,'code/code_for_recording')

            nom_mat_r = app.InputMatfilenameEditField_scils.Value;
            nom_mat = strcat(nom_mat_r,'.mat');
            load(nom_mat);

            nom_txt_r = app.OutputnameofthecreatedtxtfileEditField.Value;
            nom_txt = strcat(nom_txt_r,'.txt');

            folder_name = 'txt files';

            nom  = choix_chemin(folder_name,nom_txt)

            fid = fopen(nom,'wt');
            fprintf(fid, 'mzML=true\n');
            fprintf(fid, 'zlib=false\n');
            fprintf(fid, 'filter="scanNumber');

            l = length(bio_dat);

            for i = 1 : l
                num = abs(bio_dat(i).num);
                fprintf(fid,' [%d,%d]',num,num);
            end
            fprintf(fid,'"');

            fclose(fid);

        end

        % Value changed function: InputMatfilenameEditField_scils
        function InputMatfilenameEditField_scilsValueChanged(app, event)
            value = app.InputMatfilenameEditField_scils.Value;
            app.OutputnameofthecreatedtxtfileEditField.Value = value;
        end

        % Button pushed function: ExportrawBioMapButton
        function ExportrawBioMapButtonPushed(app, event)
            path(path,'code/code_python')
            pyrunfile("PLY-Converter_Native.py")
        end

        % Callback function
        function ExportinterpolatedBioMaptoplyfileButtonPushed(app, event)
            path(path,'code/code_python')
            pyrunfile('3-PLY-Converter.py')
        end

        % Button pushed function: ExportandcoregisterBiomapButton
        function ExportandcoregisterBiomapButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('1-Biomap_to_PNG.py')
            pyrunfile('2-Picture_Manual_Registration.py')
            pyrunfile('3-PLY-Converter.py')
        end

        % Callback function
        function ExportBioMaptoPNGButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('1-Biomap_to_PNG.py')
        end

        % Value changed function: NameofthemapEditField
        function NameofthemapEditFieldValueChanged(app, event)
            value = app.NameofthemapEditField.Value;
            app.NameofthecorrectedmapEditField.Value = strcat(value,"_corrected");
        end

        % Callback function
        function MinEditField_2ValueChanged(app, event)
% Deprecated
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.valmax = app.MaxEditField.Value;

        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.valmin = app.MinEditField.Value;

        end

        % Button pushed function: DataImportationButton
        function DataImportationButtonPushed(app, event)
            %             path(path,'code/code_for_segmentation')

            global imzML;
            %clear imzML

            % Set the path to the imzMLConverter.jar file
            javaclasspath('code/code_java/imzMLConverter.jar');

            % Select imzML file
            [filename, path] = uigetfile('*.imzML');
            imzml_file = fullfile(path, filename);

            % Parse imzML file
            data = imzMLConverter.ImzMLHandler.parseimzML(imzml_file);

            % Get dimensions of data set
            nColumns = data.getWidth();
            nRows = data.getHeight();

            % Localize pixels containing data
            mask_ini = false(nColumns, nRows);
            for i = 1:nColumns
                for j = 1:nRows
                    mask_ini(i,j) = ~isempty(data.getSpectrum(i,j));
                end
            end

            % Get mz values

            spectrum = data.getSpectrum(1,1);
            class(spectrum)  % display the class of the spectrum variable
            %mzs = spectrum.getmzArray().getDoubleArray();  % square brackets instead of dot indexing
            mzs = spectrum.getmzArray();


            % Initialize data cube
            data_cube = zeros(nColumns, nRows, numel(mzs));

            % Populate data cube
            for i = 1:nColumns
                for j = 1:nRows
                    if mask_ini(i, j)
                        spectrum = data.getSpectrum(i, j);
                        data_cube(i, j, :) = spectrum.getIntensityArray();
                    end
                end
            end

            imzML.data_cube = data_cube;
            imzML.mzs = mzs;
            imzML.mask_ini = mask_ini;
            imzML.nColumns = nColumns;
            imzML.nRows = nRows;

            disp("Importation des donnes imzML OK")
        end

        % Button pushed function: DisplaySumofIntensitiesButton
        function DisplaySumofIntensitiesButtonPushed(app, event)
            global imzML;

            % Check if data cube exists
            if isempty(imzML.data_cube)
                disp('Error: Data cube is empty or not defined.');
                return;
            end

            % Compute sum of intensities across spectral channels
            tot = sum(imzML.data_cube, 3);

            % Display image
            imagesc(tot');colormap('jet');colorbar;axis image;

            % Create mask
            mask_corner = find(tot == 0);
            mask_corner = setdiff(1:(imzML.nColumns * imzML.nRows), mask_corner);
            imzML.mask_corner = mask_corner;
        end

        % Button pushed function: DataReductionButton
        function DataReductionButtonPushed(app, event)
            %path(path,'code/code_for_segmentation')
            global imzML
            %% unfolded datacube
            all_spectra=reshape(imzML.data_cube,imzML.nColumns*imzML.nRows,numel(imzML.mzs));
            selected_spectra=all_spectra(imzML.mask_corner,:);
            %% Reduction
            [U,S,V]=svds(selected_spectra,5);

            imzML.selected_spectra = selected_spectra;
            imzML.U = U;
            disp("Reduction OK")
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            global imzML
            nb_cluster = app.ClusternumberEditField.Value;
            pool = parpool;                      % Invokes workers
            stream = RandStream('mlfg6331_64');  % Random number stream
            options = statset('UseParallel',1,'UseSubstreams',1,'Streams',stream);
            distance='cosine';

            for num_clusters = 2:nb_cluster
                [idx, C] = kmeans(imzML.U,num_clusters,'Replicates',10,'OnlinePhase','on','Display','final','MaxIter',200,'Distance',distance,'Options',options);
                varname = sprintf('idx_%d', num_clusters); % generates 'idx_2', 'idx_3', etc.
                imzML.(varname) = idx; % stores the result in 'imzML.idx_2', 'imzML.idx_3', etc.
                eval(sprintf('C_%d = C;', num_clusters));
            end
        end

        % Button pushed function: DisplayclusterButton
        function DisplayclusterButtonPushed(app, event)
            path(path,'code/code_for_segmentation')
            global imzML
            value = app.ClusterindexEditField.Value;

            % Check if idx variable exists
            varname = sprintf('idx_%d', value); % generates 'idx_4', etc.
            if ~isfield(imzML, varname)
                errordlg(sprintf('Cluster %d not found',value), 'Error');
                return;
            end

            % display clusters
            cluster = value;
            imzML.idx = imzML.(varname); % evaluates 'imzML.idx_4' and stores the result in 'imzML.idx'
            generate_cluster_map(imzML.nColumns, imzML.nRows, imzML.mask_corner,imzML.idx, cluster)
        end

        % Button pushed function: ExportwithbinningButton
        function ExportwithbinningButtonPushed(app, event)
            path(path, 'code')
            csv_mat = append(app.Input1EditField.Value,'.mat');
            csv_map = append(app.Input2EditField.Value, '.map');
            mass_min = app.MinMzEditField.Value
            mass_max = app.MaxMzEditField.Value
            win_size = app.BinningwindowsizeEditField.Value
            run('csv_extractor.m')
            disp('Successfully exported the binned data to a .csv file in the csv files folder.')
        end

        % Value changed function: Input1EditField
        function Input1EditFieldValueChanged(app, event)
            value = app.Input1EditField.Value;
            app.Input2EditField.Value = value;

        end

        % Value changed function: Input2EditField
        function Input2EditFieldValueChanged(app, event)
            value = app.Input2EditField.Value;
%             csv_map = append(app.Input2EditField.Value, '.map');
        end

        % Button pushed function: ExportcsvtoplyButton
        function ExportcsvtoplyButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('CSV_to_PLY.py')
        end

        % Button pushed function: NormalizecsvTICButton
        function NormalizecsvTICButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('CSV_TIC_norm.py')
        end

        % Button pushed function: NormalizecsvRMSButton
        function NormalizecsvRMSButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('CSV_RMS_norm.py')
            %Note: RMS normalization currently behaves as it should,
            %mathematically speaking, but should probably be coupled to
            %something else in order to provide meaningful results in MSI
        end

        % Button pushed function: ExportcsvtopngButton
        function ExportcsvtopngButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile("CSV_to_PNG.py")
        end

        % Button pushed function: CoregistertwoimagesButton
        function CoregistertwoimagesButtonPushed(app, event)
            path(path, 'code/code_python')
            pyrunfile('2-Picture_Manual_Registration.py')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MappingTab = uitab(app.TabGroup);
            app.MappingTab.Title = 'Mapping';
            app.LaunchMappingButton = uibutton(app.MappingTab, 'push');
            app.LaunchMappingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMappingButtonPushed, true);
            app.LaunchMappingButton.Text = 'Launch Mapping';
            app.MappingChoiceButtonGroup = uibuttongroup(app.MappingTab);
            app.MappingChoiceButtonGroup.Title = 'Mapping Choice';
            app.OnlyTopographicButton = uiradiobutton(app.MappingChoiceButtonGroup);
            app.OnlyTopographicButton.Text = 'Only Topographic';
            app.WithMassSpectometryButton = uiradiobutton(app.MappingChoiceButtonGroup);
            app.WithMassSpectometryButton.Text = 'With Mass Spectometry';
            app.WithMassSpectometryButton.Value = true;
            app.LampOnlyTopo = uilamp(app.MappingChoiceButtonGroup);
            app.LampWithSpectro = uilamp(app.MappingChoiceButtonGroup);
            app.DMassSpectometryButton = uiradiobutton(app.MappingChoiceButtonGroup);
            app.DMassSpectometryButton.Text = '2D Mass Spectometry';
            app.LampWithSpectro_2D = uilamp(app.MappingChoiceButtonGroup);
            app.RobotConnexionB = uibutton(app.MappingTab, 'push');
            app.RobotConnexionB.ButtonPushedFcn = createCallbackFcn(app, @RobotConnexionBButtonPushed, true);
            app.RobotConnexionB.Text = 'Connect Robot';
            app.StateLampRobot = uilamp(app.MappingTab);
            app.ConnectLaserButton = uibutton(app.MappingTab, 'push');
            app.ConnectLaserButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectLaserButtonPushed, true);
            app.ConnectLaserButton.Text = 'Connect Laser';
            app.StateLampOpotek = uilamp(app.MappingTab);
            app.LaunchSensorCalibrationButton = uibutton(app.MappingTab, 'push');
            app.LaunchSensorCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchSensorCalibrationButtonPushed, true);
            app.LaunchSensorCalibrationButton.Text = 'Launch Sensor Calibration';
            app.BurstModeButton = uibutton(app.MappingTab, 'push');
            app.BurstModeButton.ButtonPushedFcn = createCallbackFcn(app, @BurstModeButtonPushed, true);
            app.BurstModeButton.Text = 'Burst Mode';
            app.GotoPositionButton = uibutton(app.MappingTab, 'push');
            app.GotoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPositionButtonPushed, true);
            app.GotoPositionButton.Text = 'Go to Position';
            app.TurnLampOnButton = uibutton(app.MappingTab, 'push');
            app.TurnLampOnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOnButtonPushed, true);
            app.TurnLampOnButton.Text = 'Turn Lamp On ';
            app.TurnLampOffButton = uibutton(app.MappingTab, 'push');
            app.TurnLampOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOffButtonPushed, true);
            app.TurnLampOffButton.Text = 'Turn Lamp Off';
            app.Lamp_LampONorOFF = uilamp(app.MappingTab);
            app.AffichageTemperature = uilabel(app.MappingTab);
            app.AffichageTemperature.Text = 'Temperature : NO_DATA';
            app.DisconnectRobotButton = uibutton(app.MappingTab, 'push');
            app.DisconnectRobotButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRobotButtonPushed, true);
            app.DisconnectRobotButton.Text = 'Disconnect Robot';
            app.AffichageState = uilabel(app.MappingTab);
            app.AffichageState.Text = 'State : NO_DATA';
            app.DisconnectButton = uibutton(app.MappingTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect ';
            app.GetTempButton = uibutton(app.MappingTab, 'push');
            app.GetTempButton.ButtonPushedFcn = createCallbackFcn(app, @GetTempButtonPushed, true);
            app.GetTempButton.Text = 'Get Temp';
            app.GOTOButton = uibutton(app.MappingTab, 'push');
            app.GOTOButton.ButtonPushedFcn = createCallbackFcn(app, @GOTOButtonPushed, true);
            app.GOTOButton.Text = 'GO TO';
            app.GetStateButton = uibutton(app.MappingTab, 'push');
            app.GetStateButton.ButtonPushedFcn = createCallbackFcn(app, @GetStateButtonPushed, true);
            app.GetStateButton.Text = 'Get State';
            app.LampVoltageSet = uilamp(app.MappingTab);
            app.ClearMapButton = uibutton(app.MappingTab, 'push');
            app.ClearMapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMapButtonPushed, true);
            app.ClearMapButton.Text = 'Clear Map';
            app.ButtonGroupMappingSpeed = uibuttongroup(app.MappingTab);
            app.ButtonGroupMappingSpeed.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupMappingSpeedSelectionChanged, true);
            app.WithrepositioningButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.WithrepositioningButton.Text = 'With repositioning';
            app.WithrepositioningButton.Value = true;
            app.FastMappingButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.FastMappingButton.Text = 'Fast Mapping';
            app.MapNameEditFieldLabel = uilabel(app.MappingTab);
            app.MapNameEditFieldLabel.Text = 'Map Name';
            app.MapNameEditField = uieditfield(app.MappingTab, 'text');
            app.MappingStepmmEditFieldLabel = uilabel(app.MappingTab);
            app.MappingStepmmEditFieldLabel.Text = 'Mapping Step  (mm)';
            app.MappingStepmmEditField = uieditfield(app.MappingTab, 'numeric');
            app.MappingStepmmEditField.ValueChangedFcn = createCallbackFcn(app, @MappingStepmmEditFieldValueChanged, true);
            app.MappingStepmmEditField.Value = 0.5;
            app.MaximumheightmmofthesampleEditFieldLabel = uilabel(app.MappingTab);
            app.MaximumheightmmofthesampleEditFieldLabel.Text = 'Maximum height (mm)  of the sample';
            app.MaximumheightmmofthesampleEditField = uieditfield(app.MappingTab, 'numeric');
            app.MaximumheightmmofthesampleEditField.Value = 10;
            app.ZonesizeXaxismmEditFieldLabel = uilabel(app.MappingTab);
            app.ZonesizeXaxismmEditFieldLabel.Text = 'Zone size X axis (mm)';
            app.ZonesizeXaxismmEditField = uieditfield(app.MappingTab, 'numeric');
            app.ZonesizeXaxismmEditField.ValueChangedFcn = createCallbackFcn(app, @ZonesizeXaxismmEditFieldValueChanged, true);
            app.ZonesizeXaxismmEditField.Value = 16;
            app.ZonesizeYaxismmEditFieldLabel = uilabel(app.MappingTab);
            app.ZonesizeYaxismmEditFieldLabel.Text = 'Zone size Y axis (mm)';
            app.ZonesizeYaxismmEditField = uieditfield(app.MappingTab, 'numeric');
            app.ZonesizeYaxismmEditField.ValueChangedFcn = createCallbackFcn(app, @ZonesizeYaxismmEditFieldValueChanged, true);
            app.ZonesizeYaxismmEditField.Value = 16;
            app.SurfaceoffestmmEditFieldLabel = uilabel(app.MappingTab);
            app.SurfaceoffestmmEditFieldLabel.Text = 'Surface offest (mm)';
            app.SurfaceoffestmmEditField = uieditfield(app.MappingTab, 'numeric');
            app.MicroprobefocaldistanceinZaxismmEditFieldLabel = uilabel(app.MappingTab);
            app.MicroprobefocaldistanceinZaxismmEditFieldLabel.Text = 'Microprobe focal distance in Z axis (mm) ';
            app.MicroprobefocaldistanceinZaxismmEditField = uieditfield(app.MappingTab, 'numeric');
            app.MicroprobefocaldistanceinZaxismmEditField.Value = 80;
            app.TimebetweentwolasershotsecondEditFieldLabel = uilabel(app.MappingTab);
            app.TimebetweentwolasershotsecondEditFieldLabel.Text = 'Time between two laser shot (second)';
            app.TimebetweentwolasershotsecondEditField = uieditfield(app.MappingTab, 'numeric');
            app.TimebetweentwolasershotsecondEditField.ValueChangedFcn = createCallbackFcn(app, @TimebetweentwolasershotsecondEditFieldValueChanged, true);
            app.TimebetweentwolasershotsecondEditField.Value = 3;
            app.NumberoflasershotburstmodeEditFieldLabel = uilabel(app.MappingTab);
            app.NumberoflasershotburstmodeEditFieldLabel.Text = 'Number of laser shot (burst mode)';
            app.NumberoflasershotburstmodeEditField = uieditfield(app.MappingTab, 'numeric');
            app.NumberoflasershotburstmodeEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflasershotburstmodeEditFieldValueChanged, true);
            app.NumberoflasershotburstmodeEditField.Value = 3;
            app.EnergylevelVSpinnerLabel = uilabel(app.MappingTab);
            app.EnergylevelVSpinnerLabel.Text = 'Energy level (V)';
            app.EnergylevelVSpinner = uispinner(app.MappingTab);
            app.EnergylevelVSpinner.ValueChangedFcn = createCallbackFcn(app, @EnergylevelVSpinnerValueChanged, true);
            app.EnergylevelVSpinner.Value = 682;
            app.FirstmappingpointXpositionmmEditFieldLabel = uilabel(app.MappingTab);
            app.FirstmappingpointXpositionmmEditFieldLabel.Text = 'First mapping point X position (mm)';
            app.FirstmappingpointXpositionmmEditField = uieditfield(app.MappingTab, 'numeric');
            app.FirstmappingpointXpositionmmEditField.Value = 150;
            app.CalibrationstateLabel = uilabel(app.MappingTab);
            app.CalibrationstateLabel.Text = 'Calibration state :';
            app.CalibrationstateLamp = uilamp(app.MappingTab);
            app.XPositionEditFieldLabel = uilabel(app.MappingTab);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.MappingTab, 'numeric');
            app.XPositionEditField.Value = 150;
            app.YPositionEditFieldLabel = uilabel(app.MappingTab);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.MappingTab, 'numeric');
            app.YPositionEditField.Value = 2;
            app.ZPositionEditFieldLabel = uilabel(app.MappingTab);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.MappingTab, 'numeric');
            app.ZPositionEditField.Value = 100;
            app.mapLabel = uilabel(app.MappingTab);
            app.mapLabel.Text = '.map';
            app.NoMappingLabel = uilabel(app.MappingTab);
            app.NoMappingLabel.Text = 'No Mapping';
            app.EstimationofmappingtimeLabel = uilabel(app.MappingTab);
            app.EstimationofmappingtimeLabel.Text = 'Estimation of mapping time : ';
            app.LaserDropDownLabel = uilabel(app.MappingTab);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.MappingTab);
            app.LaserDropDown.Items = {'Opotek IR Radiant', 'Opolette'};
            app.LaserDropDown.Value = 'Opotek IR Radiant';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.LaunchPeakDetectionButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchPeakDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchPeakDetectionButtonPushed, true);
            app.LaunchPeakDetectionButton.Text = 'Launch Peak Detection';
            app.mzXMLfilenameEditFieldLabel = uilabel(app.ProcessingTab);
            app.mzXMLfilenameEditFieldLabel.Text = 'mzXML file name :';
            app.mzXMLfilenameEditField = uieditfield(app.ProcessingTab, 'text');
            app.mzXMLfilenameEditField.Value = '20200724_SMSI_RATBAIN_TESTNEG2';
            app.NameofthegeneratedmatfileEditFieldLabel = uilabel(app.ProcessingTab);
            app.NameofthegeneratedmatfileEditFieldLabel.Text = 'Name of the generated mat file ';
            app.NameofthegeneratedmatfileEditField = uieditfield(app.ProcessingTab, 'text');
            app.mzXMLLabel = uilabel(app.ProcessingTab);
            app.mzXMLLabel.Text = '.mzXML';
            app.matLabel = uilabel(app.ProcessingTab);
            app.matLabel.Text = '.mat';
            app.BeginThresholdEditFieldLabel = uilabel(app.ProcessingTab);
            app.BeginThresholdEditFieldLabel.Text = 'Begin Threshold';
            app.BeginThresholdEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.BeginThresholdEditField.Value = 10000;
            app.BiningwindowsizeEditFieldLabel = uilabel(app.ProcessingTab);
            app.BiningwindowsizeEditFieldLabel.Text = 'Bining window size';
            app.BiningwindowsizeEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.BiningwindowsizeEditField.Value = 0.1;
            app.LaunchPreprocessingButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchPreprocessingButtonPushed, true);
            app.LaunchPreprocessingButton.Text = 'Launch Preprocessing';
            app.MatfilenameEditFieldLabel = uilabel(app.ProcessingTab);
            app.MatfilenameEditFieldLabel.Text = 'Mat file name';
            app.MatfilenameEditField = uieditfield(app.ProcessingTab, 'text');
            app.MatfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @MatfilenameEditFieldValueChanged, true);
            app.matLabel_2 = uilabel(app.ProcessingTab);
            app.matLabel_2.Text = '.mat';
            app.MasslimitsminimumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MasslimitsminimumEditFieldLabel.Text = 'Mass limits, minimum :';
            app.MasslimitsminimumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MasslimitsminimumEditField.ValueDisplayFormat = '%.3f';
            app.MasslimitsminimumEditField.ValueChangedFcn = createCallbackFcn(app, @MasslimitsminimumEditFieldValueChanged, true);
            app.MasslimitsminimumEditField.Value = 600;
            app.maximumEditFieldLabel = uilabel(app.ProcessingTab);
            app.maximumEditFieldLabel.Text = 'maximum';
            app.maximumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.maximumEditField.ValueDisplayFormat = '%.3f';
            app.maximumEditField.Value = 600.25;
            app.NameofthegeneratedbiomapfileEditFieldLabel = uilabel(app.ProcessingTab);
            app.NameofthegeneratedbiomapfileEditFieldLabel.Text = 'Name of the generated biomap file';
            app.NameofthegeneratedbiomapfileEditField = uieditfield(app.ProcessingTab, 'text');
            app.biomapLabel = uilabel(app.ProcessingTab);
            app.biomapLabel.Text = '.biomap';
            app.mapLabel_2 = uilabel(app.ProcessingTab);
            app.mapLabel_2.Text = '.map';
            app.MapfilenameEditFieldLabel = uilabel(app.ProcessingTab);
            app.MapfilenameEditFieldLabel.Text = 'Map file name';
            app.MapfilenameEditField = uieditfield(app.ProcessingTab, 'text');
            app.MapfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @MapfilenameEditFieldValueChanged, true);
            app.MapfilenameEditField.ValueChangingFcn = createCallbackFcn(app, @MapfilenameEditFieldValueChanging, true);
            app.LaunchReconstructionButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchReconstructionButtonPushed, true);
            app.LaunchReconstructionButton.Text = 'Launch Reconstruction';
            app.BiomapfilenameEditFieldLabel = uilabel(app.ProcessingTab);
            app.BiomapfilenameEditFieldLabel.Text = 'Biomap file name';
            app.BiomapfilenameEditField = uieditfield(app.ProcessingTab, 'text');
            app.MASSbiomapLabel = uilabel(app.ProcessingTab);
            app.MASSbiomapLabel.Text = '_MASS.biomap';
            app.Mass01EditFieldLabel = uilabel(app.ProcessingTab);
            app.Mass01EditFieldLabel.Text = 'Mass 01';
            app.Mass01EditField = uieditfield(app.ProcessingTab, 'numeric');
            app.Mass02EditFieldLabel = uilabel(app.ProcessingTab);
            app.Mass02EditFieldLabel.Text = 'Mass 02';
            app.Mass02EditField = uieditfield(app.ProcessingTab, 'numeric');
            app.Mass03EditFieldLabel = uilabel(app.ProcessingTab);
            app.Mass03EditFieldLabel.Text = 'Mass 03';
            app.Mass03EditField = uieditfield(app.ProcessingTab, 'numeric');
            app.NameofthegeneratedRGBmapEditFieldLabel = uilabel(app.ProcessingTab);
            app.NameofthegeneratedRGBmapEditFieldLabel.Text = 'Name of the generated RGB map';
            app.NameofthegeneratedRGBmapEditField = uieditfield(app.ProcessingTab, 'text');
            app.rgbmapLabel = uilabel(app.ProcessingTab);
            app.rgbmapLabel.Text = '.rgbmap';
            app.CreateRGBmapButton = uibutton(app.ProcessingTab, 'push');
            app.CreateRGBmapButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRGBmapButtonPushed, true);
            app.CreateRGBmapButton.Text = 'Create RGB map';
            app.Timebetween2lasershotsecondEditFieldLabel = uilabel(app.ProcessingTab);
            app.Timebetween2lasershotsecondEditFieldLabel.Text = 'Time between 2 laser shot (second)';
            app.Timebetween2lasershotsecondEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.Timebetween2lasershotsecondEditField.Value = 3;
            app.MainmassesonthemapButton = uibutton(app.ProcessingTab, 'push');
            app.MainmassesonthemapButton.ButtonPushedFcn = createCallbackFcn(app, @MainmassesonthemapButtonPushed, true);
            app.MainmassesonthemapButton.Text = '3 Main masses on the map';
            app.BiningwindowEditFieldLabel = uilabel(app.ProcessingTab);
            app.BiningwindowEditFieldLabel.Text = 'Bining window';
            app.BiningwindowEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.BiningwindowEditField.Value = 0.1;
            app.MassbandminEditField_2Label = uilabel(app.ProcessingTab);
            app.MassbandminEditField_2Label.Text = 'Mass band (min)';
            app.MassbandminEditField_2 = uieditfield(app.ProcessingTab, 'numeric');
            app.MassbandminEditField_2.Value = 200;
            app.maxEditField_2Label = uilabel(app.ProcessingTab);
            app.maxEditField_2Label.Text = '(max)';
            app.maxEditField_2 = uieditfield(app.ProcessingTab, 'numeric');
            app.maxEditField_2.Value = 1000;
            app.LoudthresholdEditFieldLabel = uilabel(app.ProcessingTab);
            app.LoudthresholdEditFieldLabel.Text = 'Loud threshold';
            app.LoudthresholdEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.LoudthresholdEditField.Value = 5000;
            app.LaunchTimeEstimationButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchTimeEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchTimeEstimationButtonPushed, true);
            app.LaunchTimeEstimationButton.Text = 'Launch Time Estimation';
            app.ExtractOneSpectraButton = uibutton(app.ProcessingTab, 'push');
            app.ExtractOneSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractOneSpectraButtonPushed, true);
            app.ExtractOneSpectraButton.Text = 'Extract One Spectra';
            app.ExtractSpectraZoneButton = uibutton(app.ProcessingTab, 'push');
            app.ExtractSpectraZoneButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSpectraZoneButtonPushed, true);
            app.ExtractSpectraZoneButton.Text = 'Extract Spectra Zone';
            app.TimeValue = uilabel(app.ProcessingTab);
            app.TimeValue.Text = 'NO DATA';
            app.PlotChromatogramButton = uibutton(app.ProcessingTab, 'push');
            app.PlotChromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChromatogramButtonPushed, true);
            app.PlotChromatogramButton.Text = 'Plot Chromatogram';
            app.InternalTriggeringSwitchLabel = uilabel(app.ProcessingTab);
            app.InternalTriggeringSwitchLabel.Text = 'Internal Triggering';
            app.InternalTriggeringSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.InternalTriggeringSwitch.Value = 'On';
            app.MapFromSpectraButton = uibutton(app.ProcessingTab, 'push');
            app.MapFromSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @MapFromSpectraButtonPushed, true);
            app.MapFromSpectraButton.Text = 'Map From Spectra';
            app.FusionpercentSliderLabel = uilabel(app.ProcessingTab);
            app.FusionpercentSliderLabel.Text = ' Fusion percent';
            app.FusionpercentSlider = uislider(app.ProcessingTab);
            app.FusionpercentSlider.Value = 50;
            app.mapLabel_5 = uilabel(app.ProcessingTab);
            app.mapLabel_5.Text = '.map';
            app.MapfilenameEditField_2Label = uilabel(app.ProcessingTab);
            app.MapfilenameEditField_2Label.Text = 'Map file name';
            app.MapfilenameEditField_2 = uieditfield(app.ProcessingTab, 'text');
            app.NamebiomapfileEditFieldLabel = uilabel(app.ProcessingTab);
            app.NamebiomapfileEditFieldLabel.Text = 'Name biomap file';
            app.NamebiomapfileEditField = uieditfield(app.ProcessingTab, 'text');
            app.biomapLabel_4 = uilabel(app.ProcessingTab);
            app.biomapLabel_4.Text = '.biomap';
            app.LoadBiomapButton = uibutton(app.ProcessingTab, 'push');
            app.LoadBiomapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBiomapButtonPushed, true);
            app.LoadBiomapButton.Text = 'Load Biomap';
            app.LouddataonthemapSwitchLabel = uilabel(app.ProcessingTab);
            app.LouddataonthemapSwitchLabel.Text = 'Loud data on the map';
            app.LouddataonthemapSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.LoadmzXMLButton = uibutton(app.ProcessingTab, 'push');
            app.LoadmzXMLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmzXMLButtonPushed, true);
            app.LoadmzXMLButton.Text = 'Load mzXML';
            app.MaxEditFieldLabel = uilabel(app.ProcessingTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 2000;
            app.MinEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.PCAAnalysisTab = uitab(app.TabGroup);
            app.PCAAnalysisTab.Title = 'PCA Analysis';
            app.MASSbiomapLabel_2 = uilabel(app.PCAAnalysisTab);
            app.MASSbiomapLabel_2.Text = '_MASS.biomap';
            app.BiomapfilenameEditField_2 = uieditfield(app.PCAAnalysisTab, 'text');
            app.BiomapfilenameEditField_2.Value = 'skin02';
            app.BiomapfilenameEditField_2Label = uilabel(app.PCAAnalysisTab);
            app.BiomapfilenameEditField_2Label.Text = 'Biomap file name';
            app.LaunchMapPCAAnalysisButton = uibutton(app.PCAAnalysisTab, 'push');
            app.LaunchMapPCAAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMapPCAAnalysisButtonPushed, true);
            app.LaunchMapPCAAnalysisButton.Text = 'Launch Map PCA Analysis';
            app.MassesEditFieldLabel = uilabel(app.PCAAnalysisTab);
            app.MassesEditFieldLabel.Text = 'Masses';
            app.MassesEditField = uieditfield(app.PCAAnalysisTab, 'text');
            app.MassesEditField.Value = '545, 549, 571, 573, 616, 725, 760, 841, 905, 923';
            app.LaunchmatPCAAnalysisButton = uibutton(app.PCAAnalysisTab, 'push');
            app.LaunchmatPCAAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchmatPCAAnalysisButtonPushed, true);
            app.LaunchmatPCAAnalysisButton.Text = 'Launch mat PCA Analysis';
            app.MatfilenameEditField_2Label = uilabel(app.PCAAnalysisTab);
            app.MatfilenameEditField_2Label.Text = 'Mat file name';
            app.MatfilenameEditField_2 = uieditfield(app.PCAAnalysisTab, 'text');
            app.MatfilenameEditField_2.Value = 'RB-test-20210201';
            app.matLabel_3 = uilabel(app.PCAAnalysisTab);
            app.matLabel_3.Text = '.mat';
            app.BiningwindowmassEditFieldLabel = uilabel(app.PCAAnalysisTab);
            app.BiningwindowmassEditFieldLabel.Text = 'Bining window (mass)';
            app.BiningwindowmassEditField = uieditfield(app.PCAAnalysisTab, 'numeric');
            app.BiningwindowmassEditField.Value = 0.1;
            app.MassbandminEditFieldLabel = uilabel(app.PCAAnalysisTab);
            app.MassbandminEditFieldLabel.Text = 'Mass band min ';
            app.MassbandminEditField = uieditfield(app.PCAAnalysisTab, 'numeric');
            app.MassbandminEditField.Value = 200;
            app.maxEditFieldLabel = uilabel(app.PCAAnalysisTab);
            app.maxEditFieldLabel.Text = 'max ';
            app.maxEditField = uieditfield(app.PCAAnalysisTab, 'numeric');
            app.maxEditField.Value = 800;
            app.NumberofpointonthecorrelationcirclemaximummzEditFieldLabel = uilabel(app.PCAAnalysisTab);
            app.NumberofpointonthecorrelationcirclemaximummzEditFieldLabel.Text = 'Number of point on the correlation circle (maximum m/z)';
            app.NumberofpointonthecorrelationcirclemaximummzEditField = uieditfield(app.PCAAnalysisTab, 'numeric');
            app.NumberofpointonthecorrelationcirclemaximummzEditField.Value = 10;
            app.ShowalsoallmassesonanothercorrelationcircleCheckBox = uicheckbox(app.PCAAnalysisTab);
            app.ShowalsoallmassesonanothercorrelationcircleCheckBox.Text = 'Show also all masses on another correlation circle';
            app.MapRectificationTab = uitab(app.TabGroup);
            app.MapRectificationTab.Title = 'Map Rectification';
            app.NameofthemapEditFieldLabel = uilabel(app.MapRectificationTab);
            app.NameofthemapEditFieldLabel.Text = 'Name of the map';
            app.NameofthemapEditField = uieditfield(app.MapRectificationTab, 'text');
            app.NameofthemapEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthemapEditFieldValueChanged, true);
            app.NameofthecorrectedmapEditFieldLabel = uilabel(app.MapRectificationTab);
            app.NameofthecorrectedmapEditFieldLabel.Text = 'Name of the corrected map ';
            app.NameofthecorrectedmapEditField = uieditfield(app.MapRectificationTab, 'text');
            app.LaunchRectificationButton = uibutton(app.MapRectificationTab, 'push');
            app.LaunchRectificationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchRectificationButtonPushed, true);
            app.LaunchRectificationButton.Text = 'Launch Rectification ';
            app.mapLabel_3 = uilabel(app.MapRectificationTab);
            app.mapLabel_3.Text = '.map';
            app.mapLabel_4 = uilabel(app.MapRectificationTab);
            app.mapLabel_4.Text = '.map';
            app.MinimumthresholdmmEditFieldLabel = uilabel(app.MapRectificationTab);
            app.MinimumthresholdmmEditFieldLabel.Text = 'Minimum threshold (mm) ';
            app.MinimumthresholdmmEditField = uieditfield(app.MapRectificationTab, 'numeric');
            app.MaximumthresholdmmEditFieldLabel = uilabel(app.MapRectificationTab);
            app.MaximumthresholdmmEditFieldLabel.Text = 'Maximum threshold (mm) ';
            app.MaximumthresholdmmEditField = uieditfield(app.MapRectificationTab, 'numeric');
            app.MaximumthresholdmmEditField.Value = 20;
            app.LaunchInterpolationButton = uibutton(app.MapRectificationTab, 'push');
            app.LaunchInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchInterpolationButtonPushed, true);
            app.LaunchInterpolationButton.Text = 'Launch Interpolation';
            app.mapLabel_6 = uilabel(app.MapRectificationTab);
            app.mapLabel_6.Text = '.map';
            app.mapLabel_7 = uilabel(app.MapRectificationTab);
            app.mapLabel_7.Text = '.map';
            app.NameofthemapEditField_2Label = uilabel(app.MapRectificationTab);
            app.NameofthemapEditField_2Label.Text = 'Name of the map';
            app.NameofthemapEditField_22 = uieditfield(app.MapRectificationTab, 'text');
            app.NameoftheinterpolatedmapEditFieldLabel = uilabel(app.MapRectificationTab);
            app.NameoftheinterpolatedmapEditFieldLabel.Text = 'Name of the interpolated map ';
            app.NameoftheinterpolatedmapEditField = uieditfield(app.MapRectificationTab, 'text');
            app.PointMultiplierEditFieldLabel = uilabel(app.MapRectificationTab);
            app.PointMultiplierEditFieldLabel.Text = 'Point Multiplier';
            app.PointMultiplierEditField = uieditfield(app.MapRectificationTab, 'numeric');
            app.PointMultiplierEditField.Value = 2;
            app.LaunchBiomapInterpolationButton = uibutton(app.MapRectificationTab, 'push');
            app.LaunchBiomapInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchBiomapInterpolationButtonPushed, true);
            app.LaunchBiomapInterpolationButton.Text = 'Launch Biomap Interpolation';
            app.biomapLabel_2 = uilabel(app.MapRectificationTab);
            app.biomapLabel_2.Text = '.biomap';
            app.biomapLabel_3 = uilabel(app.MapRectificationTab);
            app.biomapLabel_3.Text = '.biomap';
            app.NameofthebiomapEditFieldLabel = uilabel(app.MapRectificationTab);
            app.NameofthebiomapEditFieldLabel.Text = 'Name of the biomap';
            app.NameofthebiomapEditField = uieditfield(app.MapRectificationTab, 'text');
            app.NameoftheinterpolatedbiomapEditFieldLabel = uilabel(app.MapRectificationTab);
            app.NameoftheinterpolatedbiomapEditFieldLabel.Text = 'Name of the interpolated biomap ';
            app.NameoftheinterpolatedbiomapEditField = uieditfield(app.MapRectificationTab, 'text');
            app.PointMultiplierEditField_2Label = uilabel(app.MapRectificationTab);
            app.PointMultiplierEditField_2Label.Text = 'Point Multiplier';
            app.PointMultiplierEditField_2 = uieditfield(app.MapRectificationTab, 'numeric');
            app.PointMultiplierEditField_2.Value = 2;
            app.LDATab = uitab(app.TabGroup);
            app.LDATab.Title = 'LDA';
            app.ColorDropDownLabel = uilabel(app.LDATab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LDATab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown.Value = 'Red';
            app.NameofthematfileEditFieldLabel = uilabel(app.LDATab);
            app.NameofthematfileEditFieldLabel.Text = 'Name of the mat file';
            app.NameofthematfileEditField = uieditfield(app.LDATab, 'text');
            app.matLabel_4 = uilabel(app.LDATab);
            app.matLabel_4.Text = '.mat';
            app.mapLabel_8 = uilabel(app.LDATab);
            app.mapLabel_8.Text = '.map';
            app.NameofthemapfileEditFieldLabel = uilabel(app.LDATab);
            app.NameofthemapfileEditFieldLabel.Text = 'Name of the map file';
            app.NameofthemapfileEditField = uieditfield(app.LDATab, 'text');
            app.csvLabel = uilabel(app.LDATab);
            app.csvLabel.Text = '.csv';
            app.NameofthecsvfileEditFieldLabel = uilabel(app.LDATab);
            app.NameofthecsvfileEditFieldLabel.Text = 'Name of the csv file';
            app.NameofthecsvfileEditField = uieditfield(app.LDATab, 'text');
            app.Class01EditFieldLabel = uilabel(app.LDATab);
            app.Class01EditFieldLabel.Text = 'Class 01';
            app.Class01EditField = uieditfield(app.LDATab, 'text');
            app.Class02EditFieldLabel = uilabel(app.LDATab);
            app.Class02EditFieldLabel.Text = 'Class 02';
            app.Class02EditField = uieditfield(app.LDATab, 'text');
            app.Class03EditFieldLabel = uilabel(app.LDATab);
            app.Class03EditFieldLabel.Text = 'Class 03';
            app.Class03EditField = uieditfield(app.LDATab, 'text');
            app.LaunchLDAReconstructionButton = uibutton(app.LDATab, 'push');
            app.LaunchLDAReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchLDAReconstructionButtonPushed, true);
            app.LaunchLDAReconstructionButton.Text = 'Launch LDA Reconstruction';
            app.BackgroundColorDropDownLabel = uilabel(app.LDATab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.LDATab);
            app.BackgroundColorDropDown.Items = {'Black', 'White', 'Grey'};
            app.BackgroundColorDropDown.Value = 'Black';
            app.ColorDropDown_2Label = uilabel(app.LDATab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.LDATab);
            app.ColorDropDown_2.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_2.Value = 'Green';
            app.ColorDropDown_3Label = uilabel(app.LDATab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.LDATab);
            app.ColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_3.Value = 'Orange';
            app.ColorDropDown_4Label = uilabel(app.LDATab);
            app.ColorDropDown_4Label.Text = 'Color';
            app.ColorDropDown_4 = uidropdown(app.LDATab);
            app.ColorDropDown_4.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_4.Value = 'None';
            app.Class04EditFieldLabel = uilabel(app.LDATab);
            app.Class04EditFieldLabel.Text = 'Class 04';
            app.Class04EditField = uieditfield(app.LDATab, 'text');
            app.Class05EditFieldLabel = uilabel(app.LDATab);
            app.Class05EditFieldLabel.Text = 'Class 05';
            app.Class05EditField = uieditfield(app.LDATab, 'text');
            app.Class06EditFieldLabel = uilabel(app.LDATab);
            app.Class06EditFieldLabel.Text = 'Class 06';
            app.Class06EditField = uieditfield(app.LDATab, 'text');
            app.ColorDropDown_5Label = uilabel(app.LDATab);
            app.ColorDropDown_5Label.Text = 'Color';
            app.ColorDropDown_5 = uidropdown(app.LDATab);
            app.ColorDropDown_5.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_5.Value = 'None';
            app.ColorDropDown_6Label = uilabel(app.LDATab);
            app.ColorDropDown_6Label.Text = 'Color';
            app.ColorDropDown_6 = uidropdown(app.LDATab);
            app.ColorDropDown_6.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_6.Value = 'None';
            app.Scils_Extraction = uitab(app.TabGroup);
            app.Scils_Extraction.Title = 'Data Extraction';
            app.matLabel_5 = uilabel(app.Scils_Extraction);
            app.matLabel_5.Text = '.mat';
            app.InputMatfilenameEditFieldLabel = uilabel(app.Scils_Extraction);
            app.InputMatfilenameEditFieldLabel.Text = 'Input : Mat file name';
            app.InputMatfilenameEditField_scils = uieditfield(app.Scils_Extraction, 'text');
            app.InputMatfilenameEditField_scils.ValueChangedFcn = createCallbackFcn(app, @InputMatfilenameEditField_scilsValueChanged, true);
            app.OutputnameofthecreatedtxtfileEditFieldLabel = uilabel(app.Scils_Extraction);
            app.OutputnameofthecreatedtxtfileEditFieldLabel.Text = 'Output : name of the created txt file';
            app.OutputnameofthecreatedtxtfileEditField = uieditfield(app.Scils_Extraction, 'text');
            app.txtLabel = uilabel(app.Scils_Extraction);
            app.txtLabel.Text = '.txt';
            app.LaunchExtractionButton = uibutton(app.Scils_Extraction, 'push');
            app.LaunchExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchExtractionButtonPushed, true);
            app.LaunchExtractionButton.Text = 'Launch Extraction';
            app.ExporttoSCILSLabel = uilabel(app.Scils_Extraction);
            app.ExporttoSCILSLabel.Text = 'Export to SCILS';
            app.Exportto3DfileLabel = uilabel(app.Scils_Extraction);
            app.Exportto3DfileLabel.Text = 'Export to 3D file';
            app.ExportrawBioMapButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportrawBioMapButton.ButtonPushedFcn = createCallbackFcn(app, @ExportrawBioMapButtonPushed, true);
            app.ExportrawBioMapButton.Text = 'Export raw BioMap';
            app.ExportandcoregisterBiomapButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportandcoregisterBiomapButton.ButtonPushedFcn = createCallbackFcn(app, @ExportandcoregisterBiomapButtonPushed, true);
            app.ExportandcoregisterBiomapButton.Text = 'Export and coregister Biomap';
            app.ExporttocsvLabel = uilabel(app.Scils_Extraction);
            app.ExporttocsvLabel.Text = 'Export to .csv';
            app.ExportwithbinningButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportwithbinningButton.ButtonPushedFcn = createCallbackFcn(app, @ExportwithbinningButtonPushed, true);
            app.ExportwithbinningButton.Text = 'Export with binning';
            app.ExportwithoutbinningPLACEHOLDERButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportwithoutbinningPLACEHOLDERButton.Text = {'Export without binning'; '(PLACEHOLDER)'};
            app.matLabel_6 = uilabel(app.Scils_Extraction);
            app.matLabel_6.Text = '.mat';
            app.mapLabel_9 = uilabel(app.Scils_Extraction);
            app.mapLabel_9.Text = '.map';
            app.Input1EditFieldLabel = uilabel(app.Scils_Extraction);
            app.Input1EditFieldLabel.Text = 'Input 1:';
            app.Input1EditField = uieditfield(app.Scils_Extraction, 'text');
            app.Input1EditField.ValueChangedFcn = createCallbackFcn(app, @Input1EditFieldValueChanged, true);
            app.Input2EditFieldLabel = uilabel(app.Scils_Extraction);
            app.Input2EditFieldLabel.Text = 'Input 2:';
            app.Input2EditField = uieditfield(app.Scils_Extraction, 'text');
            app.Input2EditField.ValueChangedFcn = createCallbackFcn(app, @Input2EditFieldValueChanged, true);
            app.MinMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MinMzEditFieldLabel.Text = 'Min M/z';
            app.MinMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MaxMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MaxMzEditFieldLabel.Text = 'Max M/z';
            app.MaxMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MaxMzEditField.Value = 2000;
            app.BinningwindowsizeEditFieldLabel = uilabel(app.Scils_Extraction);
            app.BinningwindowsizeEditFieldLabel.Text = 'Binning window size';
            app.BinningwindowsizeEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.BinningwindowsizeEditField.Value = 0.1;
            app.ExportcsvtoplyButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportcsvtoplyButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvtoplyButtonPushed, true);
            app.ExportcsvtoplyButton.Text = 'Export .csv to .ply';
            app.NormalizecsvTICButton = uibutton(app.Scils_Extraction, 'push');
            app.NormalizecsvTICButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizecsvTICButtonPushed, true);
            app.NormalizecsvTICButton.Text = 'Normalize .csv (TIC)';
            app.NormalizecsvRMSButton = uibutton(app.Scils_Extraction, 'push');
            app.NormalizecsvRMSButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizecsvRMSButtonPushed, true);
            app.NormalizecsvRMSButton.Text = 'Normalize .csv (RMS)';
            app.ExportcsvtopngButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportcsvtopngButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvtopngButtonPushed, true);
            app.ExportcsvtopngButton.Text = 'Export .csv to .png';
            app.CoregistertwoimagesButton = uibutton(app.Scils_Extraction, 'push');
            app.CoregistertwoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CoregistertwoimagesButtonPushed, true);
            app.CoregistertwoimagesButton.Text = 'Coregister two images';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.DataImportationButton = uibutton(app.SegmentationTab, 'push');
            app.DataImportationButton.ButtonPushedFcn = createCallbackFcn(app, @DataImportationButtonPushed, true);
            app.DataImportationButton.Text = 'Data Importation';
            app.DisplaySumofIntensitiesButton = uibutton(app.SegmentationTab, 'push');
            app.DisplaySumofIntensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySumofIntensitiesButtonPushed, true);
            app.DisplaySumofIntensitiesButton.Text = 'Display Sum of Intensities';
            app.DataReductionButton = uibutton(app.SegmentationTab, 'push');
            app.DataReductionButton.ButtonPushedFcn = createCallbackFcn(app, @DataReductionButtonPushed, true);
            app.DataReductionButton.Text = 'Data Reduction';
            app.ClusterNumberEditFieldLabel = uilabel(app.SegmentationTab);
            app.ClusterNumberEditFieldLabel.Text = 'Cluster Number';
            app.ClusterNumberEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.ClusterIndexEditFieldLabel = uilabel(app.SegmentationTab);
            app.ClusterIndexEditFieldLabel.Text = 'Cluster Index';
            app.ClusterIndexEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.SegmentationButton = uibutton(app.SegmentationTab, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.DisplayclusterButton = uibutton(app.SegmentationTab, 'push');
            app.DisplayclusterButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayclusterButtonPushed, true);
            app.DisplayclusterButton.Text = 'Display cluster';
            app.TerminalTextAreaLabel = uilabel(app.UIFigure);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.TerminalTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapping_APP_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end